classdef antrax < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExperimentMenu                  matlab.ui.container.Menu
        OpenExperimentMenu              matlab.ui.container.Menu
        RecentMenu                      matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        SessionMenu                     matlab.ui.container.Menu
        NewSessionMenu                  matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        ImportMenu_2                    matlab.ui.container.Menu
        ParametersfromfileMenu          matlab.ui.container.Menu
        ParametersfromsessionMenu       matlab.ui.container.Menu
        Menu_3                          matlab.ui.container.Menu
        ParametersfromexperimentMenu    matlab.ui.container.Menu
        BrowseMenu                      matlab.ui.container.Menu
        DefaultparametersMenu           matlab.ui.container.Menu
        ROImaskfromfileMenu             matlab.ui.container.Menu
        LabelsfromfileMenu              matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        ParameterstofileMenu            matlab.ui.container.Menu
        ROImasktofileMenu               matlab.ui.container.Menu
        BackgroundimagetofileMenu       matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        FrameExplorerMenu               matlab.ui.container.Menu
        GraphExplorerMenu               matlab.ui.container.Menu
        ValidateTrackletIDsMenu         matlab.ui.container.Menu
        ValidateFinalTrackingMenu       matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        OnlineDocumentationMenu         matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        BackgroundTab                   matlab.ui.container.Tab
        BackgroundGridLayout            matlab.ui.container.GridLayout
        BGFileLabel                     matlab.ui.control.Label
        BGFileDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        NumberofFramesSpinnerLabel      matlab.ui.control.Label
        NumberofFramesSpinner           matlab.ui.control.Spinner
        CreateBGButton                  matlab.ui.control.Button
        OneBGCheckBox                   matlab.ui.control.CheckBox
        ToFrameLabel                    matlab.ui.control.Label
        ToFrameEditField                matlab.ui.control.NumericEditField
        FromFrameLabel                  matlab.ui.control.Label
        FromFrameEditField              matlab.ui.control.NumericEditField
        BGperSubdirCheckBox             matlab.ui.control.CheckBox
        AbsoluteFrameSpinnerLabel       matlab.ui.control.Label
        AbsoluteFrameSpinner_BG         matlab.ui.control.Spinner
        MovieNumberSpinnerLabel         matlab.ui.control.Label
        MovieNumberSpinner_BG           matlab.ui.control.Spinner
        MovieFrameSpinnerLabel          matlab.ui.control.Label
        MovieFrameSpinner_BG            matlab.ui.control.Spinner
        ShowFrameCheckBox               matlab.ui.control.CheckBox
        ShowBackgroundCheckBox          matlab.ui.control.CheckBox
        ScaleTab                        matlab.ui.container.Tab
        ScaleGridLayout                 matlab.ui.container.GridLayout
        ChooseShapeDropDownLabel        matlab.ui.control.Label
        ScaleChooseShapeDropDown        matlab.ui.control.DropDown
        DimensionEditFieldLabel         matlab.ui.control.Label
        DimensionEditField              matlab.ui.control.NumericEditField
        ScaleDrawButton                 matlab.ui.control.Button
        ScaleDoneButton                 matlab.ui.control.Button
        InimageLabel                    matlab.ui.control.Label
        ImageDimEditField               matlab.ui.control.NumericEditField
        ScaleLabel                      matlab.ui.control.Label
        ScaleEditField                  matlab.ui.control.NumericEditField
        ROITab                          matlab.ui.container.Tab
        ROIGridLayout                   matlab.ui.container.GridLayout
        ResettoWhiteButton              matlab.ui.control.Button
        ResettoBlackButton              matlab.ui.control.Button
        ChooseShapeDropDown_2Label      matlab.ui.control.Label
        ROIChooseShapeDropDown          matlab.ui.control.DropDown
        AddButton                       matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        MultiColonyCheckBox             matlab.ui.control.CheckBox
        ColonyNumberingDropDownLabel    matlab.ui.control.Label
        ColonyNumberingDropDown         matlab.ui.control.DropDown
        CircularshiftdegEditFieldLabel  matlab.ui.control.Label
        CircularshiftdegEditField       matlab.ui.control.NumericEditField
        OpenBoundryCheckBox             matlab.ui.control.CheckBox
        OpenBoundryAddButton            matlab.ui.control.Button
        OpenBoundryRemoveButton         matlab.ui.control.Button
        OpenBoundryResetButton          matlab.ui.control.Button
        ROImaskalphaLabel               matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        AlphaSlider                     matlab.ui.control.Slider
        SegmentationTab                 matlab.ui.container.Tab
        SegmentationGridLayout          matlab.ui.container.GridLayout
        AbsoluteFrameSpinner_3Label     matlab.ui.control.Label
        AbsoluteFrameSpinner_SEG        matlab.ui.control.Spinner
        MovieNumberSpinner_3Label       matlab.ui.control.Label
        MovieNumberSpinner_SEG          matlab.ui.control.Spinner
        MovieFrameSpinner_3Label        matlab.ui.control.Label
        MovieFrameSpinner_SEG           matlab.ui.control.Spinner
        ShowsegmentedCheckBox           matlab.ui.control.CheckBox
        ShowBlobPropertiesCheckBox      matlab.ui.control.CheckBox
        ShowBGsubtractedimageCheckBox   matlab.ui.control.CheckBox
        ThresholdCheckBox               matlab.ui.control.CheckBox
        ThresholdSpinner                matlab.ui.control.Spinner
        MinAreapixCheckBox              matlab.ui.control.CheckBox
        MinimumAreaSpinner              matlab.ui.control.Spinner
        MinIntensityCheckBox            matlab.ui.control.CheckBox
        MinimumIntensitySpinner         matlab.ui.control.Spinner
        MorphClosingCheckBox            matlab.ui.control.CheckBox
        ClosingSpinner                  matlab.ui.control.Spinner
        MorphOpeningCheckBox            matlab.ui.control.CheckBox
        OpeningSpinner                  matlab.ui.control.Spinner
        FillHolesCheckBox               matlab.ui.control.CheckBox
        UseConvexHullCheckBox           matlab.ui.control.CheckBox
        AdaptivethresholdCheckBox       matlab.ui.control.CheckBox
        ResettoDefaultButton            matlab.ui.control.Button
        ResettoLastButton               matlab.ui.control.Button
        ShowROICheckBox                 matlab.ui.control.CheckBox
        AutothresholdCheckBox           matlab.ui.control.CheckBox
        DarkbackgroundCheckBox          matlab.ui.control.CheckBox
        SingleAntsTab                   matlab.ui.container.Tab
        SingleAntsGridLayout            matlab.ui.container.GridLayout
        AbsoluteFrameSpinner_4Label     matlab.ui.control.Label
        AbsoluteFrameSpinner_SA         matlab.ui.control.Spinner
        MovieNumberSpinner_4Label       matlab.ui.control.Label
        MovieNumberSpinner_SA           matlab.ui.control.Spinner
        MovieFrameSpinner_4Label        matlab.ui.control.Label
        MovieFrameSpinner_SA            matlab.ui.control.Spinner
        MinAreaSpinnerLabel             matlab.ui.control.Label
        SAMinAreaSpinner                matlab.ui.control.Spinner
        MaxAreaSpinnerLabel             matlab.ui.control.Label
        SAMaxAreaSpinner                matlab.ui.control.Spinner
        PixelsCheckBox                  matlab.ui.control.CheckBox
        SICheckBox                      matlab.ui.control.CheckBox
        LinkingTab                      matlab.ui.container.Tab
        LinkingGridLayout               matlab.ui.container.GridLayout
        AbsoluteFrameSpinner_4Label_2   matlab.ui.control.Label
        AbsoluteFrameSpinner_LK         matlab.ui.control.Spinner
        MovieNumberSpinner_4Label_2     matlab.ui.control.Label
        MovieNumberSpinner_LK           matlab.ui.control.Spinner
        MovieFrameSpinner_4Label_2      matlab.ui.control.Label
        MovieFrameSpinner_LK            matlab.ui.control.Spinner
        OpticalFlowCheckBox             matlab.ui.control.CheckBox
        LinearAssigmentCheckBox         matlab.ui.control.CheckBox
        LinkingclustersCheckBox         matlab.ui.control.CheckBox
        FlowCheckBox                    matlab.ui.control.CheckBox
        LinkingCheckBox                 matlab.ui.control.CheckBox
        LinkingViewButtonGroup          matlab.ui.container.ButtonGroup
        PreviousframeButton             matlab.ui.control.RadioButton
        CurrentframeButton              matlab.ui.control.RadioButton
        BlendButton                     matlab.ui.control.RadioButton
        MaxspeedmmsSpinnerLabel         matlab.ui.control.Label
        MaxspeedSpinner                 matlab.ui.control.Spinner
        ClusterradiuscoeffLabel         matlab.ui.control.Label
        RadCoeffSpinner                 matlab.ui.control.Spinner
        FlowcutoffcoeffLabel            matlab.ui.control.Label
        CutoffCoeffSpinner              matlab.ui.control.Spinner
        ClassificationTab               matlab.ui.container.Tab
        ClassificationGridLayout        matlab.ui.container.GridLayout
        AbsoluteFrameSpinner_4Label_3   matlab.ui.control.Label
        AbsoluteFrameSpinner_CL         matlab.ui.control.Spinner
        MovieNumberSpinner_4Label_3     matlab.ui.control.Label
        MovieNumberSpinner_CL           matlab.ui.control.Spinner
        MovieFrameSpinner_4Label_3      matlab.ui.control.Label
        MovieFrameSpinner_CL            matlab.ui.control.Spinner
        BlobMaskDilateSpinner           matlab.ui.control.Spinner
        BlobmaskdilationCheckBox        matlab.ui.control.CheckBox
        ColorcorrectionCheckBox         matlab.ui.control.CheckBox
        BrowseButton                    matlab.ui.control.Button
        ClassifierEditFieldLabel        matlab.ui.control.Label
        ClassifierEditField             matlab.ui.control.EditField
        ShowsegmentationCheckBox        matlab.ui.control.CheckBox
        ClassifymaskedblobsCheckBox     matlab.ui.control.CheckBox
        IDsTab                          matlab.ui.container.Tab
        IDsGridLayout                   matlab.ui.container.GridLayout
        BCheckBox                       matlab.ui.control.CheckBox
        GCheckBox                       matlab.ui.control.CheckBox
        LCheckBox                       matlab.ui.control.CheckBox
        OCheckBox                       matlab.ui.control.CheckBox
        PCheckBox                       matlab.ui.control.CheckBox
        RCheckBox                       matlab.ui.control.CheckBox
        WCheckBox                       matlab.ui.control.CheckBox
        YCheckBox                       matlab.ui.control.CheckBox
        TagColorsLabel                  matlab.ui.control.Label
        AntIDsTextAreaLabel             matlab.ui.control.Label
        AntIDsTextArea                  matlab.ui.control.TextArea
        NoAntTextAreaLabel              matlab.ui.control.Label
        NoAntTextArea                   matlab.ui.control.TextArea
        OtherTextAreaLabel              matlab.ui.control.Label
        OtherTextArea                   matlab.ui.control.TextArea
        NAntsLabel                      matlab.ui.control.Label
        NNoAntLabel                     matlab.ui.control.Label
        NOtherLabel                     matlab.ui.control.Label
        NColorsLabel                    matlab.ui.control.Label
        LoadlabelfileButton             matlab.ui.control.Button
        TaggingButtonGroup              matlab.ui.container.ButtonGroup
        untaggedButton                  matlab.ui.control.RadioButton
        grouptaggedButton               matlab.ui.control.RadioButton
        individuallytaggedButton        matlab.ui.control.RadioButton
        HorizontalsymmetryCheckBox      matlab.ui.control.CheckBox
        Autogenerate2tagIDsCheckBox     matlab.ui.control.CheckBox
        PropagationTab                  matlab.ui.container.Tab
        PropagationGridLayout           matlab.ui.container.GridLayout
        PairssearchdepthSpinnerLabel    matlab.ui.control.Label
        PairssearchdepthSpinner         matlab.ui.control.Spinner
        MaxiterationsSpinnerLabel       matlab.ui.control.Label
        MaxiterationsSpinner            matlab.ui.control.Spinner
        ImpossiblespeedSpinnerLabel     matlab.ui.control.Label
        ImpossiblespeedSpinner          matlab.ui.control.Spinner
        UsemanualconfigfileifexistCheckBox  matlab.ui.control.CheckBox
        UsetemporalconfigfileifexistCheckBox  matlab.ui.control.CheckBox
        ToolongtobewrongSpinnerLabel    matlab.ui.control.Label
        ToolongtobewrongSpinner         matlab.ui.control.Spinner
        GroupbyDropDownLabel            matlab.ui.control.Label
        GroupbyDropDown                 matlab.ui.control.DropDown
        WriteantdataCheckBox            matlab.ui.control.CheckBox
        UsesoftassigmentsCheckBox       matlab.ui.control.CheckBox
        WritetrackletdataCheckBox       matlab.ui.control.CheckBox
        DataexportoptionsLabel          matlab.ui.control.Label
        MoreoptionsTab                  matlab.ui.container.Tab
        MoreOptionsGridLayout           matlab.ui.container.GridLayout
        SaveimagesofsingleanttrackletsCheckBox  matlab.ui.control.CheckBox
        SavesingleantcroppedclipsCheckBox  matlab.ui.control.CheckBox
        TrackoninterruptCheckBox        matlab.ui.control.CheckBox
        ExportxydatauntaggedCheckBox    matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        StatusBar1                      matlab.ui.control.Label
        StatusBar2                      matlab.ui.control.Label
    end

    
    properties (Access = public)
        antraxdir = [getenv('HOME'),filesep,'.antrax',filesep]
        expdir                          char
        Trck                            trhandles
        currently_displaying = 'frame'
        display_mask = false
        display_segmentation = false
        current_frame_t                 trtime
        bgdir                           char
        scale_tool_pix = NaN
        ax
        hroi
        prev_roi_tool                   struct
        himage
        colony_map_to_save
        user_colony_labels
        colony_nums                     matlab.graphics.primitive.Text
        chplot
        BlobText
        
        PossColors = {'B','G','O','L','P','R','W','Y'};
        PossIDs
        
        graph_explorer
    end
    
    properties (Access = private)
        Property22 % Description
    end
    
    methods (Access = public)
        
        
        function focus(app)
            
            app.UIFigure.Visible=false;
            app.UIFigure.Visible=true;
            
        end
        
        function cs = get_components(app, s)
            
            fn = fieldnames(app);
            fn = fn(contains(fn, s));
            fn = fn(arrayfun(@(x) isa(app.(x),'matlab.ui.control.internal.model.ComponentModel'), fn));
            cs = arrayfun(@(x) app.(x), fn);
            
        end
        
        function set_experiment(app, expdir, session)
            
            report('D', 'set_experiment function')

            if isa(expdir, 'trhandles')
                app.Trck = expdir;
                app.expdir = app.Trck.expdir;
                session = app.Trck.trackingdirname;
            elseif nargin<3 || isempty(session)
                app.expdir = expdir;
                sessions = find_sessions(app.expdir);
                if ~isempty(sessions)
                    app.Trck = trhandles.load(app.expdir);
                    session = app.Trck.trackingdirname;
                else
                    msg = 'The experiment does not have an existing session. Would you like to create session with the defualt name ''antrax'' or enter a custom name?';
                    selection = uiconfirm(app.UIFigure,msg ,'Create new session',...
                        'Options',{'Default','Enter session name','Cancel'},...
                        'DefaultOption',2,'CancelOption',3);
                    switch selection(1)
                        case 'D'
                            session = make_new_session(app,'antrax');
                        case 'E'
                            session = make_new_session(app,[]);
                        case 'C'
                            report('W','Operation cancelled')
                            return
                    end
                    app.Trck = trhandles(expdir, session);
                    
                end
            else
                app.expdir = expdir;
                app.Trck = trhandles(expdir, session);
            end
            
            d = uiprogressdlg(app.UIFigure,'Title','Loading experiment','Indeterminate','on');
            
            sessions = find_sessions(app.expdir);
            delete(app.ParametersfromsessionMenu.Children);
            app.ParametersfromsessionMenu.Enable = false;
            delete(app.SessionMenu.Children);
            
            for i=1:length(sessions)
                
                sm = uimenu(app.SessionMenu);
                sm.Text = sessions{i};
                sm.MenuSelectedFcn = createCallbackFcn(app, @SessionMenuSelected, true);
                sm.Checked = strcmp(sessions{i},session);
                
                if ~strcmp(session,sessions{i})
                    sm = uimenu(app.ParametersfromsessionMenu);
                    sm.Text = sessions{i};
                    sm.MenuSelectedFcn = createCallbackFcn(app, @ImportFromSessionMenuSelected, true);
                    app.ParametersfromsessionMenu.Enable = true;                    
                end

            end
            
            sm = uimenu(app.SessionMenu);
            sm.Text = 'New';
            sm.Separator = true;
            sm.MenuSelectedFcn = createCallbackFcn(app, @NewSessionMenuSelected, true);
            
            
            app.set_Trck();
            
            app.TabGroup.Visible = true;
            app.ax.Visible = 'on';
            
            app.StatusBar1.Text = ['Experiment: ', app.Trck.expname, ', Session: ', session];
            app.StatusBar2.Text = ['Finished loading experiment'];
            close(d);
            
        end
        
        
        function session = make_new_session(app, session)
        
            if nargin<2 || isempty(session)
                
                prompt = {'Enter name for new session:'};
                dlgtitle = 'New session';
                dims = [1 35];
                definput = {''};
                session = inputdlg(prompt,dlgtitle,dims,definput);
                focus(app)
                
                session = session{1};
                if isempty(session)
                    return
                end
                
                sessions = find_sessions(app.expdir);
                
                if ismember(session, sessions)
                    uialert(app.UIFigure,'Session with that name already exists in experiment','Error creating session')
                    focus(app)
                    return
                end
            end

        end
        

        function set_Trck(app)
            
            app.Trck.save;
            
            app.Trck.load_bg;
            
            if isempty(app.Trck.Masks)
                load_masks(app.Trck);
            end
            
            % frame selectors
            movlist = [app.Trck.er.movies_info.index];
            if length(movlist)==1
                set(findobj(app.UIFigure, '-regexp','Tag','^*MovieNumber*','Type','uispinner'), 'Limits', [movlist movlist+1]);
                set(findobj(app.UIFigure, '-regexp','Tag','^*MovieNumber*','Type','uispinner'), 'Enable', false);
            else
                set(findobj(app.UIFigure, '-regexp','Tag','^*MovieNumber*','Type','uispinner'), 'Limits', [min(movlist) max(movlist)]);
                set(findobj(app.UIFigure, '-regexp','Tag','^*MovieNumber*','Type','uispinner'), 'Enable', true);
            end
            
            set(findobj(app.UIFigure, '-regexp','Tag','^*MovieFrame*','Type','uispinner'), 'Limits', [app.Trck.er.movies_info(1).fi,app.Trck.er.movies_info(1).ff]);
            set(findobj(app.UIFigure, '-regexp','Tag','^*AbsoluteFrame*','Type','uispinner'), 'Limits', [app.Trck.er.movies_info(1).fi,app.Trck.er.movies_info(end).ff]);
            
            set_params_in_app(app)
            update_frame_selectors(app, trtime(app.Trck, 1));
            cla(app.ax);
            app.himage = imshow(app.Trck.read_frame(1),'Parent',app.ax);
            update_image(app)
            
            app.TabGroup.Visible = true;
            
            app.ax.Visible = 'on';
            %set(app.ax,'axis','off')
            set(app.ax,'xtick',[],'ytick',[])
            set(app.ax,'XColor','none','YColor','none')
            set(app.ax,'Color',[0.94,0.94,0.94])
            
            add_to_recent_expdirs(app.expdir);
            recents = load_recent_expdirs;
            delete(app.RecentMenu.Children)
            for i=1:length(recents)
                sm = uimenu(app.RecentMenu);
                sm.Text = recents{i};
                sm.MenuSelectedFcn = createCallbackFcn(app, @RecentExpMenuSelected, true);
                app.RecentMenu.Enable = true;
                sm.Checked = strcmp(app.expdir, sm.Text);
            end
            
            if ~isempty(app.Trck.graphlist)
                app.GraphExplorerMenu.Enable = true;
                app.ValidateTrackletIDsMenu.Enable = true;
            else
                app.GraphExplorerMenu.Enable = false;
                app.ValidateTrackletIDsMenu.Enable = false;
            end

            
            focus(app)
        end
        
        
        function ok = parameters_reset_warning(app)
            
            selection = uiconfirm(app.UIFigure,'This will overwrite all configured parameters in current session. Are you sure?','Parameter overwrite');
            ok = strcmp(selection, 'OK');
            
        end
        
        function set_params_in_app(app)
            
            % background tab
            
            app.bgdir = [app.Trck.paramsdir,'backgrounds',filesep];
            mkdirp(app.bgdir);
            
            update_bg_list(app);
            
            app.BGperSubdirCheckBox.Value = app.Trck.get_param('background_per_subdir');
            app.OneBGCheckBox.Value = ~app.Trck.get_param('background_per_subdir');
            app.NumberofFramesSpinner.Value = app.Trck.get_param('background_nframes');
            app.MethodDropDown.Value = app.Trck.get_param('background_method');
            
            frame_range = app.Trck.get_param('background_frame_range');
            frame_range(2) = min(app.Trck.er.movies_info(end).ff,frame_range(2));
            
            app.FromFrameEditField.Value = frame_range(1);
            app.ToFrameEditField.Value = frame_range(2);
            
            app.ShowFrameCheckBox.Value = ~isempty(app.BGFileDropDown.Value);
            app.ShowBackgroundCheckBox.Value = isempty(app.BGFileDropDown.Value);
            
            if ~isempty(app.BGFileDropDown.Value) && app.TabGroup.SelectedTab == app.BackgroundTab
                app.currently_displaying = 'bg';
            end
            
            % scale tab
            update_scale(app);
            
            % roi tab
            if isempty(app.Trck.get_param('geometry_multi_colony_numbering'))
                app.Trck.set_param('geometry_multi_colony_numbering','Vertical');
            end
            app.MultiColonyCheckBox.Value = app.Trck.get_param('geometry_multi_colony');
            app.ColonyNumberingDropDown.Enable = app.Trck.get_param('geometry_multi_colony');
            app.ColonyNumberingDropDown.Value = app.Trck.get_param('geometry_multi_colony_numbering');
            app.CircularshiftdegEditField.Enable = app.Trck.get_param('geometry_multi_colony')...
                && ismember(app.Trck.get_param('geometry_multi_colony_numbering'),{'Clockwise','CounterClockwise'});
            app.CircularshiftdegEditField.Value = app.Trck.get_param('geometry_multi_colony_circ_shift');
            
            app.OpenBoundryCheckBox.Value = app.Trck.get_param('geometry_open_boundry');
            app.OpenBoundryAddButton.Enable = app.OpenBoundryCheckBox.Value;
            app.OpenBoundryRemoveButton.Enable = app.OpenBoundryCheckBox.Value;
            app.OpenBoundryResetButton.Enable = app.OpenBoundryCheckBox.Value;
            
            % segmentation tab
            %app.UsemaxrgbforsegmentationCheckBox.Value = app.Trck.get_param('segmentation_use_max_rgb');
            
            app.AdaptivethresholdCheckBox.Value = app.Trck.get_param('segmentation_local_z_scaling');
            app.ThresholdCheckBox.Value     = true;
            
            %app.IlluminationcorrectionCheckBox.Value = false; %app.Trck.get_param('segmentation_illum_correct');
            app.ThresholdSpinner.Value      = app.Trck.get_param('segmentation_threshold');
            app.MinAreapixCheckBox.Value    = app.Trck.get_param('segmentation_BlobAreaFilter');
            app.MinimumAreaSpinner.Value    = app.Trck.get_param('segmentation_MinimumBlobArea');
            app.MorphClosingCheckBox.Value  = app.Trck.get_param('segmentation_ImClosing');
            app.ClosingSpinner.Value        = app.Trck.get_param('segmentation_ImClosingSize');
            app.MorphOpeningCheckBox.Value  = app.Trck.get_param('segmentation_ImOpenning');
            app.OpeningSpinner.Value        = app.Trck.get_param('segmentation_ImOpenningSize');
            app.MinIntensityCheckBox.Value  = app.Trck.get_param('segmentation_IntensityFilter');
            app.MinimumIntensitySpinner.Value = app.Trck.get_param('segmentation_MaxIntensityThreshold');
            app.UseConvexHullCheckBox.Value = app.Trck.get_param('segmentation_useConvexHull');
            app.FillHolesCheckBox.Value     = app.Trck.get_param('segmentation_fillHoles');
            app.DarkbackgroundCheckBox.Value = app.Trck.get_param('segmentation_invert');
            
            app.ThresholdSpinner.Enable = app.ThresholdCheckBox.Value;
            app.MinimumAreaSpinner.Enable = app.MinAreapixCheckBox.Value;
            app.ClosingSpinner.Enable = app.MorphClosingCheckBox.Value;
            app.OpeningSpinner.Enable = app.MorphOpeningCheckBox.Value;
            app.MinimumIntensitySpinner.Enable = app.MinIntensityCheckBox.Value;
            
            app.ResettoLastButton.Enable = exist([app.Trck.paramsdir,'prmtrs_last_tracking_run.mat'],'file');
            
            % single ants tab
            update_single_ants(app)
            
            % linking tab
            app.MaxspeedSpinner.Value = 1000 * app.Trck.get_param('linking_maxspeed');
            app.RadCoeffSpinner.Value = app.Trck.get_param('linking_cluster_radius_coeff');
            app.CutoffCoeffSpinner.Value = app.Trck.get_param('linking_flow_cutoff_coeff');
            
            % Classification tab
            app.ColorcorrectionCheckBox.Value = app.Trck.get_param('segmentation_color_correction_for_classification');
            app.BlobmaskdilationCheckBox.Value = app.Trck.get_param('segmentation_blob_mask_dilate');
            app.BlobMaskDilateSpinner.Value = app.Trck.get_param('segmentation_blob_mask_dilate_radius');
            if isempty(app.Trck.classdir)
                app.ClassifierEditField.Value = [app.Trck.trackingdir,'classifier'];
            elseif isfolder(app.Trck.classdir)
                app.ClassifierEditField.Value = app.Trck.classdir;
            end
            
            % IDs tab
            update_ids(app)
            
            % prop tab
            app.UsetemporalconfigfileifexistCheckBox.Value = app.Trck.get_param('graph_apply_temporal_window');
            app.UsemanualconfigfileifexistCheckBox.Value = app.Trck.get_param('graph_apply_manual_cfg');
            app.MaxiterationsSpinner.Value = app.Trck.get_param('graph_max_iterations');
            app.PairssearchdepthSpinner.Value = app.Trck.get_param('graph_pairs_maxdepth');
            app.ImpossiblespeedSpinner.Value = app.Trck.get_param('graph_impossible_speed')*1000;
            app.ToolongtobewrongSpinner.Value = app.Trck.get_param('export_too_long_to_be_wrong');
            
            % other options tab
            app.SaveimagesofsingleanttrackletsCheckBox.Value = app.Trck.get_param('tracking_saveimages');
            app.SaveimagesofsingleanttrackletsCheckBox.Enable = ~app.Trck.get_param('tagged');
            app.TrackoninterruptCheckBox.Value = app.Trck.get_param('videos_track_on_interrupt');
            app.ExportxydatauntaggedCheckBox.Value = ismember('export_xy_untagged',app.Trck.get_param('single_video_post_commands'));
            
        end
        
        function update_ids(app)
            
            tagged = app.Trck.get_param('tagged');
            tagging_type = app.Trck.get_param('tagging_type');
            
            for i=1:length(app.TaggingButtonGroup.Children)
                app.TaggingButtonGroup.Children(i).Value = strcmp(tagging_type, app.TaggingButtonGroup.Children(i).Text);
            end
            
            app.SaveimagesofsingleanttrackletsCheckBox.Enable = ~tagged;
     
            a = findobj(app.IDsGridLayout);
            for i=1:length(a)
                try
                    set(a(i), 'Enable', tagged);
                catch
                end
            end
            
            a = findobj(app.PropagationGridLayout);
            
            for i=1:length(a)
                try
                    set(a(i), 'Enable', tagged);
                catch
                end
            end
            
            for i=1:length(app.TaggingButtonGroup.Children)
                app.TaggingButtonGroup.Children(i).Enable = true;
            end


            if isfolder(app.Trck.classdir)
                app.ClassifierEditField.Value = app.Trck.classdir;
            end
            
            app.BCheckBox.Value = ismember('B',app.Trck.labels.tagcolors);
            app.GCheckBox.Value = ismember('G',app.Trck.labels.tagcolors);
            app.OCheckBox.Value = ismember('O',app.Trck.labels.tagcolors);
            app.LCheckBox.Value = ismember('L',app.Trck.labels.tagcolors);
            app.PCheckBox.Value = ismember('P',app.Trck.labels.tagcolors);
            app.RCheckBox.Value = ismember('R',app.Trck.labels.tagcolors);
            app.YCheckBox.Value = ismember('Y',app.Trck.labels.tagcolors);
            app.WCheckBox.Value = ismember('W',app.Trck.labels.tagcolors);
            
            if isempty(app.Trck.labels.ant_labels)
                app.AntIDsTextArea.Value = '';
            else
                app.AntIDsTextArea.Value = tocol(app.Trck.labels.ant_labels);
            end
            
            if isempty(app.Trck.labels.noant_labels)
                app.NoAntTextArea.Value = '';
            else
                app.NoAntTextArea.Value = tocol(app.Trck.labels.noant_labels);
            end
            
            if isempty(app.Trck.labels.other_labels)
                app.OtherTextArea.Value = '';
            else
                app.OtherTextArea.Value = tocol(app.Trck.labels.other_labels);
            end
            
            app.NColorsLabel.Text = ['(',num2str(numel(app.Trck.labels.tagcolors)),')'];
            app.NAntsLabel.Text  = ['(',num2str(numel(app.Trck.labels.ant_labels)),')'];
            app.NNoAntLabel.Text = ['(',num2str(numel(app.Trck.labels.noant_labels)),')'];
            app.NOtherLabel.Text = ['(',num2str(numel(app.Trck.labels.other_labels)),')'];
            
            app.HorizontalsymmetryCheckBox.Value = app.Trck.get_param('symmetric_tags');
            
        end
        
        function update_single_ants(app)
            
            meanareamin = app.Trck.get_param('thrsh_meanareamin');
            meanareamax = app.Trck.get_param('thrsh_meanareamax');
            
            scale = app.Trck.get_param('geometry_rscale');
            scale2 = scale^2;
            
            if app.SICheckBox.Value
                
                app.SAMinAreaSpinner.Step = 0.1;
                app.SAMaxAreaSpinner.Step = 0.1;
                
                app.SAMinAreaSpinner.ValueDisplayFormat = '%g mm sq';
                app.SAMaxAreaSpinner.ValueDisplayFormat = '%g mm sq';
                
                app.SAMinAreaSpinner.Value = round(10*meanareamin*1e6)/10;
                app.SAMaxAreaSpinner.Value = round(10*meanareamax*1e6)/10;
                
            else
                
                app.SAMinAreaSpinner.Step = 1;
                app.SAMaxAreaSpinner.Step = 1;
                
                app.SAMinAreaSpinner.ValueDisplayFormat = '%g pixels';
                app.SAMaxAreaSpinner.ValueDisplayFormat = '%g pixels';
                
                app.SAMinAreaSpinner.Value = round(meanareamin/scale2);
                app.SAMaxAreaSpinner.Value = round(meanareamax/scale2);


            end


        end
        
        function update_scale(app)
            
            scale = app.Trck.get_param('geometry_rscale');
            lfs = app.Trck.get_param('geometry_scale_tool_meas');
            tool = app.Trck.get_param('geometry_scale_tool');
            tool(1) = upper(tool(1));
            app.ScaleChooseShapeDropDown.Value = tool;
            app.DimensionEditField.Value = lfs;
            app.ScaleEditField.Value = 1/(1000*scale);
            
            if strcmp(app.Trck.get_param('geometry_scale_tool'),'Circle')
                app.DimensionEditFieldLabel.Text = 'Diameter:';
            else
                app.DimensionEditFieldLabel.Text = 'Length:';
            end
            
        end
        
        function update_colonies(app)
            
            multi = app.Trck.get_param('geometry_multi_colony');
            
            circshift = angle(deg2rad(app.CircularshiftdegEditField.Value));
            nummeth = app.ColonyNumberingDropDown.Value;
            
            msk = app.Trck.Masks.roi(:,:,1)>0;
            L = bwlabel(msk);
            S = regionprops(msk,'BoundingBox','ConvexImage','Centroid','PixelIdxList');
            cent = cat(1,S.Centroid);
            N = bweuler(app.Trck.Masks.roi(:,:,1));
            if ~multi
                
                app.Trck.Masks.colony = app.Trck.Masks.roi(:,:,1);
                app.Trck.set_param('geometry_multi_colony',false);
                app.Trck.set_param('geometry_colony_labels',{});
                app.Trck.set_param('geometry_Ncolonies',1);
                
            else
                
                jnd = min(pdist(cent))/4;
                if ~isempty(jnd)
                    rcent = jnd*round(cent/jnd);
                else
                    rcent = cent;
                end
                app.Trck.set_param('geometry_multi_colony',true);
                
                
                if N>0
                    switch nummeth
                        case 'Vertical'
                            
                            a = 1000*rcent(:,2)+rcent(:,1);
                            newlabels = argsort(a);
                            
                        case 'Horizontal'
                            
                            a = 1000*rcent(:,1)+rcent(:,2);
                            newlabels = argsort(a);
                            
                        case 'Clockwise'
                            
                            mcent = mean(cent);
                            cent1 = cent-repmat(mcent,size(cent,1),1);
                            theta = angle(cart2pol(cent1(:,2),cent1(:,1)));
                            theta = -theta-circshift;
                            newlabels = argsort(theta);
                            
                        case 'CounterClockwise'
                            
                            mcent = mean(cent);
                            cent1 = cent-repmat(mcent,size(cent,1),1);
                            theta = angle(cart2pol(cent1(:,2),cent1(:,1)));
                            theta = -theta-circshift;
                            newlabels = argsort(-theta);
                            
                    end
                    
                    S = S(newlabels);
                    cent = cent(newlabels,:);
                    for k = 1:numel(S)
                        kth_object_idx_list = S(k).PixelIdxList;
                        L(kth_object_idx_list) = k;
                    end
                    
                    N = length(unique(L(:)))-1;
                    app.Trck.set_param('geometry_Ncolonies',N);
                    for i=1:N
                        colony_labels{i}=['C',num2str(i)];
                        cmsks(:,:,i) = L==i;
                    end
                    
                    
                    if length(colony_labels)>=length(app.user_colony_labels)
                        colony_labels(1:length(app.user_colony_labels)) =  app.user_colony_labels;
                    else
                        colony_labels =  app.user_colony_labels(1:length(colony_labels));
                    end
                    
                    
                    app.Trck.set_param('geometry_colony_labels',colony_labels);
                    app.Trck.set_param('geometry_multi_colony_numbering',nummeth);
                    app.Trck.set_param('geometry_multi_colony_circ_shift',circshift);
                    app.Trck.Masks.colony = cmsks;
                end
            end
            
            delete(app.colony_nums(isvalid(app.colony_nums)))
            
            if multi
                for i=1:N
                    app.colony_nums(i) = text(app.ax,cent(i,1),cent(i,2),num2str(i),'FontSize',30,'FontWeight','bold','Color',[1,0.2,0.2]);
                end
            end
            
            app.colony_map_to_save = app.Trck.Masks.roi*255;
            if multi && N>0
                app.colony_map_to_save = insertText(app.colony_map_to_save,cent-18,1:N,'TextColor','red','FontSize',72,'BoxOpacity',0);
            end
            
        end
        
        function set_scale_from_hroi(app)
            
            if ~isvalid(app.hroi)
                return
            end
            
            switch class(app.hroi)
                case 'images.roi.Ellipse'
                    Dpix = sum(app.hroi.SemiAxes);
                    s.type = 'ellipse';
                    s.SemiAxes = app.hroi.SemiAxes;
                    s.Center = app.hroi.Center;
                    s.RotationAngle = app.hroi.RotationAngle;
                    s.Vertices = app.hroi.Vertices;
                case 'images.roi.Line'
                    s.type = 'line';
                    s.Position = app.hroi.Position;
                    Dpix = app.hroi.Position(1,:)-app.hroi.Position(2,:);
                    Dpix = sqrt(sum(Dpix.^2));
                otherwise
                    report('E','Something wrong with roi')
            end
            
            lfs = app.DimensionEditField.Value;
            scale = lfs/(1000*Dpix);
            app.ImageDimEditField.Value = Dpix;
            app.Trck.set_param('geometry_rscale', scale);
            app.Trck.set_param('geometry_scale_tool',app.ScaleChooseShapeDropDown.Value);
            app.Trck.set_param('geometry_scale_tool_params',s);
            %sqsz = 50*(app.Trck.get_param('geometry_scale0')/app.Trck.get_param('geometry_rscale'));
            %app.Trck.set_param('sqsz',2*round(sqsz/2));
            app.scale_tool_pix = Dpix;
            update_scale(app);
            app.StatusBar2.Text = ['Scale set to ',num2str(scale),' pix/mm'];
        end
        
        function update_bg_list(app)
            
            % list files in bgdir
            files=dir([app.bgdir,'background*.png']);
            
            if ~isempty(files)
                app.ShowBackgroundCheckBox.Enable = true;
                app.BGFileDropDown.Enable = true;
                app.BGFileDropDown.Items = {files.name};
                app.BGFileDropDown.Value = app.BGFileDropDown.Items{1};
            else
                app.ShowBackgroundCheckBox.Enable = false;
                app.BGFileDropDown.Enable = false;
                app.BGFileDropDown.Items = {''};
                app.BGFileDropDown.Value = '';
            end
            
        end
        
        function update_frame_selectors(app, t)
            
            app.current_frame_t = t;
            
            if app.current_frame_t.m ~= app.MovieNumberSpinner_BG.Value
                m = app.current_frame_t.m;
                set(findobj(app.UIFigure, '-regexp','Tag','^*MovieFrame*','Type','uispinner'), 'Limits', [1,app.Trck.er.movies_info(m).nframes]);
            end
            
            set(findobj(app.UIFigure, '-regexp','Tag','^*MovieNumber*','Type','uispinner'), 'Value', t.m);
            set(findobj(app.UIFigure, '-regexp','Tag','^*MovieFrame*','Type','uispinner'), 'Value', t.mf);
            set(findobj(app.UIFigure, '-regexp','Tag','^*AbsoluteFrame*','Type','uispinner'), 'Value', t.f);
            
        end
        
        
        function update_image(app)
            
            % if frame is displayed


            if app.TabGroup.SelectedTab == app.LinkingTab && app.current_frame_t == 1
                
                app.update_frame_selectors(app.current_frame_t+1);
                
            end
                
            if app.TabGroup.SelectedTab == app.LinkingTab
                
                
                if isempty(app.Trck.prevfrm.t) || app.Trck.prevfrm.t ~= app.current_frame_t - 1 
                    app.Trck.read_frame(app.current_frame_t - 1);
                    detect_blobs(app.Trck);
                    app.Trck.read_frame(app.current_frame_t);
                    detect_blobs(app.Trck);
                end
                
                linkfun = app.Trck.get_param('linking_method');
                linkfun(app.Trck);
                
                if app.CurrentframeButton.Value
                    im = app.Trck.currfrm.single;
                    frm = app.Trck.currfrm;
                elseif app.PreviousframeButton.Value
                    im = app.Trck.prevfrm.single;
                    frm = app.Trck.prevfrm;
                elseif app.BlendButton.Value
                    gry1 = 1-rgb2gray(app.Trck.prevfrm.Z);
                    gry2 = 1-rgb2gray(app.Trck.currfrm.Z);
                    im = cat(3,gry1,gry2,gry2);
                end
                
            elseif app.TabGroup.SelectedTab == app.ClassificationTab && app.ColorcorrectionCheckBox.Value
                
                if app.Trck.currfrm.t ~= app.current_frame_t
                    app.Trck.read_frame(app.current_frame_t);
                end
                
                [~,~,BGW] = app.Trck.get_bg(app.Trck.currfrm.t);
                im = app.Trck.currfrm.single./BGW;
                
            elseif strcmp(app.currently_displaying, 'frame') || isempty(app.BGFileDropDown.Value)
                
                if app.Trck.currfrm.t ~= app.current_frame_t
                    app.Trck.read_frame(app.current_frame_t);
                end
                
                im = app.Trck.currfrm.single;
                app.ShowBackgroundCheckBox.Value = false;
                app.ShowFrameCheckBox.Value = true;
                
            elseif strcmp(app.currently_displaying, 'bg') && app.TabGroup.SelectedTab == app.BackgroundTab
                
                im = im2single(imread([app.bgdir,app.BGFileDropDown.Value]));
                app.ShowBackgroundCheckBox.Value = true;
                app.ShowFrameCheckBox.Value = false;
                
            elseif strcmp(app.currently_displaying, 'bg')
                
                im = app.Trck.Backgrounds.bg_single;
                app.ShowBackgroundCheckBox.Value = true;
                app.ShowFrameCheckBox.Value = false;
                
            end
            
            
            %
            if app.display_segmentation && ~isempty(app.Trck.Backgrounds)
                
                detect_blobs(app.Trck);
                
                if app.TabGroup.SelectedTab == app.SegmentationTab && app.ShowBGsubtractedimageCheckBox.Value
                    
                    im = 1-app.Trck.currfrm.Z;
                    
                end
                
            end
            
            % if need to display mask
            if app.display_mask && isfield(app.Trck.Masks,'roi')
                
                
                alpha = app.AlphaSlider.Value;
                msk = single(app.Trck.Masks.roi);
                im = im.*msk + im.*(1-msk).*alpha;
                %im = imdivide(im,3*(1-msk)+msk);
                
                if app.Trck.get_param('geometry_open_boundry') && isfield(app.Trck.Masks,'open_boundry_perimeter')
                    
                    open_boundry = imdilate(app.Trck.Masks.open_boundry_perimeter(:,:,1),ones(3))>0;
                    
                    im1 = im(:,:,1);
                    im2 = im(:,:,2);
                    im3 = im(:,:,3);
                    im1(open_boundry) = 0;
                    im2(open_boundry) = 0;
                    im3(open_boundry) = 1;
                    im = cat(3,im1,im2,im3);
                    
                end
                
            end
            
            % if segmentation needed
            if app.display_segmentation && ~isempty(app.Trck.Backgrounds)
                
                ab = app.Trck.currfrm.antblob;
                S = regionprops(ab.LABEL,app.Trck.currfrm.grayIm,'ConvexHull');
                
                big = ab.rarea > app.Trck.get_param('thrsh_meanareamax');
                sml = ab.rarea < app.Trck.get_param('thrsh_meanareamin');
                
                for i=1:length(S)
                    if big(i)
                        clr(i,:) = rgb('Red');
                    elseif sml(i)
                        clr(i,:) = rgb('Magenta');
                    else
                        clr(i,:) = rgb('LimeGreen');
                    end
                end
                
                if app.TabGroup.SelectedTab == app.SegmentationTab && app.ShowsegmentedCheckBox.Value && ~isempty(S)
                    clrs = distinguishable_colors(length(S),{'w','k'});
                    im = imadd(im,im2single(label2rgb(ab.LABEL,clrs,'k')));
                elseif app.TabGroup.SelectedTab == app.ClassificationTab && app.ShowsegmentationCheckBox.Value
                    bw1 = ab.LABEL>0;
                    cmsk = im2single(label2rgb(uint8(bw1),[0,0,0.3],'k'));
                    im = imadd(im,cmsk);
                    if app.BlobmaskdilationCheckBox.Value
                        bw2 = imdilate(bw1, ones(app.BlobMaskDilateSpinner.Value));
                        cmsk = im2single(label2rgb(uint8(bw2-bw1),[0.3,0,0.3],'k'));
                        im = imadd(im, cmsk);
                    end
                end
                
            end
                        
            
            try
                delete(app.chplot);
            catch
            end
            app.chplot = [];
            try
                delete(app.BlobText);
            catch
            end
            app.BlobText = [];
            app.himage.CData = im;
            hold(app.ax,'on')
            app.ax.YLabel.String = '';
            app.ax.XLabel.String = '';
            
            
            if app.display_segmentation && ~isempty(app.Trck.Backgrounds)
                if app.TabGroup.SelectedTab == app.SingleAntsTab || (app.TabGroup.SelectedTab == app.SegmentationTab && ~app.ShowsegmentedCheckBox.Value)
                    for i=1:length(S)
                        app.chplot(i) = plot(app.ax,S(i).ConvexHull(:,1),S(i).ConvexHull(:,2),'Color',clr(i,:),'LineWidth',2);
                    end
                end
                
                if app.TabGroup.SelectedTab == app.SegmentationTab && app.ShowBlobPropertiesCheckBox.Value
                    for i=1:length(S)
                        s = [num2str(ab.DATA.AREA(i)),' (',num2str(ab.DATA.MAXZ(i)),')'];
                        app.BlobText(i) = text(app.ax,ab.DATA.CENTROID(i,1),ab.DATA.CENTROID(i,2),s,'Color',clr(i,:));
                    end
                end
            end
            
            
            if app.TabGroup.SelectedTab == app.LinkingTab && app.LinkingclustersCheckBox.Value && isfield(app.Trck.currfrm,'LinkingClusters')
                
                S = regionprops(app.Trck.currfrm.LinkingClusters,'ConvexHull');
                for i=1:length(S)
                    if app.Trck.currfrm.cluster_nblobs_prev(i) > 1 || app.Trck.currfrm.cluster_nblobs_curr(i) > 1
                        clr = [0,0,1];
                    else
                        clr = [0.8,0.8,1];
                    end
                    app.chplot(i) = plot(app.ax,S(i).ConvexHull(:,1),S(i).ConvexHull(:,2),'Color',clr,'LineWidth',2);
                end
            end
            
            if app.TabGroup.SelectedTab==app.ROITab
                update_colonies(app)
            else
                delete(app.colony_nums(isvalid(app.colony_nums)))
            end
        end
        
        function create_bg(app)
            
            app.Trck.set_param('background_method',app.MethodDropDown.Value);
            app.Trck.set_param('background_nframes',app.NumberofFramesSpinner.Value);
            app.Trck.set_param('background_per_subdir',app.BGperSubdirCheckBox.Value);
            
            fi = app.FromFrameEditField.Value;
            ff = app.ToFrameEditField.Value;
            app.Trck.set_param('background_frame_range',[fi,ff]);
            
            d = uiprogressdlg(app.UIFigure,'Title','Creating background image(s)','Indeterminate','on');
            create_backgrounds(app.Trck, d);
                    
            app.currently_displaying = 'bg';
            app.ShowBackgroundCheckBox.Value = true;
            app.ShowFrameCheckBox.Value = false;
            
            update_bg_list(app);
            
            update_image(app);
            close(d)
            app.StatusBar2.Text = ['Background image created'];
            
        end
        
        function wait_for_scale_roi(app)
            
            % Listen for mouse clicks on the ROI
            l_click = addlistener(app.hroi,'ROIClicked',@(src,evt) resume_after_roi(app,src,evt));
            l_move = addlistener(app.hroi,'ROIMoved',@(src,evt) set_scale_from_hroi(app));
            
            % Block program execution
            uiwait(app.UIFigure);
            
            % Remove listener
            delete(l_click);
            delete(l_move)
            
        end
        
        function wait_for_roi(app)
            
            l_click = addlistener(app.hroi,'ROIClicked',@(src,evt) resume_after_roi(app,src,evt));
            uiwait(app.UIFigure);
            delete(l_click);
            
        end
        
        function resume_after_roi(~,~,evt)
            
            if nargin==1 || strcmp(evt.SelectionType,'double')
                uiresume(gcbf);
            end


        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Trck, varargin)
            
        
            report('D', 'starting antrax configure app')
        
            mkdirp(app.antraxdir)
            
            if nargin<2
                Trck = uigetdir;
            end
            
            if ~isa(Trck, 'trhandles') && ~(ischar(Trck) && isfolder(Trck))
                report('E', 'Invalid experimental directory given')
                delete(app)
                return
            end
        
            p = inputParser;
            addRequired(p,'Trck',@(x) isa(x,'trhandles') || isfolder(x));
            addParameter(p, 'session', [], @ischar)
            parse(p,Trck,varargin{:});
            
            
            % replace the uiaxes with regular axes
            position = app.UIAxes.Position;
            delete(app.UIAxes)
            app.ax = axes(app.UIFigure,'Units','pixels');
            set(app.ax,'Position',position)
                                            
            app.TabGroup.Visible = false;
            app.ax.Visible = 'off';
            
            % set tags
            fn = fieldnames(app);
            for i=1:length(fn)
                if isa(app.(fn{i}),'matlab.ui.control.internal.model.ComponentModel')
                    app.(fn{i}).Tag=fn{i};
                end
            end


            % load_recent_expdirs;
            recents = load_recent_expdirs;
            delete(app.RecentMenu.Children)
            for i=1:length(recents)
                
                sm = uimenu(app.RecentMenu);
                sm.Text = recents{i};
                sm.MenuSelectedFcn = createCallbackFcn(app, @RecentExpMenuSelected, true);
                app.RecentMenu.Enable = true;
            end
            
            set_experiment(app, Trck, p.Results.session)
            
            report('D', 'antrax configure app ready!')
          
        end

        % Callback function: AbsoluteFrameSpinner_BG, 
        % AbsoluteFrameSpinner_CL, AbsoluteFrameSpinner_LK, 
        % AbsoluteFrameSpinner_SA, AbsoluteFrameSpinner_SEG, 
        % MovieFrameSpinner_BG, MovieFrameSpinner_CL, 
        % MovieFrameSpinner_LK, MovieFrameSpinner_SA, 
        % MovieFrameSpinner_SEG, MovieNumberSpinner_BG, 
        % MovieNumberSpinner_CL, MovieNumberSpinner_LK, 
        % MovieNumberSpinner_LK, MovieNumberSpinner_SA, 
        % MovieNumberSpinner_SEG
        function FrameSelectors_ValueChanged(app, event)
            value = event.Value;
            tag = event.Source.Tag;
            
            % find new t
            if contains(tag,'AbsoluteFrame')
                f = value;
                t = trtime(app.Trck, f);
            elseif contains(tag, 'MovieNumber')
                m = value;
                mf = app.current_frame_t.mf;
                t = trtime(app.Trck, m, mf);
            elseif contains(tag, 'MovieFrame')
                mf = value;
                m = app.current_frame_t.m;
                t = trtime(app.Trck, m, mf);
            end
            
            % update frame selectors
            update_frame_selectors(app, t);
            
            % display new frame
            update_image(app);
            
        end

        % Value changed function: BGperSubdirCheckBox, OneBGCheckBox
        function BGoptions_ValueChanged(app, event)
            
            value = event.Value;
            
            if event.Source == app.OneBGCheckBox
                
                app.BGperSubdirCheckBox.Value = ~value;
                
            elseif event.Source == app.BGperSubdirCheckBox
                
                app.OneBGCheckBox.Value = ~value;
                
            else
                
                error('Wrong component calling to callback')
                
            end
            
            
        end

        % Value changed function: ShowBackgroundCheckBox, 
        % ShowFrameCheckBox
        function BGViewoptions_ValueChanged(app, event)
            
            value = event.Value;
            
            if event.Source == app.ShowBackgroundCheckBox
                
                app.ShowFrameCheckBox.Value = ~value;
                
            elseif event.Source == app.ShowFrameCheckBox
                
                app.ShowBackgroundCheckBox.Value = ~value;
                
            else
                
                error('Wrong component calling to callback')
                
            end
            
            
            if app.ShowBackgroundCheckBox.Value
                
                app.currently_displaying = 'bg';
                
            else
                
                app.currently_displaying = 'frame';
                
            end
            
            update_image(app);
            
            
        end

        % Button pushed function: CreateBGButton
        function CreateBGButtonPushed(app, event)
            create_bg(app)
            
        end

        % Button pushed function: ScaleDrawButton
        function ScaleDrawButtonPushed(app, event)
            
            try
                delete(app.hroi)
            catch
                
            end
            
            sz = app.Trck.er.frame_size;
            sz = [sz(2),sz(1)];
            s = app.Trck.get_param('geometry_scale_tool_params');
            
            switch app.ScaleChooseShapeDropDown.Value
                case 'Circle'
                    if ~strcmp(s.type,'ellipse')
                        s.Center = round(sz/2);
                        s.RotationAngle = 0;
                        s.SemiAxes = round(sz/4);
                    end
                    app.hroi = drawellipse(app.ax,'Parent',app.ax,'Center',s.Center,'RotationAngle',s.RotationAngle,'SemiAxes',s.SemiAxes);
                case 'Line'
                    if ~strcmp(s.type,'line')
                        s.Position = [round(sz/4);round(3*sz/4)];
                    end
                    app.hroi = drawline(app.ax,'Parent',app.ax,'Position',s.Position);
            end
            
            app.hroi.DrawingArea = 'unlimited';
            
            app.wait_for_roi;
            
            app.set_scale_from_hroi;
            
            delete(app.hroi)
            
        end

        % Value changed function: DimensionEditField
        function DimensionEditFieldValueChanged(app, event)
            lfs = app.DimensionEditField.Value;
            app.Trck.set_param('geometry_scale_tool_meas',lfs);
            if ~isnan(app.scale_tool_pix)
                scale = lfs/(1000*app.scale_tool_pix);
                app.Trck.set_param('geometry_rscale', scale);
            end
            update_scale(app);
        end

        % Button pushed function: ScaleDoneButton
        function ScaleDoneButtonPushed(app, event)
            if isvalid(app.hroi)
                app.resume_after_roi;
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            
            selectedTab = app.TabGroup.SelectedTab;
            prevTab = event.OldValue;
            
            if prevTab == app.ROITab
                app.Trck.save_masks;
            end
            
            switch selectedTab
                
                case app.ROITab
                    
                    app.currently_displaying = 'bg';
                    app.display_mask = true;
                    app.display_segmentation = false;
                    update_image(app)
                    
                case app.BackgroundTab
                    
                    if app.ShowBackgroundCheckBox.Value && isfield(app.Trck.Backgrounds,'bg_single')
                        app.currently_displaying = 'bg';
                    else
                        app.currently_displaying = 'frame';
                    end
                    app.display_mask = false;
                    app.display_segmentation = false;
                    update_image(app)
                    
                case app.SegmentationTab
                    
                    app.currently_displaying = 'frame';
                    app.display_mask = app.ShowROICheckBox.Value;
                    app.display_segmentation = true;
                    
                    % make sure bg was created
                    if isempty(app.Trck.Backgrounds)
                        uialert(app.UIFigure,'Please create a background image first!', 'Warning','Icon','warning')
                    else
                        update_image(app)
                    end
                    
                case app.SingleAntsTab
                    
                    app.currently_displaying = 'frame';
                    app.display_mask = app.ShowROICheckBox.Value;
                    app.display_segmentation = true;
                    
                    % make sure bg was created
                    if isempty(app.Trck.Backgrounds)
                        uialert(app.UIFigure,'Please create a background image first!', 'Warning','Icon','warning')
                    else
                        update_image(app)
                    end
                    
                case app.ClassificationTab
                    
                    app.currently_displaying = 'frame';
                    app.display_mask = true;
                    app.display_segmentation = true;
                    
                    % make sure bg was created
                    if isempty(app.Trck.Backgrounds)
                        uialert(app.UIFigure,'Please create a background image first!', 'Warning','Icon','warning')
                    else
                        update_image(app)
                    end
                    
                case app.LinkingTab
                    
                    app.currently_displaying = 'frame';
                    app.display_mask = true;
                    app.display_segmentation = false;
                    
                    % make sure bg was created
                    if isempty(app.Trck.Backgrounds)
                        uialert(app.UIFigure,'Please create a background image first!', 'Warning','Icon','warning')
                    else
                        update_image(app)
                    end
                    
                otherwise
                    
            end

        end

        % Button pushed function: ResettoWhiteButton
        function ResettoWhiteButtonPushed(app, event)
            app.Trck.Masks.roi = true(size(app.Trck.Masks.roi));
            update_image(app);
        end

        % Button pushed function: ResettoBlackButton
        function ResettoBlackButtonPushed(app, event)
            app.Trck.Masks.roi = false(size(app.Trck.Masks.roi));
            update_image(app);
        end

        % Button pushed function: AddButton, OpenBoundryAddButton, 
        % OpenBoundryRemoveButton, RemoveButton
        function ROIAddRemoveButtonPushed(app, event)
            
            
            try
                delete(app.hroi)
            catch
                
            end
            
            sz = size(app.Trck.Backgrounds.bg);
            sz = [sz(2),sz(1)];
            
            switch app.ROIChooseShapeDropDown.Value
                case 'Ellipse'
                    if isfield(app.prev_roi_tool,'SemiAxes')
                        SemiAxes = app.prev_roi_tool.SemiAxes;
                    else
                        SemiAxes = round(sz/4);
                    end
                    app.hroi = drawellipse(app.ax,'Parent',app.ax,'LineWidth',1,'Center',round(sz/2),'SemiAxes',SemiAxes);
                case 'Rectangle'
                    app.hroi = drawrectangle(app.ax,'Parent',app.ax,'LineWidth',1,'Position',[round(sz/4),round(sz/2)]);
                case 'Polygon'
                    app.hroi = drawpolygon(app.ax,'Parent',app.ax);
                case 'Freehand'
                    app.hroi = drawfreehand(app.ax,'Parent',app.ax);
                case 'Assisted'
                    app.hroi = drawassisted(app.himage,'Parent',app.ax);
                    
            end
            
            if ~strcmp(app.ROIChooseShapeDropDown.Value,'Assisted')
                app.hroi.DrawingArea = 'unlimited';
            end
            
            app.wait_for_roi;
            
            if isvalid(app.hroi)
                new_roi = createMask(app.hroi);
            else
                report('D', 'invalid roi object')
                return
            end
            
            if isa(app.hroi,'images.roi.Ellipse')
                app.prev_roi_tool(1).SemiAxes = app.hroi.SemiAxes;
            end
            
            if event.Source == app.AddButton
                
                app.Trck.Masks.roi = app.Trck.Masks.roi | repmat(new_roi,[1,1,3]);
                
            elseif event.Source == app.RemoveButton
                
                app.Trck.Masks.roi = app.Trck.Masks.roi & ~repmat(new_roi,[1,1,3]);
                
            elseif event.Source == app.OpenBoundryAddButton
                if ~isfield(app.Trck.Masks,'open_boundry')
                    app.Trck.Masks.open_boundry = zeros(size(app.Trck.Masks.roi),'uint8');
                end
                app.Trck.Masks.open_boundry = app.Trck.Masks.open_boundry | repmat(new_roi,[1,1,3]);
                app.Trck.Masks.open_boundry_perimeter = app.Trck.Masks.open_boundry & repmat(bwperim(app.Trck.Masks.roi(:,:,1)),[1,1,3]);
                
            elseif event.Source == app.OpenBoundryRemoveButton
                if ~isfield(app.Trck.Masks,'open_boundry')
                    app.Trck.Masks.open_boundry = zeros(size(app.Trck.Masks.roi),'uint8');
                end
                app.Trck.Masks.open_boundry = app.Trck.Masks.open_boundry & ~repmat(new_roi,[1,1,3]);
                app.Trck.Masks.open_boundry_perimeter = app.Trck.Masks.open_boundry & repmat(bwperim(app.Trck.Masks.roi(:,:,1)),[1,1,3]);
            else
                
                report('E','Something wrong')
            end
            
            app.Trck.save_masks;
            
            app.Trck.Masks.tracking = app.Trck.Masks.roi;
            update_image(app);
            delete(app.hroi)
            
        end

        % Value changed function: MultiColonyCheckBox
        function MultiColonyCheckBoxValueChanged(app, event)
            
            app.Trck.set_param('geometry_multi_colony',app.MultiColonyCheckBox.Value);
            app.ColonyNumberingDropDown.Enable = app.Trck.get_param('geometry_multi_colony');
            app.CircularshiftdegEditField.Enable = app.Trck.get_param('geometry_multi_colony')...
                && ismember(app.Trck.get_param('geometry_multi_colony_numbering'),{'Clockwise','CounterClockwise'});
            
            update_colonies(app);
            
        end

        % Value changed function: CircularshiftdegEditField, 
        % ColonyNumberingDropDown
        function ColonyNumberingDropDownValueChanged(app, event)
            app.Trck.set_param('geometry_multi_colony_numbering',app.ColonyNumberingDropDown.Value);
            app.CircularshiftdegEditField.Enable = app.Trck.get_param('geometry_multi_colony')...
                && ismember(app.Trck.get_param('geometry_multi_colony_numbering'),{'Clockwise','CounterClockwise'});
            
            update_colonies(app);
            
        end

        % Menu selected function: ROImaskfromfileMenu
        function LoadfromfileButtonPushed(app, event)
            
            defPath = '~/.antrax/masks/';
            mkdirp(defPath);
            
            [FileName,PathName] = uigetfile('*','Select mask file:',defPath);
            
            
            if strcmp(FileName(end-3:end),'.mat')
                try
                    load([PathName,filesep,FileName],'roimsk');
                catch
                    uialert(app.UIFigure,'Failed to load mask from file','anTraX Error','Icon','error')
                    return
                end
            else
                
                try
                    im = imread([PathName,filesep,FileName]);
                    roimsk = im>0;
                catch
                    uialert(app.UIFigure,'Failed to load mask from file','anTraX Error','Icon','error')
                    return
                end
            end
            
            if ~size(roimsk,1)==size(app.Trck.currfrm.CData,1) || ~size(roimsk,2)==size(app.Trck.currfrm.CData,2)
                uialert(app.UIFigure,'Size of loaded mask does not match frames! Abborting..','anTraX Error','Icon','error')
                return
            end
            
            app.Trck.Masks.roi = roimsk;
            app.Trck.Masks.hshift = 0;
            app.Trck.Masks.vshift = 0;
            
            update_colonies(app);
            
            app.Trck.save_masks;
            app.Trck.load_masks;
            
        end

        % Menu selected function: ROImasktofileMenu
        function SavetofileButtonPushed(app, event)
            
            if ~isfield(app.Trck.Masks,'roi')
                uialert(app.UIFigure,'ROI mask is not yet defined for current session','anTraX Error','Icon','error');
                return
            end
        
            defPath = '~/.antrax/masks/';
            mkdirp(defPath);
            
            [FileName,PathName,FilterIndex] = uiputfile('*','Select save location:',[defPath,'mask.png']);
            if strcmp(FileName(end-3:end),'.mat')
                roimsk = app.Trck.Masks.roi;
                save([PathName,filesep,FileName],roimsk);
            else
                imwrite(uint8(app.Trck.Masks.roi)*255,[PathName,filesep,FileName]);
            end
            

        end

        % Value changed function: AdaptivethresholdCheckBox, 
        % BlobMaskDilateSpinner, BlobmaskdilationCheckBox, 
        % ClosingSpinner, ColorcorrectionCheckBox, 
        % DarkbackgroundCheckBox, FillHolesCheckBox, 
        % MinAreapixCheckBox, MinIntensityCheckBox, 
        % MinimumAreaSpinner, MinimumIntensitySpinner, 
        % MorphClosingCheckBox, MorphOpeningCheckBox, 
        % OpeningSpinner, ThresholdCheckBox, ThresholdSpinner, 
        % UseConvexHullCheckBox
        function SegmentationParamsValueChanged(app, event)
            
            switch event.Source
                
                case app.DarkbackgroundCheckBox
                    app.Trck.set_param('segmentation_invert',app.DarkbackgroundCheckBox.Value);
                
                case app.MinAreapixCheckBox
                    app.Trck.set_param('segmentation_BlobAreaFilter',app.MinAreapixCheckBox.Value);
                    app.MinimumAreaSpinner.Enable = app.MinAreapixCheckBox.Value;
                
                case app.ThresholdSpinner
                    app.Trck.set_param('segmentation_threshold',app.ThresholdSpinner.Value);
                    
                case app.AdaptivethresholdCheckBox
                    app.Trck.set_param('segmentation_local_z_scaling',app.AdaptivethresholdCheckBox.Value);
                    
                case app.MinimumAreaSpinner
                    app.Trck.set_param('segmentation_MinimumBlobArea',app.MinimumAreaSpinner.Value);
                    
                case app.MorphClosingCheckBox
                    app.Trck.set_param('segmentation_ImClosing',app.MorphClosingCheckBox.Value);
                    app.ClosingSpinner.Enable = app.MorphClosingCheckBox.Value;
                    
                case app.ClosingSpinner
                    app.Trck.set_param('segmentation_ImClosingSize',app.ClosingSpinner.Value);
                    app.Trck.set_param('segmentation_ImClosingStrel',strel('disk',app.ClosingSpinner.Value));
                    
                case app.MorphOpeningCheckBox
                    app.Trck.set_param('segmentation_ImOpenning',app.MorphOpeningCheckBox.Value);
                    app.OpeningSpinner.Enable = app.MorphOpeningCheckBox.Value;
                    
                case app.OpeningSpinner
                    app.Trck.set_param('segmentation_ImOpenningSize',app.OpeningSpinner.Value);
                    app.Trck.set_param('segmentation_ImOpenningStrel',strel('disk',app.OpeningSpinner.Value));
                    
                case app.MinIntensityCheckBox
                    app.Trck.set_param('segmentation_IntensityFilter',app.MinIntensityCheckBox.Value);
                    app.MinimumIntensitySpinner.Enable = app.MinIntensityCheckBox.Value;
                    
                case app.MinimumIntensitySpinner
                    app.Trck.set_param('segmentation_MaxIntensityThreshold',app.MinimumIntensitySpinner.Value);
                    
                case app.UseConvexHullCheckBox
                    app.Trck.set_param('segmentation_useConvexHull',app.UseConvexHullCheckBox.Value);
                    
                case app.FillHolesCheckBox
                    app.Trck.set_param('segmentation_fillHoles',app.FillHolesCheckBox.Value);
                
                case app.BlobmaskdilationCheckBox
                    app.Trck.set_param('segmentation_blob_mask_dilate',app.BlobmaskdilationCheckBox.Value);
                    
                case app.BlobMaskDilateSpinner
                    app.Trck.set_param('segmentation_blob_mask_dilate_radius',app.BlobMaskDilateSpinner.Value);
                
                case app.ColorcorrectionCheckBox
                    app.Trck.set_param('segmentation_color_correction_for_classification',app.ColorcorrectionCheckBox.Value);
                
            end
          
            app.Trck.set_param('segmentation_SplitDoubleAntBlobs',false);
            
            % resegment frame
            if ~isempty(app.Trck.Backgrounds)
                detect_blobs(app.Trck);
            end
            
            update_image(app);
            
        end

        % Value changed function: ShowBGsubtractedimageCheckBox, 
        % ShowBlobPropertiesCheckBox, ShowROICheckBox, 
        % ShowsegmentationCheckBox, ShowsegmentedCheckBox
        function SegmentationViweOptionsChanged(app, event)
            
            if event.Source == app.ShowROICheckBox 
                app.display_mask = event.Source.Value;
                app.ShowROICheckBox.Value = event.Source.Value;
                %app.ShowROICheckBox_2.Value = event.Source.Value;
            end
            
            update_image(app)
        end

        % Value changed function: PixelsCheckBox, SAMaxAreaSpinner, 
        % SAMinAreaSpinner, SICheckBox
        function SAParamsValueChanged(app, event)
            
            if event.Source == app.PixelsCheckBox
                app.SICheckBox.Value = ~app.PixelsCheckBox.Value;
                update_single_ants(app)
            elseif event.Source == app.SICheckBox
                app.PixelsCheckBox.Value = ~app.SICheckBox.Value;
                update_single_ants(app)
            else
                if app.SICheckBox.Value
                    app.Trck.set_param('thrsh_meanareamin',app.SAMinAreaSpinner.Value * 1e-6);
                    app.Trck.set_param('thrsh_meanareamax',app.SAMaxAreaSpinner.Value * 1e-6);
                else
                    scale2 = app.Trck.get_param('geometry_rscale')^2;
                    app.Trck.set_param('thrsh_meanareamin',app.SAMinAreaSpinner.Value * scale2);
                    app.Trck.set_param('thrsh_meanareamax',app.SAMaxAreaSpinner.Value * scale2);
                end
                update_single_ants(app)
                update_image(app)
            end

        end

        % Menu selected function: OpenExperimentMenu
        function OpenExperimentMenuSelected(app, event)
        
           if isfolder(app.expdir)
               startdir = app.expdir;
           else
               startdir = pwd;
           end
           
           expdir = uigetdir(startdir,'Open experiment');
           
           focus(app)
           
           if isnumeric(expdir) || ~isfolder(expdir)
               report('W','Invalid expdir')
           else
               set_experiment(app, expdir);
           end


        end

        % Value changed function: BCheckBox, GCheckBox, LCheckBox, 
        % OCheckBox, PCheckBox, RCheckBox, WCheckBox, YCheckBox
        function TagColorsChanged(app, event)
            
            prev_colors = app.Trck.labels.tagcolors;
            new_colors = {};
            
            for i=1:length(app.PossColors)
                c = app.PossColors{i};
                v = eval(['app.',c,'CheckBox.Value;']);
                if v
                    new_colors{end+1} = c;
                end
            end
            
            removed = setdiff(prev_colors,new_colors);
            added = setdiff(new_colors,prev_colors);
            
            ant_labels = app.Trck.labels.ant_labels;
            
            for i=1:length(removed)
                c = removed{i};
                ant_labels = ant_labels(~contains(ant_labels,c));
            end
            
            for i=1:length(added)
                c = added{i};
                for j=1:length(new_colors)
                    ant_labels{end+1} = [new_colors{j},c];
                    ant_labels{end+1} = [c,new_colors{j}];
                end
            end

            app.PossIDs = all_possible_ids(new_colors);
            
            ant_labels = unique(ant_labels);
            ant_labels = sort(ant_labels);
            
            app.Trck.labels.tagcolors = new_colors;
            
            
            if app.Autogenerate2tagIDsCheckBox.Value
                app.Trck.labels.ant_labels = ant_labels;
            end
            
            update_ids(app);
            
        end

        % Value changed function: AntIDsTextArea, NoAntTextArea, 
        % OtherTextArea
        function IDListChanged(app, event)
            
            labels = setdiff(app.AntIDsTextArea.Value,{''});
            if app.Autogenerate2tagIDsCheckBox.Value
                labels = intersect(labels,app.PossIDs);
            end
            app.Trck.labels.ant_labels = labels;
            
            labels = setdiff(app.NoAntTextArea.Value,{''});
            if app.Autogenerate2tagIDsCheckBox.Value
                labels = setdiff(labels,app.PossIDs);
            end
            app.Trck.labels.noant_labels = labels;
            
            labels = setdiff(app.OtherTextArea.Value,{''});
            if app.Autogenerate2tagIDsCheckBox.Value
                labels = setdiff(labels,app.PossIDs);
            end
            app.Trck.labels.other_labels = labels;
            
            app.Trck.save_labels;
            update_ids(app);
        end

        % Value changed function: OpenBoundryCheckBox
        function OpenBoundryCheckBoxValueChanged(app, event)
            if ~isfield(app.Trck.Masks,'open_boundry')
                app.Trck.Masks.open_boundry = zeros(size(app.Trck.Masks.roi),'uint8');
            end
            app.Trck.set_param('geometry_open_boundry', app.OpenBoundryCheckBox.Value);
            app.OpenBoundryAddButton.Enable = app.OpenBoundryCheckBox.Value;
            app.OpenBoundryRemoveButton.Enable = app.OpenBoundryCheckBox.Value;
            app.OpenBoundryResetButton.Enable = app.OpenBoundryCheckBox.Value;
            update_image(app)
        end

        % Button pushed function: OpenBoundryResetButton
        function OpenBoundryResetButtonPushed(app, event)
            app.Trck.Masks.open_boundry = zeros(size(app.Trck.Masks.roi),'uint8');
            app.Trck.Masks.open_boundry_perimeter = app.Trck.Masks.open_boundry;
            update_image(app)
        end

        % Callback function: QuitMenu, UIFigure
        function UIFigureCloseRequest(app, event)
            disp('app closing..')
            if ~isempty(app.Trck)
                app.Trck.save;
                
                if app.TabGroup.SelectedTab == app.ROITab
                    app.Trck.save_masks;
                end
            end
            delete(app)
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            
            if isfolder(app.Trck.classdir)
                d = app.Trck.classdir;
            else
                d = pwd;
            end
            
            classdir = uigetdir(d);
            if isfolder(classdir)
                app.ClassifierEditField.Value = classdir;
                app.Trck.classdir = classdir;
            end
            
        end

        % Value changed function: ClassifierEditField
        function ClassifierEditFieldValueChanged(app, event)
            classdir = app.ClassifierEditField.Value;
            
            if isfolder(classdir)
                app.Trck.classdir = classdir;
            else
                report('W', 'Not a valid classdir');
            end
            
        end

        % Value changed function: AlphaSlider
        function ROIAlphaSliderValueChanged(app, event)
            update_image(app);
            
        end

        % Value changed function: 
        % SaveimagesofsingleanttrackletsCheckBox
        function SaveimagesofsingleanttrackletsCheckBoxValueChanged(app, event)
            value = app.SaveimagesofsingleanttrackletsCheckBox.Value;
            app.Trck.set_param('tracking_saveimages', value);
        end

        % Button pushed function: ResettoLastButton
        function ResettoLastButtonPushed(app, event)
            
            load([app.Trck.paramsdir,'prmtrs_last_tracking_run.mat'],'prmtrs');
            print('W','Not implemented yet!')
            
        end

        % Button pushed function: ResettoDefaultButton
        function ResettoDefaultButtonPushed(app, event)
            print('W','Not implemented yet!')
        end

        % Value changed function: UsemanualconfigfileifexistCheckBox
        function UsemanualconfigfileifexistCheckBoxValueChanged(app, event)
            value = app.UsemanualconfigfileifexistCheckBox.Value;
            app.Trck.set_param('graph_apply_manual_cfg',value);
        end

        % Value changed function: 
        % UsetemporalconfigfileifexistCheckBox
        function UsetemporalconfigfileifexistCheckBoxValueChanged(app, event)
            value = app.UsetemporalconfigfileifexistCheckBox.Value;
            app.Trck.set_param('graph_apply_temporal_window',value);
        end

        % Value changed function: MaxiterationsSpinner
        function MaxiterationsSpinnerValueChanged(app, event)
            value = app.MaxiterationsSpinner.Value;
            app.Trck.set_param('graph_max_iterations',value);
        end

        % Value changed function: ImpossiblespeedSpinner
        function ImpossiblespeedSpinnerValueChanged(app, event)
            value = app.ImpossiblespeedSpinner.Value;
            app.Trck.set_param('graph_impossible_speed',value/1000);
        end

        % Value changed function: PairssearchdepthSpinner
        function PairssearchdepthSpinnerValueChanged(app, event)
            value = app.PairssearchdepthSpinner.Value;
            app.Trck.set_param('graph_pairs_maxdepth',value);
        end

        % Value changed function: GroupbyDropDown
        function GroupbyDropDownValueChanged(app, event)
            value = app.GroupbyDropDown.Value;
            app.Trck.set_param('graph_groupby',value);
        end

        % Value changed function: TrackoninterruptCheckBox
        function TrackoninterruptCheckBoxValueChanged(app, event)
            value = app.TrackoninterruptCheckBox.Value;
            app.Trck.set_param('videos_track_on_interrupt',value);
        end

        % Value changed function: ExportxydatauntaggedCheckBox
        function ExportxydatauntaggedCheckBoxValueChanged(app, event)
            if app.ExportxydatauntaggedCheckBox.Value
                app.Trck.add_post_command('export_xy_untagged');
            else
                app.Trck.remove_post_command('export_xy_untagged');
            end
        end

        % Callback function
        function SessionMenuSelected(app, event)
            session = event.Source.Text;
            app.set_experiment(app.expdir, session);
        end

        % Menu selected function: NewSessionMenu
        function NewSessionMenuSelected(app, event)
            session = make_new_session(app);
            app.set_experiment(app.expdir, session);
        end

        % Callback function
        function RecentExpMenuSelected(app, event)
            expdir = event.Source.Text;
            app.set_experiment(expdir);
        end

        % Menu selected function: Menu
        function ImportFromSessionMenuSelected(app, event)
            
            Trck_ref = trhandles(app.expdir, event.Source.Text);
            if app.parameters_reset_warning
                app.Trck.import(Trck_ref)
            end
        
        end

        % Menu selected function: GraphExplorerMenu
        function GraphExplorerMenuSelected(app, event)
            app.graph_explorer = graph_explorer_app(app.Trck);
        end

        % Value changed function: FlowCheckBox, 
        % LinkingclustersCheckBox, OpticalFlowCheckBox
        function LinkingViewOptionsChanged(app, event)
            
            update_image(app)
        end

        % Selection changed function: LinkingViewButtonGroup
        function LinkingViewButtonGroupSelectionChanged(app, event)
            update_image(app)
        end

        % Menu selected function: ParameterstofileMenu
        function ParameterstofileMenuSelected(app, event)
            defPath = '~/.antrax/prmtrs/';
            mkdirp(defPath);
            
            [FileName,PathName,FilterIndex] = uiputfile('*.json','Select save location:',[defPath,'my_prmtrs.json']);
            
            if strcmp(FileName(end-4:end),'.json')
                disp(FileName)
                app.Trck.export_params([PathName,filesep,FileName]);
            else
                uialert(app.UIFigure,'Please select a json file','anTraX Error','Icon','error');
            end
            focus(app)
        end

        % Menu selected function: ParametersfromfileMenu
        function ParametersfromfileMenuSelected(app, event)
            defPath = '~/.antrax/prmtrs/';
            mkdirp(defPath);
            
            [file,path] = uigetfile('*.json','Select parameters file to load:',defPath);
            
            app.Trck.import_params([path,filesep,file]);
            app.Trck.save_params;
            set_params_in_app(app)
            app.update_image
            
            focus(app)
        end

        % Menu selected function: DefaultparametersMenu
        function DefaultparametersMenuSelected(app, event)
            app.Trck.reset_params;
            app.Trck.save_params;
            set_params_in_app(app)
            app.update_image
        end

        % Value changed function: CutoffCoeffSpinner, 
        % MaxspeedSpinner, RadCoeffSpinner
        function LinkingParamsValueChanged(app, event)
            app.Trck.set_param('linking_maxspeed', app.MaxspeedSpinner.Value/1000);
            app.Trck.set_param('linking_cluster_radius_coeff', app.RadCoeffSpinner.Value);
            app.Trck.set_param('linking_flow_cutoff_coeff', app.CutoffCoeffSpinner.Value);
            app.update_image
        end

        % Selection changed function: TaggingButtonGroup
        function TaggingButtonGroupSelectionChanged(app, event)
            tagging_type = app.TaggingButtonGroup.SelectedObject.Text;
            tagged = ~strcmp(tagging_type,'untagged');
            app.Trck.set_param('tagging_type', tagging_type);
            app.Trck.set_param('tagged', tagged);
            
            update_ids(app)
            
            if tagged
                app.SaveimagesofsingleanttrackletsCheckBox.Value = true;
                app.Trck.set_param('tracking_saveimages', true);
            end
            
        end

        % Value changed function: HorizontalsymmetryCheckBox
        function HorizontalsymmetryCheckBoxValueChanged(app, event)
            value = app.HorizontalsymmetryCheckBox.Value;
            app.Trck.set_param('symmetric_tags', value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'anTraX: track color tagged ants';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExperimentMenu = uimenu(app.UIFigure);
            app.ExperimentMenu.Text = 'Experiment';
            app.OpenExperimentMenu = uimenu(app.ExperimentMenu);
            app.OpenExperimentMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenExperimentMenuSelected, true);
            app.OpenExperimentMenu.Text = 'Open';
            app.RecentMenu = uimenu(app.ExperimentMenu);
            app.RecentMenu.Enable = 'off';
            app.RecentMenu.Text = 'Recent';
            app.Menu = uimenu(app.RecentMenu);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @ImportFromSessionMenuSelected, true);
            app.Menu.Text = 'Menu';
            app.SessionMenu = uimenu(app.ExperimentMenu);
            app.SessionMenu.Text = 'Session';
            app.NewSessionMenu = uimenu(app.SessionMenu);
            app.NewSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @NewSessionMenuSelected, true);
            app.NewSessionMenu.Text = 'New';
            app.QuitMenu = uimenu(app.ExperimentMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.QuitMenu.Separator = 'on';
            app.QuitMenu.Text = 'Quit';
            app.ImportMenu_2 = uimenu(app.UIFigure);
            app.ImportMenu_2.Text = 'Import';
            app.ParametersfromfileMenu = uimenu(app.ImportMenu_2);
            app.ParametersfromfileMenu.MenuSelectedFcn = createCallbackFcn(app, @ParametersfromfileMenuSelected, true);
            app.ParametersfromfileMenu.Text = 'Parameters from file';
            app.ParametersfromsessionMenu = uimenu(app.ImportMenu_2);
            app.ParametersfromsessionMenu.Text = 'Parameters from session';
            app.Menu_3 = uimenu(app.ParametersfromsessionMenu);
            app.Menu_3.Text = 'Menu';
            app.ParametersfromexperimentMenu = uimenu(app.ImportMenu_2);
            app.ParametersfromexperimentMenu.Enable = 'off';
            app.ParametersfromexperimentMenu.Text = 'Parameters from experiment';
            app.BrowseMenu = uimenu(app.ParametersfromexperimentMenu);
            app.BrowseMenu.Text = 'Browse';
            app.DefaultparametersMenu = uimenu(app.ImportMenu_2);
            app.DefaultparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @DefaultparametersMenuSelected, true);
            app.DefaultparametersMenu.Text = 'Default parameters';
            app.ROImaskfromfileMenu = uimenu(app.ImportMenu_2);
            app.ROImaskfromfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfromfileButtonPushed, true);
            app.ROImaskfromfileMenu.Text = 'ROI mask from file';
            app.LabelsfromfileMenu = uimenu(app.ImportMenu_2);
            app.LabelsfromfileMenu.Text = 'Labels from file';
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Text = 'Export';
            app.ParameterstofileMenu = uimenu(app.ExportMenu);
            app.ParameterstofileMenu.MenuSelectedFcn = createCallbackFcn(app, @ParameterstofileMenuSelected, true);
            app.ParameterstofileMenu.Text = 'Parameters to file';
            app.ROImasktofileMenu = uimenu(app.ExportMenu);
            app.ROImasktofileMenu.MenuSelectedFcn = createCallbackFcn(app, @SavetofileButtonPushed, true);
            app.ROImasktofileMenu.Text = 'ROI mask to file';
            app.BackgroundimagetofileMenu = uimenu(app.ExportMenu);
            app.BackgroundimagetofileMenu.Enable = 'off';
            app.BackgroundimagetofileMenu.Text = 'Background image to file';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.FrameExplorerMenu = uimenu(app.ToolsMenu);
            app.FrameExplorerMenu.Enable = 'off';
            app.FrameExplorerMenu.Text = 'Frame Explorer';
            app.GraphExplorerMenu = uimenu(app.ToolsMenu);
            app.GraphExplorerMenu.MenuSelectedFcn = createCallbackFcn(app, @GraphExplorerMenuSelected, true);
            app.GraphExplorerMenu.Enable = 'off';
            app.GraphExplorerMenu.Text = 'Graph Explorer';
            app.ValidateTrackletIDsMenu = uimenu(app.ToolsMenu);
            app.ValidateTrackletIDsMenu.Enable = 'off';
            app.ValidateTrackletIDsMenu.Text = 'Validate Tracklet IDs';
            app.ValidateFinalTrackingMenu = uimenu(app.ToolsMenu);
            app.ValidateFinalTrackingMenu.Enable = 'off';
            app.ValidateFinalTrackingMenu.Text = 'Validate Final Tracking';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.OnlineDocumentationMenu = uimenu(app.HelpMenu);
            app.OnlineDocumentationMenu.Enable = 'off';
            app.OnlineDocumentationMenu.Text = 'Online Documentation';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.BackgroundTab = uitab(app.TabGroup);
            app.BackgroundTab.Tooltip = {'Use this tab to configure and  generate background frames'};
            app.BackgroundTab.Title = 'Background';
            app.BackgroundGridLayout = uigridlayout(app.BackgroundTab);
            app.BGFileLabel = uilabel(app.BackgroundGridLayout);
            app.BGFileLabel.Text = 'BG File:';
            app.BGFileDropDown = uidropdown(app.BackgroundGridLayout);
            app.BGFileDropDown.Tooltip = {'Select one of the already generated background frames'};
            app.MethodDropDownLabel = uilabel(app.BackgroundGridLayout);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.BackgroundGridLayout);
            app.MethodDropDown.Items = {'max', 'min', 'median'};
            app.MethodDropDown.Tooltip = {'Select the method for background generationn '};
            app.MethodDropDown.Value = 'max';
            app.NumberofFramesSpinnerLabel = uilabel(app.BackgroundGridLayout);
            app.NumberofFramesSpinnerLabel.Text = 'Number of Frames:';
            app.NumberofFramesSpinner = uispinner(app.BackgroundGridLayout);
            app.NumberofFramesSpinner.ValueDisplayFormat = '%d';
            app.NumberofFramesSpinner.Tooltip = {'The number of randomly selected frames to generate a background image from'};
            app.CreateBGButton = uibutton(app.BackgroundGridLayout, 'push');
            app.CreateBGButton.ButtonPushedFcn = createCallbackFcn(app, @CreateBGButtonPushed, true);
            app.CreateBGButton.Tooltip = {'Generate background/s'};
            app.CreateBGButton.Text = 'Create BG';
            app.OneBGCheckBox = uicheckbox(app.BackgroundGridLayout);
            app.OneBGCheckBox.ValueChangedFcn = createCallbackFcn(app, @BGoptions_ValueChanged, true);
            app.OneBGCheckBox.Tooltip = {'Generate one background image for the entire experiment'};
            app.OneBGCheckBox.Text = 'One BG';
            app.ToFrameLabel = uilabel(app.BackgroundGridLayout);
            app.ToFrameLabel.Text = '      To Frame:';
            app.ToFrameEditField = uieditfield(app.BackgroundGridLayout, 'numeric');
            app.ToFrameEditField.ValueDisplayFormat = '%d';
            app.ToFrameEditField.Tooltip = {'The end of the range (in absolute frame numbers) to draw frames from'};
            app.FromFrameLabel = uilabel(app.BackgroundGridLayout);
            app.FromFrameLabel.Text = '      From Frame:';
            app.FromFrameEditField = uieditfield(app.BackgroundGridLayout, 'numeric');
            app.FromFrameEditField.ValueDisplayFormat = '%d';
            app.FromFrameEditField.Tooltip = {'The start of the range (in absolute frame numbers) to draw frames from'};
            app.BGperSubdirCheckBox = uicheckbox(app.BackgroundGridLayout);
            app.BGperSubdirCheckBox.ValueChangedFcn = createCallbackFcn(app, @BGoptions_ValueChanged, true);
            app.BGperSubdirCheckBox.Tooltip = {'Generate one background image for each video subdirectories in the experiment. Useful for cases where there is considerable variablity between parts of the experiment.'};
            app.BGperSubdirCheckBox.Text = 'BG per Subdir';
            app.AbsoluteFrameSpinnerLabel = uilabel(app.BackgroundGridLayout);
            app.AbsoluteFrameSpinnerLabel.Text = 'Absolute Frame:';
            app.AbsoluteFrameSpinner_BG = uispinner(app.BackgroundGridLayout);
            app.AbsoluteFrameSpinner_BG.ValueDisplayFormat = '%d';
            app.AbsoluteFrameSpinner_BG.ValueChangedFcn = createCallbackFcn(app, @FrameSelectors_ValueChanged, true);
            app.AbsoluteFrameSpinner_BG.Tooltip = {'Index of frame in the experiment'};
            app.MovieNumberSpinnerLabel = uilabel(app.BackgroundGridLayout);
            app.MovieNumberSpinnerLabel.Text = 'Movie Number:';
            app.MovieNumberSpinner_BG = uispinner(app.BackgroundGridLayout);
            app.MovieNumberSpinner_BG.ValueChangedFcn = createCallbackFcn(app, @FrameSelectors_ValueChanged, true);
            app.MovieNumberSpinner_BG.Tooltip = {'Index of video '};
            app.MovieFrameSpinnerLabel = uilabel(app.BackgroundGridLayout);
            app.MovieFrameSpinnerLabel.Text = 'Movie Frame:';
            app.MovieFrameSpinner_BG = uispinner(app.BackgroundGridLayout);
            app.MovieFrameSpinner_BG.ValueDisplayFormat = '%d';
            app.MovieFrameSpinner_BG.ValueChangedFcn = createCallbackFcn(app, @FrameSelectors_ValueChanged, true);
            app.MovieFrameSpinner_BG.Tooltip = {'Index of frame in video'};
            app.ShowFrameCheckBox = uicheckbox(app.BackgroundGridLayout);
            app.ShowFrameCheckBox.ValueChangedFcn = createCallbackFcn(app, @BGViewoptions_ValueChanged, true);
            app.ShowFrameCheckBox.Text = 'Show Frame';
            app.ShowBackgroundCheckBox = uicheckbox(app.BackgroundGridLayout);
            app.ShowBackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @BGViewoptions_ValueChanged, true);
            app.ShowBackgroundCheckBox.Text = 'Show Background';
            app.ScaleTab = uitab(app.TabGroup);
            app.ScaleTab.Title = 'Scale';
            app.ScaleGridLayout = uigridlayout(app.ScaleTab);
            app.ChooseShapeDropDownLabel = uilabel(app.ScaleGridLayout);
            app.ChooseShapeDropDownLabel.Text = 'Choose Shape:';
            app.ScaleChooseShapeDropDown = uidropdown(app.ScaleGridLayout);
            app.ScaleChooseShapeDropDown.Items = {'Line', 'Circle'};
            app.ScaleChooseShapeDropDown.Value = 'Line';
            app.DimensionEditFieldLabel = uilabel(app.ScaleGridLayout);
            app.DimensionEditFieldLabel.Text = 'Dimension:';
            app.DimensionEditField = uieditfield(app.ScaleGridLayout, 'numeric');
            app.DimensionEditField.Limits = [0 Inf];
            app.DimensionEditField.ValueDisplayFormat = '%11.4g mm';
            app.DimensionEditField.ValueChangedFcn = createCallbackFcn(app, @DimensionEditFieldValueChanged, true);
            app.ScaleDrawButton = uibutton(app.ScaleGridLayout, 'push');
            app.ScaleDrawButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleDrawButtonPushed, true);
            app.ScaleDrawButton.Text = 'Draw';
            app.ScaleDoneButton = uibutton(app.ScaleGridLayout, 'push');
            app.ScaleDoneButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleDoneButtonPushed, true);
            app.ScaleDoneButton.Text = 'Done';
            app.InimageLabel = uilabel(app.ScaleGridLayout);
            app.InimageLabel.Text = 'In image:';
            app.ImageDimEditField = uieditfield(app.ScaleGridLayout, 'numeric');
            app.ImageDimEditField.ValueDisplayFormat = '%11.4g pixels';
            app.ImageDimEditField.Editable = 'off';
            app.ScaleLabel = uilabel(app.ScaleGridLayout);
            app.ScaleLabel.Text = 'Scale:';
            app.ScaleEditField = uieditfield(app.ScaleGridLayout, 'numeric');
            app.ScaleEditField.ValueDisplayFormat = '%11.4g pixels/mm';
            app.ScaleEditField.Editable = 'off';
            app.ROITab = uitab(app.TabGroup);
            app.ROITab.Title = 'ROI';
            app.ROIGridLayout = uigridlayout(app.ROITab);
            app.ResettoWhiteButton = uibutton(app.ROIGridLayout, 'push');
            app.ResettoWhiteButton.ButtonPushedFcn = createCallbackFcn(app, @ResettoWhiteButtonPushed, true);
            app.ResettoWhiteButton.Text = 'Reset to White';
            app.ResettoBlackButton = uibutton(app.ROIGridLayout, 'push');
            app.ResettoBlackButton.ButtonPushedFcn = createCallbackFcn(app, @ResettoBlackButtonPushed, true);
            app.ResettoBlackButton.Text = 'Reset to Black';
            app.ChooseShapeDropDown_2Label = uilabel(app.ROIGridLayout);
            app.ChooseShapeDropDown_2Label.Text = 'Choose Shape:';
            app.ROIChooseShapeDropDown = uidropdown(app.ROIGridLayout);
            app.ROIChooseShapeDropDown.Items = {'Rectangle', 'Ellipse', 'Polygon', 'Freehand', 'Assisted'};
            app.ROIChooseShapeDropDown.Value = 'Rectangle';
            app.AddButton = uibutton(app.ROIGridLayout, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @ROIAddRemoveButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.ROIGridLayout, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @ROIAddRemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.MultiColonyCheckBox = uicheckbox(app.ROIGridLayout);
            app.MultiColonyCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultiColonyCheckBoxValueChanged, true);
            app.MultiColonyCheckBox.Text = 'Multi Colony';
            app.ColonyNumberingDropDownLabel = uilabel(app.ROIGridLayout);
            app.ColonyNumberingDropDownLabel.Text = 'Colony Numbering';
            app.ColonyNumberingDropDown = uidropdown(app.ROIGridLayout);
            app.ColonyNumberingDropDown.Items = {'Vertical', 'Horizontal', 'Clockwise', 'CounterClockwise'};
            app.ColonyNumberingDropDown.ValueChangedFcn = createCallbackFcn(app, @ColonyNumberingDropDownValueChanged, true);
            app.ColonyNumberingDropDown.Value = 'Vertical';
            app.CircularshiftdegEditFieldLabel = uilabel(app.ROIGridLayout);
            app.CircularshiftdegEditFieldLabel.Text = 'Circular shift (deg):';
            app.CircularshiftdegEditField = uieditfield(app.ROIGridLayout, 'numeric');
            app.CircularshiftdegEditField.ValueChangedFcn = createCallbackFcn(app, @ColonyNumberingDropDownValueChanged, true);
            app.OpenBoundryCheckBox = uicheckbox(app.ROIGridLayout);
            app.OpenBoundryCheckBox.ValueChangedFcn = createCallbackFcn(app, @OpenBoundryCheckBoxValueChanged, true);
            app.OpenBoundryCheckBox.Text = 'Open Boundry';
            app.OpenBoundryAddButton = uibutton(app.ROIGridLayout, 'push');
            app.OpenBoundryAddButton.ButtonPushedFcn = createCallbackFcn(app, @ROIAddRemoveButtonPushed, true);
            app.OpenBoundryAddButton.Enable = 'off';
            app.OpenBoundryAddButton.Text = 'Add';
            app.OpenBoundryRemoveButton = uibutton(app.ROIGridLayout, 'push');
            app.OpenBoundryRemoveButton.ButtonPushedFcn = createCallbackFcn(app, @ROIAddRemoveButtonPushed, true);
            app.OpenBoundryRemoveButton.Enable = 'off';
            app.OpenBoundryRemoveButton.Text = 'Remove';
            app.OpenBoundryResetButton = uibutton(app.ROIGridLayout, 'push');
            app.OpenBoundryResetButton.ButtonPushedFcn = createCallbackFcn(app, @OpenBoundryResetButtonPushed, true);
            app.OpenBoundryResetButton.Enable = 'off';
            app.OpenBoundryResetButton.Text = 'Reset';
            app.ROImaskalphaLabel = uilabel(app.ROIGridLayout);
            app.ROImaskalphaLabel.Text = 'ROI mask alpha:';
            app.Panel = uipanel(app.ROIGridLayout);
            app.AlphaSlider = uislider(app.Panel);
            app.AlphaSlider.Limits = [0 1];
            app.AlphaSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.AlphaSlider.MajorTickLabels = {'0', '', '', '', '', '0.5', '', '', '', '', '1'};
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @ROIAlphaSliderValueChanged, true);
            app.AlphaSlider.Value = 0.5;
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.SegmentationGridLayout = uigridlayout(app.SegmentationTab);
            app.AbsoluteFrameSpinner_3Label = uilabel(app.SegmentationGridLayout);
            app.AbsoluteFrameSpinner_3Label.Text = 'Absolute Frame:';
            app.AbsoluteFrameSpinner_SEG = uispinner(app.SegmentationGridLayout);
            app.AbsoluteFrameSpinner_SEG.ValueDisplayFormat = '%d';
            app.AbsoluteFrameSpinner_SEG.ValueChangedFcn = createCallbackFcn(app, @FrameSelectors_ValueChanged, true);
            app.AbsoluteFrameSpinner_SEG.Tooltip = {'Index of frame in the experiment'};
            app.MovieNumberSpinner_3Label = uilabel(app.SegmentationGridLayout);
            app.MovieNumberSpinner_3Label.Text = 'Movie Number:';
            app.MovieNumberSpinner_SEG = uispinner(app.SegmentationGridLayout);
            app.MovieNumberSpinner_SEG.ValueChangedFcn = createCallbackFcn(app, @FrameSelectors_ValueChanged, true);
            app.MovieNumberSpinner_SEG.Tooltip = {'Index of video '};
            app.MovieFrameSpinner_3Label = uilabel(app.SegmentationGridLayout);
            app.MovieFrameSpinner_3Label.Text = 'Movie Frame:';
            app.MovieFrameSpinner_SEG = uispinner(app.SegmentationGridLayout);
            app.MovieFrameSpinner_SEG.ValueDisplayFormat = '%d';
            app.MovieFrameSpinner_SEG.ValueChangedFcn = createCallbackFcn(app, @FrameSelectors_ValueChanged, true);
            app.MovieFrameSpinner_SEG.Tooltip = {'Index of frame in video'};
            app.ShowsegmentedCheckBox = uicheckbox(app.SegmentationGridLayout);
            app.ShowsegmentedCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentationViweOptionsChanged, true);
            app.ShowsegmentedCheckBox.Tooltip = {'Show segmented regions on frame instead of convex hulls '};
            app.ShowsegmentedCheckBox.Text = 'Show segmented';
            app.ShowBlobPropertiesCheckBox = uicheckbox(app.SegmentationGridLayout);
            app.ShowBlobPropertiesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentationViweOptionsChanged, true);
            app.ShowBlobPropertiesCheckBox.Tooltip = {'Display blob properties'};
            app.ShowBlobPropertiesCheckBox.Text = 'Show Blob Properties';
            app.ShowBGsubtractedimageCheckBox = uicheckbox(app.SegmentationGridLayout);
            app.ShowBGsubtractedimageCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentationViweOptionsChanged, true);
            app.ShowBGsubtractedimageCheckBox.Tooltip = {'Show the BG subtracted grayscale image ("Z") instead of the original frame'};
            app.ShowBGsubtractedimageCheckBox.Text = 'Show BG subtracted image';
            app.ThresholdCheckBox = uicheckbox(app.SegmentationGridLayout);
            app.ThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentationParamsValueChanged, true);
            app.ThresholdCheckBox.Enable = 'off';
            app.ThresholdCheckBox.Text = 'Threshold:';
            app.ThresholdCheckBox.Value = true;
            app.ThresholdSpinner = uispinner(app.SegmentationGridLayout);
            app.ThresholdSpinner.Step = 0.01;
            app.ThresholdSpinner.Limits = [0 1];
            app.ThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @SegmentationParamsValueChanged, true);
            app.MinAreapixCheckBox = uicheckbox(app.SegmentationGridLayout);
            app.MinAreapixCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentationParamsValueChanged, true);
            app.MinAreapixCheckBox.Text = 'Min Area (pix):';
            app.MinimumAreaSpinner = uispinner(app.SegmentationGridLayout);
            app.MinimumAreaSpinner.Limits = [0 Inf];
            app.MinimumAreaSpinner.ValueChangedFcn = createCallbackFcn(app, @SegmentationParamsValueChanged, true);
            app.MinIntensityCheckBox = uicheckbox(app.SegmentationGridLayout);
            app.MinIntensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentationParamsValueChanged, true);
            app.MinIntensityCheckBox.Text = 'Min Intensity:';
            app.MinimumIntensitySpinner = uispinner(app.SegmentationGridLayout);
            app.MinimumIntensitySpinner.Step = 0.01;
            app.MinimumIntensitySpinner.Limits = [0 1];
            app.MinimumIntensitySpinner.ValueChangedFcn = createCallbackFcn(app, @SegmentationParamsValueChanged, true);
            app.MorphClosingCheckBox = uicheckbox(app.SegmentationGridLayout);
            app.MorphClosingCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentationParamsValueChanged, true);
            app.MorphClosingCheckBox.Text = 'Morph Closing:';
            app.ClosingSpinner = uispinner(app.SegmentationGridLayout);
            app.ClosingSpinner.Limits = [0 Inf];
            app.ClosingSpinner.ValueChangedFcn = createCallbackFcn(app, @SegmentationParamsValueChanged, true);
            app.MorphOpeningCheckBox = uicheckbox(app.SegmentationGridLayout);
            app.MorphOpeningCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentationParamsValueChanged, true);
            app.MorphOpeningCheckBox.Text = 'Morph Opening';
            app.OpeningSpinner = uispinner(app.SegmentationGridLayout);
            app.OpeningSpinner.Limits = [0 Inf];
            app.OpeningSpinner.ValueChangedFcn = createCallbackFcn(app, @SegmentationParamsValueChanged, true);
            app.FillHolesCheckBox = uicheckbox(app.SegmentationGridLayout);
            app.FillHolesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentationParamsValueChanged, true);
            app.FillHolesCheckBox.Text = 'Fill Holes';
            app.UseConvexHullCheckBox = uicheckbox(app.SegmentationGridLayout);
            app.UseConvexHullCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentationParamsValueChanged, true);
            app.UseConvexHullCheckBox.Text = 'Use Convex Hull';
            app.AdaptivethresholdCheckBox = uicheckbox(app.SegmentationGridLayout);
            app.AdaptivethresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentationParamsValueChanged, true);
            app.AdaptivethresholdCheckBox.Tooltip = {'Scale threshold by the local light intensity (i.e. the pixel brightness in bg frame)'};
            app.AdaptivethresholdCheckBox.Text = 'Adaptive threshold';
            app.ResettoDefaultButton = uibutton(app.SegmentationGridLayout, 'push');
            app.ResettoDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResettoDefaultButtonPushed, true);
            app.ResettoDefaultButton.Tooltip = {'Revert back to default parameter values'};
            app.ResettoDefaultButton.Text = 'Reset to Default';
            app.ResettoLastButton = uibutton(app.SegmentationGridLayout, 'push');
            app.ResettoLastButton.ButtonPushedFcn = createCallbackFcn(app, @ResettoLastButtonPushed, true);
            app.ResettoLastButton.Tooltip = {'Revert back to the parameters used in the last tracking run'};
            app.ResettoLastButton.Text = 'Reset to Last';
            app.ShowROICheckBox = uicheckbox(app.SegmentationGridLayout);
            app.ShowROICheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentationViweOptionsChanged, true);
            app.ShowROICheckBox.Text = 'Show ROI';
            app.AutothresholdCheckBox = uicheckbox(app.SegmentationGridLayout);
            app.AutothresholdCheckBox.Enable = 'off';
            app.AutothresholdCheckBox.Text = 'Auto threshold';
            app.DarkbackgroundCheckBox = uicheckbox(app.SegmentationGridLayout);
            app.DarkbackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentationParamsValueChanged, true);
            app.DarkbackgroundCheckBox.Tooltip = {'Check this if background is darker than objects'};
            app.DarkbackgroundCheckBox.Text = 'Dark background';
            app.SingleAntsTab = uitab(app.TabGroup);
            app.SingleAntsTab.Title = 'Single ants';
            app.SingleAntsGridLayout = uigridlayout(app.SingleAntsTab);
            app.AbsoluteFrameSpinner_4Label = uilabel(app.SingleAntsGridLayout);
            app.AbsoluteFrameSpinner_4Label.Text = 'Absolute Frame:';
            app.AbsoluteFrameSpinner_SA = uispinner(app.SingleAntsGridLayout);
            app.AbsoluteFrameSpinner_SA.ValueDisplayFormat = '%d';
            app.AbsoluteFrameSpinner_SA.ValueChangedFcn = createCallbackFcn(app, @FrameSelectors_ValueChanged, true);
            app.AbsoluteFrameSpinner_SA.Tooltip = {'Index of frame in the experiment'};
            app.MovieNumberSpinner_4Label = uilabel(app.SingleAntsGridLayout);
            app.MovieNumberSpinner_4Label.Text = 'Movie Number:';
            app.MovieNumberSpinner_SA = uispinner(app.SingleAntsGridLayout);
            app.MovieNumberSpinner_SA.ValueChangedFcn = createCallbackFcn(app, @FrameSelectors_ValueChanged, true);
            app.MovieNumberSpinner_SA.Tooltip = {'Index of video '};
            app.MovieFrameSpinner_4Label = uilabel(app.SingleAntsGridLayout);
            app.MovieFrameSpinner_4Label.Text = 'Movie Frame:';
            app.MovieFrameSpinner_SA = uispinner(app.SingleAntsGridLayout);
            app.MovieFrameSpinner_SA.ValueDisplayFormat = '%d';
            app.MovieFrameSpinner_SA.ValueChangedFcn = createCallbackFcn(app, @FrameSelectors_ValueChanged, true);
            app.MovieFrameSpinner_SA.Tooltip = {'Index of frame in video'};
            app.MinAreaSpinnerLabel = uilabel(app.SingleAntsGridLayout);
            app.MinAreaSpinnerLabel.Text = 'Min Area:';
            app.SAMinAreaSpinner = uispinner(app.SingleAntsGridLayout);
            app.SAMinAreaSpinner.ValueDisplayFormat = '%g pixels';
            app.SAMinAreaSpinner.ValueChangedFcn = createCallbackFcn(app, @SAParamsValueChanged, true);
            app.SAMinAreaSpinner.Tooltip = {'Set this to be the minimum size of a single ant in the experiment'};
            app.MaxAreaSpinnerLabel = uilabel(app.SingleAntsGridLayout);
            app.MaxAreaSpinnerLabel.Text = 'Max Area:';
            app.SAMaxAreaSpinner = uispinner(app.SingleAntsGridLayout);
            app.SAMaxAreaSpinner.ValueDisplayFormat = '%g pixels';
            app.SAMaxAreaSpinner.ValueChangedFcn = createCallbackFcn(app, @SAParamsValueChanged, true);
            app.SAMaxAreaSpinner.Tooltip = {'Set this to be the maximum area of a single ant in the experiment'};
            app.PixelsCheckBox = uicheckbox(app.SingleAntsGridLayout);
            app.PixelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SAParamsValueChanged, true);
            app.PixelsCheckBox.Text = 'Pixels';
            app.PixelsCheckBox.Value = true;
            app.SICheckBox = uicheckbox(app.SingleAntsGridLayout);
            app.SICheckBox.ValueChangedFcn = createCallbackFcn(app, @SAParamsValueChanged, true);
            app.SICheckBox.Text = 'mm square';
            app.LinkingTab = uitab(app.TabGroup);
            app.LinkingTab.Title = 'Linking';
            app.LinkingGridLayout = uigridlayout(app.LinkingTab);
            app.AbsoluteFrameSpinner_4Label_2 = uilabel(app.LinkingGridLayout);
            app.AbsoluteFrameSpinner_4Label_2.Text = 'Absolute Frame:';
            app.AbsoluteFrameSpinner_LK = uispinner(app.LinkingGridLayout);
            app.AbsoluteFrameSpinner_LK.ValueDisplayFormat = '%d';
            app.AbsoluteFrameSpinner_LK.ValueChangedFcn = createCallbackFcn(app, @FrameSelectors_ValueChanged, true);
            app.AbsoluteFrameSpinner_LK.Tooltip = {'Index of frame in the experiment'};
            app.MovieNumberSpinner_4Label_2 = uilabel(app.LinkingGridLayout);
            app.MovieNumberSpinner_4Label_2.Text = 'Movie Number:';
            app.MovieNumberSpinner_LK = uispinner(app.LinkingGridLayout);
            app.MovieNumberSpinner_LK.ValueChangingFcn = createCallbackFcn(app, @FrameSelectors_ValueChanged, true);
            app.MovieNumberSpinner_LK.ValueChangedFcn = createCallbackFcn(app, @FrameSelectors_ValueChanged, true);
            app.MovieNumberSpinner_LK.Tooltip = {'Index of video '};
            app.MovieFrameSpinner_4Label_2 = uilabel(app.LinkingGridLayout);
            app.MovieFrameSpinner_4Label_2.Text = 'Movie Frame:';
            app.MovieFrameSpinner_LK = uispinner(app.LinkingGridLayout);
            app.MovieFrameSpinner_LK.ValueDisplayFormat = '%d';
            app.MovieFrameSpinner_LK.ValueChangedFcn = createCallbackFcn(app, @FrameSelectors_ValueChanged, true);
            app.MovieFrameSpinner_LK.Tooltip = {'Index of frame in video'};
            app.OpticalFlowCheckBox = uicheckbox(app.LinkingGridLayout);
            app.OpticalFlowCheckBox.ValueChangedFcn = createCallbackFcn(app, @LinkingViewOptionsChanged, true);
            app.OpticalFlowCheckBox.Enable = 'off';
            app.OpticalFlowCheckBox.Text = 'Optical Flow';
            app.OpticalFlowCheckBox.Value = true;
            app.LinearAssigmentCheckBox = uicheckbox(app.LinkingGridLayout);
            app.LinearAssigmentCheckBox.Enable = 'off';
            app.LinearAssigmentCheckBox.Text = 'Linear Assigment';
            app.LinkingclustersCheckBox = uicheckbox(app.LinkingGridLayout);
            app.LinkingclustersCheckBox.ValueChangedFcn = createCallbackFcn(app, @LinkingViewOptionsChanged, true);
            app.LinkingclustersCheckBox.Text = 'Linking clusters';
            app.FlowCheckBox = uicheckbox(app.LinkingGridLayout);
            app.FlowCheckBox.ValueChangedFcn = createCallbackFcn(app, @LinkingViewOptionsChanged, true);
            app.FlowCheckBox.Enable = 'off';
            app.FlowCheckBox.Text = 'Flow ';
            app.LinkingCheckBox = uicheckbox(app.LinkingGridLayout);
            app.LinkingCheckBox.Enable = 'off';
            app.LinkingCheckBox.Text = 'Linking';
            app.LinkingViewButtonGroup = uibuttongroup(app.LinkingGridLayout);
            app.LinkingViewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LinkingViewButtonGroupSelectionChanged, true);
            app.PreviousframeButton = uiradiobutton(app.LinkingViewButtonGroup);
            app.PreviousframeButton.Text = 'Previous frame';
            app.CurrentframeButton = uiradiobutton(app.LinkingViewButtonGroup);
            app.CurrentframeButton.Text = 'Current frame';
            app.BlendButton = uiradiobutton(app.LinkingViewButtonGroup);
            app.BlendButton.Text = 'Blend';
            app.BlendButton.Value = true;
            app.MaxspeedmmsSpinnerLabel = uilabel(app.LinkingGridLayout);
            app.MaxspeedmmsSpinnerLabel.Text = 'Max speed (mm/s):';
            app.MaxspeedSpinner = uispinner(app.LinkingGridLayout);
            app.MaxspeedSpinner.Step = 0.1;
            app.MaxspeedSpinner.Limits = [0 Inf];
            app.MaxspeedSpinner.ValueDisplayFormat = '%5.1f';
            app.MaxspeedSpinner.ValueChangedFcn = createCallbackFcn(app, @LinkingParamsValueChanged, true);
            app.MaxspeedSpinner.Tooltip = {'The maximal possible velocity for the tracked animals'};
            app.ClusterradiuscoeffLabel = uilabel(app.LinkingGridLayout);
            app.ClusterradiuscoeffLabel.Text = 'Cluster radius coeff:';
            app.RadCoeffSpinner = uispinner(app.LinkingGridLayout);
            app.RadCoeffSpinner.Step = 0.1;
            app.RadCoeffSpinner.Limits = [1 Inf];
            app.RadCoeffSpinner.ValueDisplayFormat = '%5.1f';
            app.RadCoeffSpinner.ValueChangedFcn = createCallbackFcn(app, @LinkingParamsValueChanged, true);
            app.RadCoeffSpinner.Tooltip = {'This number times times the max velocity divided by the inter frame interval is the linking cluster radius'};
            app.RadCoeffSpinner.Value = 1;
            app.FlowcutoffcoeffLabel = uilabel(app.LinkingGridLayout);
            app.FlowcutoffcoeffLabel.Text = 'Flow cutoff coeff:';
            app.CutoffCoeffSpinner = uispinner(app.LinkingGridLayout);
            app.CutoffCoeffSpinner.Step = 0.01;
            app.CutoffCoeffSpinner.Limits = [0 Inf];
            app.CutoffCoeffSpinner.ValueDisplayFormat = '%5.1f';
            app.CutoffCoeffSpinner.ValueChangedFcn = createCallbackFcn(app, @LinkingParamsValueChanged, true);
            app.CutoffCoeffSpinner.Tooltip = {'This number times the minimum single ant size is the optical flow linking threshold'};
            app.ClassificationTab = uitab(app.TabGroup);
            app.ClassificationTab.Title = 'Classification';
            app.ClassificationGridLayout = uigridlayout(app.ClassificationTab);
            app.AbsoluteFrameSpinner_4Label_3 = uilabel(app.ClassificationGridLayout);
            app.AbsoluteFrameSpinner_4Label_3.Text = 'Absolute Frame:';
            app.AbsoluteFrameSpinner_CL = uispinner(app.ClassificationGridLayout);
            app.AbsoluteFrameSpinner_CL.ValueDisplayFormat = '%d';
            app.AbsoluteFrameSpinner_CL.ValueChangedFcn = createCallbackFcn(app, @FrameSelectors_ValueChanged, true);
            app.AbsoluteFrameSpinner_CL.Tooltip = {'Index of frame in the experiment'};
            app.MovieNumberSpinner_4Label_3 = uilabel(app.ClassificationGridLayout);
            app.MovieNumberSpinner_4Label_3.Text = 'Movie Number:';
            app.MovieNumberSpinner_CL = uispinner(app.ClassificationGridLayout);
            app.MovieNumberSpinner_CL.ValueChangedFcn = createCallbackFcn(app, @FrameSelectors_ValueChanged, true);
            app.MovieNumberSpinner_CL.Tooltip = {'Index of video '};
            app.MovieFrameSpinner_4Label_3 = uilabel(app.ClassificationGridLayout);
            app.MovieFrameSpinner_4Label_3.Text = 'Movie Frame:';
            app.MovieFrameSpinner_CL = uispinner(app.ClassificationGridLayout);
            app.MovieFrameSpinner_CL.ValueDisplayFormat = '%d';
            app.MovieFrameSpinner_CL.ValueChangedFcn = createCallbackFcn(app, @FrameSelectors_ValueChanged, true);
            app.MovieFrameSpinner_CL.Tooltip = {'Index of frame in video'};
            app.BlobMaskDilateSpinner = uispinner(app.ClassificationGridLayout);
            app.BlobMaskDilateSpinner.Limits = [1 Inf];
            app.BlobMaskDilateSpinner.ValueDisplayFormat = '%d pix';
            app.BlobMaskDilateSpinner.ValueChangedFcn = createCallbackFcn(app, @SegmentationParamsValueChanged, true);
            app.BlobMaskDilateSpinner.Tooltip = {'Dilation radius'};
            app.BlobMaskDilateSpinner.Value = 1;
            app.BlobmaskdilationCheckBox = uicheckbox(app.ClassificationGridLayout);
            app.BlobmaskdilationCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentationParamsValueChanged, true);
            app.BlobmaskdilationCheckBox.Tooltip = {'Dilate blob masks for classification (to add extra information)'; ''};
            app.BlobmaskdilationCheckBox.Text = 'Blob mask dilation:';
            app.ColorcorrectionCheckBox = uicheckbox(app.ClassificationGridLayout);
            app.ColorcorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentationParamsValueChanged, true);
            app.ColorcorrectionCheckBox.Tooltip = {'Apply color correction to blob images for classification.'};
            app.ColorcorrectionCheckBox.Text = 'Color correction';
            app.BrowseButton = uibutton(app.ClassificationGridLayout, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Enable = 'off';
            app.BrowseButton.Text = 'Browse';
            app.ClassifierEditFieldLabel = uilabel(app.ClassificationGridLayout);
            app.ClassifierEditFieldLabel.Enable = 'off';
            app.ClassifierEditFieldLabel.Text = 'Classifier:';
            app.ClassifierEditField = uieditfield(app.ClassificationGridLayout, 'text');
            app.ClassifierEditField.ValueChangedFcn = createCallbackFcn(app, @ClassifierEditFieldValueChanged, true);
            app.ClassifierEditField.Enable = 'off';
            app.ClassifierEditField.Tooltip = {'The classifier to be used'};
            app.ShowsegmentationCheckBox = uicheckbox(app.ClassificationGridLayout);
            app.ShowsegmentationCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentationViweOptionsChanged, true);
            app.ShowsegmentationCheckBox.Tooltip = {'Show segmented blobs in app'};
            app.ShowsegmentationCheckBox.Text = 'Show segmentation';
            app.ClassifymaskedblobsCheckBox = uicheckbox(app.ClassificationGridLayout);
            app.ClassifymaskedblobsCheckBox.Enable = 'off';
            app.ClassifymaskedblobsCheckBox.Tooltip = {'Apply mask to blob images for classification.'};
            app.ClassifymaskedblobsCheckBox.Text = 'Classify masked blobs';
            app.ClassifymaskedblobsCheckBox.Value = true;
            app.IDsTab = uitab(app.TabGroup);
            app.IDsTab.Title = 'IDs';
            app.IDsGridLayout = uigridlayout(app.IDsTab);
            app.BCheckBox = uicheckbox(app.IDsGridLayout);
            app.BCheckBox.ValueChangedFcn = createCallbackFcn(app, @TagColorsChanged, true);
            app.BCheckBox.Text = 'B';
            app.GCheckBox = uicheckbox(app.IDsGridLayout);
            app.GCheckBox.ValueChangedFcn = createCallbackFcn(app, @TagColorsChanged, true);
            app.GCheckBox.Text = 'G';
            app.LCheckBox = uicheckbox(app.IDsGridLayout);
            app.LCheckBox.ValueChangedFcn = createCallbackFcn(app, @TagColorsChanged, true);
            app.LCheckBox.Text = 'L';
            app.OCheckBox = uicheckbox(app.IDsGridLayout);
            app.OCheckBox.ValueChangedFcn = createCallbackFcn(app, @TagColorsChanged, true);
            app.OCheckBox.Text = 'O';
            app.PCheckBox = uicheckbox(app.IDsGridLayout);
            app.PCheckBox.ValueChangedFcn = createCallbackFcn(app, @TagColorsChanged, true);
            app.PCheckBox.Text = 'P';
            app.RCheckBox = uicheckbox(app.IDsGridLayout);
            app.RCheckBox.ValueChangedFcn = createCallbackFcn(app, @TagColorsChanged, true);
            app.RCheckBox.Text = 'R';
            app.WCheckBox = uicheckbox(app.IDsGridLayout);
            app.WCheckBox.ValueChangedFcn = createCallbackFcn(app, @TagColorsChanged, true);
            app.WCheckBox.Text = 'W';
            app.YCheckBox = uicheckbox(app.IDsGridLayout);
            app.YCheckBox.ValueChangedFcn = createCallbackFcn(app, @TagColorsChanged, true);
            app.YCheckBox.Text = 'Y';
            app.TagColorsLabel = uilabel(app.IDsGridLayout);
            app.TagColorsLabel.Text = 'Tag Colors:';
            app.AntIDsTextAreaLabel = uilabel(app.IDsGridLayout);
            app.AntIDsTextAreaLabel.Text = 'Ant IDs:';
            app.AntIDsTextArea = uitextarea(app.IDsGridLayout);
            app.AntIDsTextArea.ValueChangedFcn = createCallbackFcn(app, @IDListChanged, true);
            app.NoAntTextAreaLabel = uilabel(app.IDsGridLayout);
            app.NoAntTextAreaLabel.Text = 'NoAnt:';
            app.NoAntTextArea = uitextarea(app.IDsGridLayout);
            app.NoAntTextArea.ValueChangedFcn = createCallbackFcn(app, @IDListChanged, true);
            app.OtherTextAreaLabel = uilabel(app.IDsGridLayout);
            app.OtherTextAreaLabel.Text = 'Other:';
            app.OtherTextArea = uitextarea(app.IDsGridLayout);
            app.OtherTextArea.ValueChangedFcn = createCallbackFcn(app, @IDListChanged, true);
            app.NAntsLabel = uilabel(app.IDsGridLayout);
            app.NAntsLabel.Text = '(15)';
            app.NNoAntLabel = uilabel(app.IDsGridLayout);
            app.NNoAntLabel.Text = '(2)';
            app.NOtherLabel = uilabel(app.IDsGridLayout);
            app.NOtherLabel.Text = '(1)';
            app.NColorsLabel = uilabel(app.IDsGridLayout);
            app.NColorsLabel.Text = '(4)';
            app.LoadlabelfileButton = uibutton(app.IDsGridLayout, 'push');
            app.LoadlabelfileButton.Text = 'Load label file';
            app.TaggingButtonGroup = uibuttongroup(app.IDsGridLayout);
            app.TaggingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TaggingButtonGroupSelectionChanged, true);
            app.TaggingButtonGroup.Title = 'Tagging:';
            app.untaggedButton = uiradiobutton(app.TaggingButtonGroup);
            app.untaggedButton.Text = 'untagged';
            app.untaggedButton.Value = true;
            app.grouptaggedButton = uiradiobutton(app.TaggingButtonGroup);
            app.grouptaggedButton.Text = 'group-tagged';
            app.individuallytaggedButton = uiradiobutton(app.TaggingButtonGroup);
            app.individuallytaggedButton.Text = 'individually-tagged';
            app.HorizontalsymmetryCheckBox = uicheckbox(app.IDsGridLayout);
            app.HorizontalsymmetryCheckBox.ValueChangedFcn = createCallbackFcn(app, @HorizontalsymmetryCheckBoxValueChanged, true);
            app.HorizontalsymmetryCheckBox.Text = 'Horizontal symmetry';
            app.Autogenerate2tagIDsCheckBox = uicheckbox(app.IDsGridLayout);
            app.Autogenerate2tagIDsCheckBox.Tooltip = {'Select this option to generate 2 tag combination from color list. If selected'; ' you cannot add general string ant labels'};
            app.Autogenerate2tagIDsCheckBox.Text = ' Auto generate 2 tag IDs';
            app.PropagationTab = uitab(app.TabGroup);
            app.PropagationTab.Title = 'Propagation';
            app.PropagationGridLayout = uigridlayout(app.PropagationTab);
            app.PairssearchdepthSpinnerLabel = uilabel(app.PropagationGridLayout);
            app.PairssearchdepthSpinnerLabel.Text = 'Pairs search depth:';
            app.PairssearchdepthSpinner = uispinner(app.PropagationGridLayout);
            app.PairssearchdepthSpinner.Limits = [1 100];
            app.PairssearchdepthSpinner.ValueDisplayFormat = '%d';
            app.PairssearchdepthSpinner.ValueChangedFcn = createCallbackFcn(app, @PairssearchdepthSpinnerValueChanged, true);
            app.PairssearchdepthSpinner.Value = 5;
            app.MaxiterationsSpinnerLabel = uilabel(app.PropagationGridLayout);
            app.MaxiterationsSpinnerLabel.Text = 'Max iterations:';
            app.MaxiterationsSpinner = uispinner(app.PropagationGridLayout);
            app.MaxiterationsSpinner.Limits = [1 Inf];
            app.MaxiterationsSpinner.ValueDisplayFormat = '%d';
            app.MaxiterationsSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxiterationsSpinnerValueChanged, true);
            app.MaxiterationsSpinner.Value = 10;
            app.ImpossiblespeedSpinnerLabel = uilabel(app.PropagationGridLayout);
            app.ImpossiblespeedSpinnerLabel.Text = 'Impossible speed:';
            app.ImpossiblespeedSpinner = uispinner(app.PropagationGridLayout);
            app.ImpossiblespeedSpinner.Limits = [1 Inf];
            app.ImpossiblespeedSpinner.ValueDisplayFormat = '%g mm/s';
            app.ImpossiblespeedSpinner.ValueChangedFcn = createCallbackFcn(app, @ImpossiblespeedSpinnerValueChanged, true);
            app.ImpossiblespeedSpinner.Value = 10;
            app.UsemanualconfigfileifexistCheckBox = uicheckbox(app.PropagationGridLayout);
            app.UsemanualconfigfileifexistCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsemanualconfigfileifexistCheckBoxValueChanged, true);
            app.UsemanualconfigfileifexistCheckBox.Text = 'Use manual config file (if exist)';
            app.UsetemporalconfigfileifexistCheckBox = uicheckbox(app.PropagationGridLayout);
            app.UsetemporalconfigfileifexistCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsetemporalconfigfileifexistCheckBoxValueChanged, true);
            app.UsetemporalconfigfileifexistCheckBox.Text = 'Use temporal config file (if exist)';
            app.ToolongtobewrongSpinnerLabel = uilabel(app.PropagationGridLayout);
            app.ToolongtobewrongSpinnerLabel.Text = 'Too long to be wrong:';
            app.ToolongtobewrongSpinner = uispinner(app.PropagationGridLayout);
            app.ToolongtobewrongSpinner.Step = 1000;
            app.ToolongtobewrongSpinner.Limits = [0 Inf];
            app.ToolongtobewrongSpinner.Value = 6000;
            app.GroupbyDropDownLabel = uilabel(app.PropagationGridLayout);
            app.GroupbyDropDownLabel.Text = 'Group by:';
            app.GroupbyDropDown = uidropdown(app.PropagationGridLayout);
            app.GroupbyDropDown.Items = {'movie', 'subdir', 'experiment', 'manual'};
            app.GroupbyDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupbyDropDownValueChanged, true);
            app.GroupbyDropDown.Tooltip = {'Choose the critera to group movies by for the propogation step'};
            app.GroupbyDropDown.Value = 'subdir';
            app.WriteantdataCheckBox = uicheckbox(app.PropagationGridLayout);
            app.WriteantdataCheckBox.Tooltip = {'Write final data by ant ID. Use for individually tagged ants.'};
            app.WriteantdataCheckBox.Text = 'Write ant data';
            app.WriteantdataCheckBox.Value = true;
            app.UsesoftassigmentsCheckBox = uicheckbox(app.PropagationGridLayout);
            app.UsesoftassigmentsCheckBox.Text = 'Use soft assigments';
            app.UsesoftassigmentsCheckBox.Value = true;
            app.WritetrackletdataCheckBox = uicheckbox(app.PropagationGridLayout);
            app.WritetrackletdataCheckBox.Tooltip = {'Write final data by tracklet. USe for untagged experiments or non-individualy tagged ants'};
            app.WritetrackletdataCheckBox.Text = 'Write tracklet data';
            app.DataexportoptionsLabel = uilabel(app.PropagationGridLayout);
            app.DataexportoptionsLabel.Text = 'Data export options:';
            app.MoreoptionsTab = uitab(app.TabGroup);
            app.MoreoptionsTab.Title = 'More options';
            app.MoreOptionsGridLayout = uigridlayout(app.MoreoptionsTab);
            app.SaveimagesofsingleanttrackletsCheckBox = uicheckbox(app.MoreOptionsGridLayout);
            app.SaveimagesofsingleanttrackletsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveimagesofsingleanttrackletsCheckBoxValueChanged, true);
            app.SaveimagesofsingleanttrackletsCheckBox.Enable = 'off';
            app.SaveimagesofsingleanttrackletsCheckBox.Tooltip = {'Save cropped images for single ants. Always true for tagged experiment'; ' but can be useful also for non-tagged.'};
            app.SaveimagesofsingleanttrackletsCheckBox.Text = 'Save images of single ant tracklets';
            app.SavesingleantcroppedclipsCheckBox = uicheckbox(app.MoreOptionsGridLayout);
            app.SavesingleantcroppedclipsCheckBox.Enable = 'off';
            app.SavesingleantcroppedclipsCheckBox.Text = 'Save single ant cropped clips';
            app.TrackoninterruptCheckBox = uicheckbox(app.MoreOptionsGridLayout);
            app.TrackoninterruptCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackoninterruptCheckBoxValueChanged, true);
            app.TrackoninterruptCheckBox.Tooltip = {'Should the tracking algorithm skip frames marked as "interrupt'' in the dat file?'};
            app.TrackoninterruptCheckBox.Text = 'Track on ''interrupt''';
            app.ExportxydatauntaggedCheckBox = uicheckbox(app.MoreOptionsGridLayout);
            app.ExportxydatauntaggedCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExportxydatauntaggedCheckBoxValueChanged, true);
            app.ExportxydatauntaggedCheckBox.Enable = 'off';
            app.ExportxydatauntaggedCheckBox.Text = 'Export xy data (untagged)';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.StatusBar1 = uilabel(app.UIFigure);
            app.StatusBar1.Text = 'No experiment loaded';
            app.StatusBar2 = uilabel(app.UIFigure);
            app.StatusBar2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = antrax(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end