classdef bg_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CATTCreateBackgroundUIFigure   matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        MovienumberSpinnerLabel        matlab.ui.control.Label
        MovienumberSpinner             matlab.ui.control.Spinner
        MovieframeSpinnerLabel         matlab.ui.control.Label
        MovieframeSpinner              matlab.ui.control.Spinner
        ExperimentframeSpinnerLabel    matlab.ui.control.Label
        ExperimentframeSpinner         matlab.ui.control.Spinner
        MethodDropDownLabel            matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
        BGfileDropDownLabel            matlab.ui.control.Label
        BGfileDropDown                 matlab.ui.control.DropDown
        FramesperBGSpinnerLabel        matlab.ui.control.Label
        FramesperBGSpinner             matlab.ui.control.Spinner
        CreateBGButton                 matlab.ui.control.Button
        ShowBGButton                   matlab.ui.control.Button
        ShowframeButton                matlab.ui.control.Button
        OneBGCheckBox                  matlab.ui.control.CheckBox
        FramerangestartEditFieldLabel  matlab.ui.control.Label
        FramerangestartEditField       matlab.ui.control.NumericEditField
        FramerangeendEditFieldLabel    matlab.ui.control.Label
        FramerangeendEditField         matlab.ui.control.NumericEditField
        BGpersubdirCheckBox            matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        Trck trhandles 
        bgdir 
        t
    end
    
    methods (Access = private)
        
        function update_frame_selectors(app)
            
            movlist = [app.Trck.er.movies_info.index];
            if length(movlist)==1
                app.MovienumberSpinner.Limits = [movlist movlist+1];
                app.MovienumberSpinner.Value = movlist; 
                app.MovienumberSpinner.Enable = false;
                
            else
                app.MovienumberSpinner.Limits = [min(movlist),max(movlist)];
                app.MovienumberSpinner.Value = app.t.m;
            end
            app.ExperimentframeSpinner.Limits = [app.Trck.er.movies_info(1).fi,app.Trck.er.movies_info(end).ff];
            app.ExperimentframeSpinner.Value = app.t.f;
            app.MovieframeSpinner.Limits = [1,app.Trck.er.movies_info(app.t.m).nframes];
            app.MovieframeSpinner.Value = app.t.mf;
            
        end
    
        function update_bg_list(app)
            
            % list files in bgdir
            files=dir([app.bgdir,'background*.png']);
            
            if ~isempty(files)
                app.ShowBGButton.Enable = true;
                app.BGfileDropDown.Enable = true;
                app.BGfileDropDown.Items = {files.name};
                app.BGfileDropDown.Value = app.BGfileDropDown.Items{1};
            else
                app.ShowBGButton.Enable = false;
                app.BGfileDropDown.Enable = false;
                app.BGfileDropDown.Items = {''};
                app.BGfileDropDown.Value = '';
            end
            
        end
        
        function show_frame(app)
            
            frame = app.Trck.er.read(app.t);
            imshow(frame,'Parent',app.UIAxes)
            app.UIAxes.YLabel.String = '';
            app.UIAxes.XLabel.String = '';
        end
    
        function show_bg(app)
            
            
            if isempty(app.BGfileDropDown.Value)
                return
            end
            file = [app.bgdir,app.BGfileDropDown.Value];
            imshow(file,'Parent',app.UIAxes)
            app.UIAxes.YLabel.String = '';
            app.UIAxes.XLabel.String = '';
            
        end
    
    
        function create_bg(app)
            
           
            app.Trck.set_param('background_method',app.MethodDropDown.Value);
            app.Trck.set_param('background_nframes',app.FramesperBGSpinner.Value);
            app.Trck.set_param('background_per_subdir',app.BGpersubdirCheckBox.Value);
            
            fi = app.FramerangestartEditField.Value;
            ff = app.FramerangeendEditField.Value;
            app.Trck.set_param('background_frame_range',[fi,ff]);

            create_backgrounds(app.Trck);
            
            update_bg_list(app);
            show_bg(app);
        end

    
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Trck)
            
            if nargin<2
                return
            end
            app.Trck = Trck;
            app.bgdir = [Trck.paramsdir,'backgrounds',filesep];
            mkdirp(app.bgdir);
            app.t = trtime(Trck,1);
            update_frame_selectors(app);
            update_bg_list(app);
            
            app.BGpersubdirCheckBox.Value = Trck.get_param('background_per_subdir');
            app.OneBGCheckBox.Value = ~Trck.get_param('background_per_subdir');
            app.FramesperBGSpinner.Value = Trck.get_param('background_nframes');
            app.MethodDropDown.Value = Trck.get_param('background_method');
            
            frame_range = Trck.get_param('background_frame_range');
            frame_range(2) = min(Trck.er.movies_info(end).ff,frame_range(2));
            
            app.FramerangestartEditField.Value = frame_range(1);
            app.FramerangeendEditField.Value = frame_range(2);
            
            if isempty(app.BGfileDropDown.Value) 
                show_frame(app);
            else
                show_bg(app);
            end
            
        end

        % Value changed function: MovienumberSpinner
        function MovienumberSpinnerValueChanged(app, event)
            m = app.MovienumberSpinner.Value;
            app.t = trtime(app.Trck,m,1);
            update_frame_selectors(app);
            show_frame(app);
        end

        % Value changed function: MovieframeSpinner
        function MovieframeSpinnerValueChanged(app, event)
            mf = app.MovieframeSpinner.Value;
            app.t = trtime(app.Trck,app.t.m,mf);
            update_frame_selectors(app);
            show_frame(app);
        end

        % Value changed function: ExperimentframeSpinner
        function ExperimentframeSpinnerValueChanged(app, event)
            f = app.ExperimentframeSpinner.Value;
            app.t = trtime(app.Trck,f);
            update_frame_selectors(app);
            show_frame(app);
        end

        % Button pushed function: ShowframeButton
        function ShowframeButtonPushed(app, event)
            show_frame(app)
        end

        % Button pushed function: ShowBGButton
        function ShowBGButtonPushed(app, event)
            show_bg(app);
        end

        % Button pushed function: CreateBGButton
        function CreateBGButtonPushed(app, event)
            create_bg(app);
        end

        % Value changed function: OneBGCheckBox
        function OneBGCheckBoxValueChanged(app, event)
            value = app.OneBGCheckBox.Value;
            
            if value
                app.Trck.set_param('background_per_subdir',false);
                app.Trck.set_param('background_kind','experiment');
                app.BGpersubdirCheckBox.Value = false;
                app.FramerangeendEditField.Enable = true;
                app.FramerangestartEditField.Enable = true;
            else
                app.Trck.set_param('background_per_subdir',true);
                app.Trck.set_param('background_kind','subdir')
                app.BGpersubdirCheckBox.Value = true;
                app.FramerangeendEditField.Enable = false;
                app.FramerangestartEditField.Enable = false;
            end
        end

        % Value changed function: BGpersubdirCheckBox
        function BGpersubdirCheckBoxValueChanged(app, event)
            value = app.BGpersubdirCheckBox.Value;
            if value
                app.Trck.set_param('background_per_subdir',true);
                app.Trck.set_param('background_kind','subdir')
                app.OneBGCheckBox.Value = false;
                app.FramerangeendEditField.Enable = false;
                app.FramerangestartEditField.Enable = false;
            else
                app.Trck.set_param('background_per_subdir',false);
                app.Trck.set_param('background_kind','experiment')
                app.OneBGCheckBox.Value = true;
                app.FramerangeendEditField.Enable = true;
                app.FramerangestartEditField.Enable = true;
            end
        end

        % Value changed function: BGfileDropDown
        function BGfileDropDownValueChanged(app, event)
            show_bg(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CATTCreateBackgroundUIFigure = uifigure;
            app.CATTCreateBackgroundUIFigure.Name = 'CATT: Create Background';
            app.UIAxes = uiaxes(app.CATTCreateBackgroundUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MovienumberSpinnerLabel = uilabel(app.CATTCreateBackgroundUIFigure);
            app.MovienumberSpinnerLabel.Text = 'Movie number';
            app.MovienumberSpinner = uispinner(app.CATTCreateBackgroundUIFigure);
            app.MovienumberSpinner.ValueChangedFcn = createCallbackFcn(app, @MovienumberSpinnerValueChanged, true);
            app.MovieframeSpinnerLabel = uilabel(app.CATTCreateBackgroundUIFigure);
            app.MovieframeSpinnerLabel.Text = 'Movie frame';
            app.MovieframeSpinner = uispinner(app.CATTCreateBackgroundUIFigure);
            app.MovieframeSpinner.ValueChangedFcn = createCallbackFcn(app, @MovieframeSpinnerValueChanged, true);
            app.ExperimentframeSpinnerLabel = uilabel(app.CATTCreateBackgroundUIFigure);
            app.ExperimentframeSpinnerLabel.Text = 'Experiment frame';
            app.ExperimentframeSpinner = uispinner(app.CATTCreateBackgroundUIFigure);
            app.ExperimentframeSpinner.ValueChangedFcn = createCallbackFcn(app, @ExperimentframeSpinnerValueChanged, true);
            app.MethodDropDownLabel = uilabel(app.CATTCreateBackgroundUIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.CATTCreateBackgroundUIFigure);
            app.MethodDropDown.Items = {'median', 'max'};
            app.MethodDropDown.Value = 'median';
            app.BGfileDropDownLabel = uilabel(app.CATTCreateBackgroundUIFigure);
            app.BGfileDropDownLabel.Text = 'BG file';
            app.BGfileDropDown = uidropdown(app.CATTCreateBackgroundUIFigure);
            app.BGfileDropDown.ValueChangedFcn = createCallbackFcn(app, @BGfileDropDownValueChanged, true);
            app.FramesperBGSpinnerLabel = uilabel(app.CATTCreateBackgroundUIFigure);
            app.FramesperBGSpinnerLabel.Text = 'Frames per BG';
            app.FramesperBGSpinner = uispinner(app.CATTCreateBackgroundUIFigure);
            app.FramesperBGSpinner.Limits = [1 Inf];
            app.FramesperBGSpinner.Value = 20;
            app.CreateBGButton = uibutton(app.CATTCreateBackgroundUIFigure, 'push');
            app.CreateBGButton.ButtonPushedFcn = createCallbackFcn(app, @CreateBGButtonPushed, true);
            app.CreateBGButton.Text = 'Create BG';
            app.ShowBGButton = uibutton(app.CATTCreateBackgroundUIFigure, 'push');
            app.ShowBGButton.ButtonPushedFcn = createCallbackFcn(app, @ShowBGButtonPushed, true);
            app.ShowBGButton.Text = 'Show BG';
            app.ShowframeButton = uibutton(app.CATTCreateBackgroundUIFigure, 'push');
            app.ShowframeButton.ButtonPushedFcn = createCallbackFcn(app, @ShowframeButtonPushed, true);
            app.ShowframeButton.Text = 'Show frame';
            app.OneBGCheckBox = uicheckbox(app.CATTCreateBackgroundUIFigure);
            app.OneBGCheckBox.ValueChangedFcn = createCallbackFcn(app, @OneBGCheckBoxValueChanged, true);
            app.OneBGCheckBox.Text = 'One BG';
            app.OneBGCheckBox.Value = true;
            app.FramerangestartEditFieldLabel = uilabel(app.CATTCreateBackgroundUIFigure);
            app.FramerangestartEditFieldLabel.Text = 'Frame range start';
            app.FramerangestartEditField = uieditfield(app.CATTCreateBackgroundUIFigure, 'numeric');
            app.FramerangestartEditField.ValueDisplayFormat = '%.0f';
            app.FramerangeendEditFieldLabel = uilabel(app.CATTCreateBackgroundUIFigure);
            app.FramerangeendEditFieldLabel.Text = 'Frame range end';
            app.FramerangeendEditField = uieditfield(app.CATTCreateBackgroundUIFigure, 'numeric');
            app.FramerangeendEditField.ValueDisplayFormat = '%.0f';
            app.BGpersubdirCheckBox = uicheckbox(app.CATTCreateBackgroundUIFigure);
            app.BGpersubdirCheckBox.ValueChangedFcn = createCallbackFcn(app, @BGpersubdirCheckBoxValueChanged, true);
            app.BGpersubdirCheckBox.Text = 'BG per subdir';
        end
    end

    methods (Access = public)

        % Construct app
        function app = bg_app(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CATTCreateBackgroundUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CATTCreateBackgroundUIFigure)
        end
    end
end