classdef export_frame_selector_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        anTraXExampleFrameSelectorUIFigure  matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        NextButton                 matlab.ui.control.Button
        ExportthisasIDButton       matlab.ui.control.Button
        ExportthisasUnknownButton  matlab.ui.control.Button
        PreviousButton             matlab.ui.control.Button
        ShowallButton              matlab.ui.control.Button
        GotoDropDownLabel          matlab.ui.control.Label
        GotoDropDown               matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        
        trj
        curr_frame
        curr_frame_index
        images
        id
        classdir
        
    end
    
    methods (Access = public)
        
        function add_example(app,id)
            
            if ~isfolder(app.classdir)
                report('E','classdir does not exist')
                return
            end
            
            if isa(app.trj,'tracklet')
                app.trj.add_examples(app.id,'tt',app.curr_frame,'classdir',app.classdir);
            else
                
                target_dir = [app.classdir,filesep,'examples',filesep,id,filesep];
                
                mkdirp(target_dir);
                
                if ~isfolder(target_dir)
                    report('E','could not create target dir')
                    return
                end
                
                im = app.trj.images(:,:,:,app.curr_frame_index);
                msk = repmat(max(im,[],3)==0,[1,1,3]);
                im(msk)=255;
                
                fname = [target_dir,app.trj.Trck.expname,'.',app.trj.name,'.',num2str(app.curr_frame),'.png'];
                imwrite(im,fname);

            end
            
        end
        
        
        function update_image(app)
            
            if app.curr_frame == app.trj.fi
                app.PreviousButton.Enable = false;
            else
                app.PreviousButton.Enable = true;
            end
            
            if app.curr_frame == app.trj.ff
                app.NextButton.Enable = false;
            else
                app.NextButton.Enable = true;
            end
            
            imshow(app.images(:,:,:,app.curr_frame_index),'Parent',app.UIAxes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, trj, id, classdir)
            
        
            if nargin>3
                app.classdir = classdir;
            elseif isempty(trj.Trck.classdir) || ~isfolder(trj.Trck.classdir)
                app.classdir = [trj.Trck.trackingdir,'classifier'];
            else
                app.classdir = trj.Trck.classdir;
            end

        
            app.trj = trj;
            app.id = id;
            
            app.ExportthisasIDButton.Text = ['Export this as ',id];
            
            % update frame list
            fs = trj.fi:trj.ff;
            fs = arrayfun(@num2str,fs,'UniformOutput',false);
            app.GotoDropDown.Items = fs;
            
            % get all images
            if isa(app.trj,'tracklet')
                app.images = app.trj.get_image('all');
            else
                app.images = app.trj.images;
            end
            
            % show first frame
            app.GotoDropDown.Value = app.GotoDropDown.Items{1};
            app.curr_frame_index = 1;
            app.curr_frame = app.trj.fi;
            update_image(app);
            
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.curr_frame_index = app.curr_frame_index + 1;
            app.curr_frame = app.curr_frame + 1;
            update_image(app);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.curr_frame_index = app.curr_frame_index - 1;
            app.curr_frame = app.curr_frame - 1;
            update_image(app);
        end

        % Value changed function: GotoDropDown
        function GotoDropDownValueChanged(app, event)
            f = str2double(app.GotoDropDown.Value);
            app.curr_frame_index = f - app.trj.fi + 1;
            app.curr_frame = f;
            update_image(app);
            
        end

        % Button pushed function: ExportthisasIDButton
        function ExportthisasIDButtonPushed(app, event)
            
            add_example(app,app.id);
            %app.trj.add_examples(app.id,'tt',app.curr_frame);
            
            if app.curr_frame_index<app.trj.len
                app.curr_frame_index = app.curr_frame_index + 1;
                app.curr_frame = app.curr_frame + 1;
                update_image(app);
            end
        end

        % Button pushed function: ExportthisasUnknownButton
        function ExportthisasUnknownButtonPushed(app, event)
            
            add_example(app,'Unknown');
            %app.trj.add_examples('Unknown','tt',app.curr_frame);
            
            if app.curr_frame_index<app.trj.len
                app.curr_frame_index = app.curr_frame_index + 1;
                app.curr_frame = app.curr_frame + 1;
                update_image(app);
            end
        end

        % Button pushed function: ShowallButton
        function ShowallButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.anTraXExampleFrameSelectorUIFigure = uifigure('Visible', 'off');
            app.anTraXExampleFrameSelectorUIFigure.Name = 'anTraX: Example Frame Selector';
            app.UIAxes = uiaxes(app.anTraXExampleFrameSelectorUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.NextButton = uibutton(app.anTraXExampleFrameSelectorUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ExportthisasIDButton = uibutton(app.anTraXExampleFrameSelectorUIFigure, 'push');
            app.ExportthisasIDButton.ButtonPushedFcn = createCallbackFcn(app, @ExportthisasIDButtonPushed, true);
            app.ExportthisasIDButton.Text = 'Export this as ID';
            app.ExportthisasUnknownButton = uibutton(app.anTraXExampleFrameSelectorUIFigure, 'push');
            app.ExportthisasUnknownButton.ButtonPushedFcn = createCallbackFcn(app, @ExportthisasUnknownButtonPushed, true);
            app.ExportthisasUnknownButton.Text = 'Export this as Unknown';
            app.PreviousButton = uibutton(app.anTraXExampleFrameSelectorUIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.ShowallButton = uibutton(app.anTraXExampleFrameSelectorUIFigure, 'push');
            app.ShowallButton.ButtonPushedFcn = createCallbackFcn(app, @ShowallButtonPushed, true);
            app.ShowallButton.Enable = 'off';
            app.ShowallButton.Visible = 'off';
            app.ShowallButton.Text = 'Show all';
            app.GotoDropDownLabel = uilabel(app.anTraXExampleFrameSelectorUIFigure);
            app.GotoDropDownLabel.Text = 'Go to:';
            app.GotoDropDown = uidropdown(app.anTraXExampleFrameSelectorUIFigure);
            app.GotoDropDown.ValueChangedFcn = createCallbackFcn(app, @GotoDropDownValueChanged, true);
            % Show the figure after all components are created
            app.anTraXExampleFrameSelectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = export_frame_selector_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.anTraXExampleFrameSelectorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.anTraXExampleFrameSelectorUIFigure)
        end
    end
end