classdef frame_explorer_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CATTFrameExplorerUIFigure       matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        MovienumberSpinnerLabel         matlab.ui.control.Label
        MovienumberSpinner              matlab.ui.control.Spinner
        MovieframeSpinnerLabel          matlab.ui.control.Label
        MovieframeSpinner               matlab.ui.control.Spinner
        ExperimentframeSpinnerLabel     matlab.ui.control.Label
        ExperimentframeSpinner          matlab.ui.control.Spinner
        IlluminationcorrectionCheckBox  matlab.ui.control.CheckBox
        FocusonIDCheckBox               matlab.ui.control.CheckBox
        ShowassigmentsCheckBox          matlab.ui.control.CheckBox
        ROImaskCheckBox                 matlab.ui.control.CheckBox
        ShowtrackletCheckBox            matlab.ui.control.CheckBox
        IDDropDown                      matlab.ui.control.DropDown
        TrackletTextArea                matlab.ui.control.TextArea
        ColonyDropDownLabel             matlab.ui.control.Label
        ColonyDropDown                  matlab.ui.control.DropDown
        ShowblobsCheckBox               matlab.ui.control.CheckBox
        ShowautoIDsCheckBox             matlab.ui.control.CheckBox
    end

    
    properties 
        Trck  trhandles 
        GS    trgraph
        G     trgraph
        XY
        trj
        node
        t
        mask
        colony = ''
        chplot
        annotations
    end
    
    methods (Access = private)
        
        function update_frame_selectors(app)
            
            movlist = [app.Trck.er.movies_info.index];
            app.MovienumberSpinner.Limits = [min(movlist),max(movlist)];
            app.MovienumberSpinner.Value = app.t.m;
            app.ExperimentframeSpinner.Limits = [app.Trck.er.movies_info(1).fi,app.Trck.er.movies_info(end).ff];
            app.ExperimentframeSpinner.Value = app.t.f;
            app.MovieframeSpinner.Limits = [1,app.Trck.er.movies_info(app.t.m).nframes];
            app.MovieframeSpinner.Value = app.t.mf;
            
            set_G(app);
            
        end


        function show_frame(app)
            
            
            % prevent changing frames while computation is ongoing
            app.MovieframeSpinner.Enable = false;
            app.MovienumberSpinner.Enable = false;
            app.ExperimentframeSpinner.Enable = false;
            app.ColonyDropDown.Enable = false;
            
            drawnow
            
            app.Trck.read_frame(app.t);
            frame = im2single(app.Trck.currfrm.CData);
            delete(app.chplot);
            delete(app.annotations);
            app.chplot = [];
            app.annotations = [];
            detect_blobs(app.Trck);
            ab = app.Trck.currfrm.antblob;
            S = regionprops(ab.LABEL,app.Trck.currfrm.grayIm,'ConvexHull');
            
            if app.IlluminationcorrectionCheckBox.Value
                
                [~,~,BGW] = app.Trck.get_bg(app.t.m);
                L = app.Trck.get_param('segmentation_illum_correct_level');
                % L = median(app.Trck.Backgrounds.white(app.mask>0));
                frame = L * frame./BGW;
                
            end
            
            if app.ROImaskCheckBox.Value
                msk = single(app.mask>0);
                frame = imdivide(frame,3*(1-msk)+msk);
            end
            
            imshow(frame,'Parent',app.UIAxes)
            hold(app.UIAxes,'on')
            
            if app.ShowblobsCheckBox.Value
                
                for i=1:length(S)
                    app.chplot(i) = plot(app.UIAxes,S(i).ConvexHull(:,1),S(i).ConvexHull(:,2),'Color',[1,0,0,0.5],'LineWidth',2);
                end
            end
            
            if app.ShowautoIDsCheckBox.Value 
                
                % find tracklets in frame
                trjs = app.G.trjs(find(app.G.E(app.t.f,:)));
                trjs = trjs(trjs.isSingle);
                
                % connect trjs to blobs in frame
                for i=1:length(trjs)
                    ix = app.t.f-trjs(i).fi+1;
                    trjs_xy(i,:) = trjs(i).CENTROID(ix,:);
                end
                cost = pdist2(single(trjs_xy),single(ab.DATA.CENTROID));
                [assigments,unassigned_trjs,unassigned_blobs] = assignDetectionsToTracks(cost,single(5));

                if ~isempty(unassigned_trjs)
                    report('W','Some tracklets are not detected in frame');
                end
                
                for i=1:size(assigments,1)
                    bix = assigments(i,2);
                    tix = assigments(i,1);
                    aid = trjs(tix).autoID;
                    
                    app.chplot(i) = plot(app.UIAxes,S(bix).ConvexHull(:,1),S(bix).ConvexHull(:,2),'Color',[0,1,0,0.5],'LineWidth',2);
                    ytxt = double(trjs_xy(tix,2));
                    xtxt = double(max(S(bix).ConvexHull(:,1))+5);
                    app.annotations(i) = text(app.UIAxes,xtxt,ytxt,aid);
                end
                for i=1:length(unassigned_blobs)
                    bix = unassigned_blobs(i);
                    app.chplot(end+1) = plot(app.UIAxes,S(bix).ConvexHull(:,1),S(bix).ConvexHull(:,2),'Color',[1,0,0,0.5],'LineWidth',2);
                end

            end
 
            
            if app.ShowassigmentsCheckBox.Value
                
                % find tracklets in frame
                trjs = app.G.trjs(find(app.G.E(app.t.f,:)));
                nodes = app.G.nodes_from_trjs(trjs);
                
                % connect trjs to blobs in frame
                for i=1:length(trjs)
                    ix = app.t.f-trjs(i).fi+1;
                    trjs_xy(i,:) = trjs(i).CENTROID(ix,:);
                end
                
                cost = pdist2(single(trjs_xy),single(ab.DATA.CENTROID));
                [assigments,unassigned_trjs,unassigned_blobs] = assignDetectionsToTracks(cost,single(5));

                if ~isempty(unassigned_trjs)
                    report('W','Some tracklets are not detected in frame');
                end
                
                for i=1:size(assigments,1)
                    bix = assigments(i,2);
                    tix = assigments(i,1);
                    assigned = app.G.usedIDs(app.G.assigned_ids(nodes(tix),:));
                    possible = app.G.usedIDs(app.G.possible_ids(nodes(tix),:));
                    possible = setdiff(possible,assigned);
                    
                    if isempty(possible)
                        color = [0,0,1,0.5];
                    else
                        color = [0.5,0,0.5,0.5];
                    end
                    
                    app.chplot(i) = plot(app.UIAxes,S(bix).ConvexHull(:,1),S(bix).ConvexHull(:,2),'Color',color,'LineWidth',2);
                    ytxt = double(trjs_xy(tix,2));
                    xtxt = double(max(S(bix).ConvexHull(:,1))+5);
                    app.annotations(i) = text(app.UIAxes,xtxt,ytxt,strjoin(assigned));
                    
                end

                
            end
            
            app.UIAxes.YLabel.String = '';
            app.UIAxes.XLabel.String = '';
            
            
            % enable
            app.MovieframeSpinner.Enable = true;
            app.MovienumberSpinner.Enable = true;
            app.ExperimentframeSpinner.Enable = true;
            app.ColonyDropDown.Enable = true;
            drawnow
        end
    
    
        function set_colony(app)
            
            cix = strcmp(app.colony,app.Trck.colony_labels);
            app.ColonyDropDown.Value = app.colony;
            app.mask = app.Trck.Masks.colony(:,:,cix);            
            set_G(app);            
            show_frame(app);                
            
        end
    
        function set_G(app,G)


            if nargin>1
                if app.G==G
                    return
                end
                app.G = G;
                app.ShowassigmentsCheckBox.Enable = ~isempty(app.G.assigned_ids);
                f = [app.Trck.labelsdir,'autoids_',num2str(app.t.m),'.csv'];
                app.ShowautoIDsCheckBox.Enable =  exist(f,'file');
                app.GS = unique([app.GS,app.G]);
                app.XY = app.G.loadxy;
                app.colony = G.colony;
                app.ColonyDropDown.Value = app.colony;
                return
            end
            
            
            f = [app.Trck.labelsdir,'autoids_',num2str(app.t.m),'.csv'];
            app.ShowautoIDsCheckBox.Enable =  exist(f,'file');

            % see if G is needed
            if ~app.FocusonIDCheckBox.Value && ~app.ShowassigmentsCheckBox.Value && ~app.ShowautoIDsCheckBox.Value
                return
            end
            
            
            % prevent changing frames while computation is ongoing
            app.MovieframeSpinner.Enable = false;
            app.MovienumberSpinner.Enable = false;
            app.ExperimentframeSpinner.Enable = false;
            app.ColonyDropDown.Enable = false;
            drawnow
            
            % see if the current colony/movie G is already loaded
            m = app.t.m;
            c = app.ColonyDropDown.Value;
            
            loaded_movlist = [app.GS.movlist];
            loaded_colonies = {app.GS.colony};
            
            gix = find(loaded_movlist==m & strcmp(c,loaded_colonies));
            
            if isempty(gix)
                app.G = app.Trck.loaddata(m,c);
                app.GS = [app.GS,app.G];
            else
                app.G = app.GS(gix);
            end
            
            app.XY = app.G.loadxy;
            
            app.ShowassigmentsCheckBox.Enable = ~isempty(app.G.assigned_ids);


            % enable
            app.MovieframeSpinner.Enable = true;
            app.MovienumberSpinner.Enable = true;
            app.ExperimentframeSpinner.Enable = true;
            app.ColonyDropDown.Enable = true;
            drawnow
            
        end
    
        function set_trj(app,trj)
            
            if trj.G~=app.G
                set_G(app,trj.G);
            end
            app.trj = trj;
            app.node = findnode(app.G.G,trj.name);
            
        end


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            
            if nargin<2
                report('E','No argument to frame explorer')
                return
            end
            
            switch class(arg1)
                case 'trhandles'
                    app.Trck = arg1;
                    app.t = trtime(app.Trck,1);
                    ids = app.Trck.usedIDs;
                case 'trgraph'
                    app.set_G(arg1);
                    app.Trck = app.G.Trck;
                    app.t = app.G.ti;
                    ids = app.G.usedIDs;
                case 'tracklet'
                    app.trj = arg1;
                    app.set_G(app.trj.G);
                    app.Trck = app.trj.Trck;
                    app.t = app.trj.ti;
                    ids = app.G.usedIDs;
                otherwise
                    report('E','Wrong argument class to frame explorer')
                    return
            end
            
            
            update_frame_selectors(app);
            
            show_frame(app);
            
            if app.Trck.get_param('geometry_multi_colony')
                app.ColonyDropDown.Enable = true;
                app.ColonyDropDown.Items = app.Trck.colony_labels;
                app.ColonyDropDown.Value = app.colony;
                set_colony(app);
            else
                app.ColonyDropDown.Enable = false;
                app.ColonyDropDown.Items = {''};
                app.ColonyDropDown.Value = '';
                app.mask = app.Trck.TrackingMask;
            end
            
            app.IDDropDown.Items = ids;
            app.IDDropDown.Value = ids{1};
           
        end

        % Value changed function: MovienumberSpinner
        function MovienumberSpinnerValueChanged(app, event)
            m = app.MovienumberSpinner.Value;
            app.t = trtime(app.Trck,m,1);
            update_frame_selectors(app);
            show_frame(app);
        end

        % Value changed function: MovieframeSpinner
        function MovieframeSpinnerValueChanged(app, event)
            mf = app.MovieframeSpinner.Value;
            app.t = trtime(app.Trck,app.t.m,mf);
            update_frame_selectors(app);
            show_frame(app);
        end

        % Value changed function: ExperimentframeSpinner
        function ExperimentframeSpinnerValueChanged(app, event)
            f = app.ExperimentframeSpinner.Value;
            app.t = trtime(app.Trck,f);
            update_frame_selectors(app);
            show_frame(app);
                       
            
        end

        % Callback function
        function ShowframeButtonPushed(app, event)
            show_frame(app)
        end

        % Callback function
        function ShowBGButtonPushed(app, event)
            show_bg(app);
        end

        % Callback function
        function CreateBGButtonPushed(app, event)
            create_bg(app);
        end

        % Callback function
        function OneBGCheckBoxValueChanged(app, event)
           
        end

        % Callback function
        function BGpersubdirCheckBoxValueChanged(app, event)
           
        end

        % Callback function
        function BGfileDropDownValueChanged(app, event)
            
        end

        % Value changed function: ColonyDropDown
        function ColonyDropDownValueChanged(app, event)
            app.colony = app.ColonyDropDown.Value;
            set_colony(app);
        end

        % Value changed function: ROImaskCheckBox
        function ROImaskCheckBoxValueChanged(app, event)
            show_frame(app)
        end

        % Value changed function: IlluminationcorrectionCheckBox
        function IlluminationcorrectionCheckBoxValueChanged(app, event)
            show_frame(app)
        end

        % Value changed function: ShowblobsCheckBox
        function ShowblobsCheckBoxValueChanged(app, event)
            app.ShowautoIDsCheckBox.Value = false;
            app.ShowassigmentsCheckBox.Value = false;
            show_frame(app)
        end

        % Value changed function: ShowautoIDsCheckBox
        function ShowautoIDsCheckBoxValueChanged(app, event)
            app.ShowblobsCheckBox.Value = false;
            app.ShowassigmentsCheckBox.Value = false;
            set_G(app);
            show_frame(app)
        end

        % Value changed function: ShowassigmentsCheckBox
        function ShowassigmentsCheckBoxValueChanged(app, event)
            app.ShowautoIDsCheckBox.Value = false;
            app.ShowblobsCheckBox.Value = false;
            set_G(app);
            show_frame(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CATTFrameExplorerUIFigure = uifigure;
            app.CATTFrameExplorerUIFigure.Name = 'CATT: Frame Explorer';
            app.UIAxes = uiaxes(app.CATTFrameExplorerUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MovienumberSpinnerLabel = uilabel(app.CATTFrameExplorerUIFigure);
            app.MovienumberSpinnerLabel.Text = 'Movie number';
            app.MovienumberSpinner = uispinner(app.CATTFrameExplorerUIFigure);
            app.MovienumberSpinner.ValueChangedFcn = createCallbackFcn(app, @MovienumberSpinnerValueChanged, true);
            app.MovieframeSpinnerLabel = uilabel(app.CATTFrameExplorerUIFigure);
            app.MovieframeSpinnerLabel.Text = 'Movie frame';
            app.MovieframeSpinner = uispinner(app.CATTFrameExplorerUIFigure);
            app.MovieframeSpinner.ValueChangedFcn = createCallbackFcn(app, @MovieframeSpinnerValueChanged, true);
            app.ExperimentframeSpinnerLabel = uilabel(app.CATTFrameExplorerUIFigure);
            app.ExperimentframeSpinnerLabel.Text = 'Experiment frame';
            app.ExperimentframeSpinner = uispinner(app.CATTFrameExplorerUIFigure);
            app.ExperimentframeSpinner.ValueChangedFcn = createCallbackFcn(app, @ExperimentframeSpinnerValueChanged, true);
            app.IlluminationcorrectionCheckBox = uicheckbox(app.CATTFrameExplorerUIFigure);
            app.IlluminationcorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @IlluminationcorrectionCheckBoxValueChanged, true);
            app.IlluminationcorrectionCheckBox.Text = 'Illumination correction';
            app.FocusonIDCheckBox = uicheckbox(app.CATTFrameExplorerUIFigure);
            app.FocusonIDCheckBox.Enable = 'off';
            app.FocusonIDCheckBox.Text = 'Focus on ID';
            app.ShowassigmentsCheckBox = uicheckbox(app.CATTFrameExplorerUIFigure);
            app.ShowassigmentsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowassigmentsCheckBoxValueChanged, true);
            app.ShowassigmentsCheckBox.Text = 'Show assigments';
            app.ROImaskCheckBox = uicheckbox(app.CATTFrameExplorerUIFigure);
            app.ROImaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @ROImaskCheckBoxValueChanged, true);
            app.ROImaskCheckBox.Text = 'ROI mask';
            app.ShowtrackletCheckBox = uicheckbox(app.CATTFrameExplorerUIFigure);
            app.ShowtrackletCheckBox.Enable = 'off';
            app.ShowtrackletCheckBox.Text = 'Show tracklet';
            app.IDDropDown = uidropdown(app.CATTFrameExplorerUIFigure);
            app.IDDropDown.Enable = 'off';
            app.TrackletTextArea = uitextarea(app.CATTFrameExplorerUIFigure);
            app.TrackletTextArea.Editable = 'off';
            app.TrackletTextArea.Enable = 'off';
            app.ColonyDropDownLabel = uilabel(app.CATTFrameExplorerUIFigure);
            app.ColonyDropDownLabel.Text = 'Colony';
            app.ColonyDropDown = uidropdown(app.CATTFrameExplorerUIFigure);
            app.ColonyDropDown.ValueChangedFcn = createCallbackFcn(app, @ColonyDropDownValueChanged, true);
            app.ShowblobsCheckBox = uicheckbox(app.CATTFrameExplorerUIFigure);
            app.ShowblobsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowblobsCheckBoxValueChanged, true);
            app.ShowblobsCheckBox.Text = 'Show blobs';
            app.ShowautoIDsCheckBox = uicheckbox(app.CATTFrameExplorerUIFigure);
            app.ShowautoIDsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowautoIDsCheckBoxValueChanged, true);
            app.ShowautoIDsCheckBox.Text = 'Show autoIDs';
        end
    end

    methods (Access = public)

        % Construct app
        function app = frame_explorer_app(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CATTFrameExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CATTFrameExplorerUIFigure)
        end
    end
end