classdef graph_explorer_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        anTraXGraphExplorerUIFigure  matlab.ui.Figure
        ExperimentMenu               matlab.ui.container.Menu
        OpenMenu                     matlab.ui.container.Menu
        RecentMenu                   matlab.ui.container.Menu
        SessionMenu                  matlab.ui.container.Menu
        QuitMenu                     matlab.ui.container.Menu
        GraphMenu                    matlab.ui.container.Menu
        ColonyMenu                   matlab.ui.container.Menu
        MovieMenu                    matlab.ui.container.Menu
        ViewMenu                     matlab.ui.container.Menu
        ZoomMenu                     matlab.ui.container.Menu
        ZoominMenu                   matlab.ui.container.Menu
        ZoomresetMenu                matlab.ui.container.Menu
        HighlightIDMenu              matlab.ui.container.Menu
        FramenumberingMenu           matlab.ui.container.Menu
        experimentNumberingMenu      matlab.ui.container.Menu
        subdirNumberingMenu          matlab.ui.container.Menu
        movieNumberingMenu           matlab.ui.container.Menu
        YpositionbytimeMenu          matlab.ui.container.Menu
        ZoomtofocalnodeMenu          matlab.ui.container.Menu
        GAxes                        matlab.ui.control.UIAxes
        ImageButton                  matlab.ui.control.Button
        ImageallframesButton         matlab.ui.control.Button
        FromLabel                    matlab.ui.control.Label
        FromEditField                matlab.ui.control.NumericEditField
        ToEditFieldLabel             matlab.ui.control.Label
        ToEditField                  matlab.ui.control.NumericEditField
        PlotButton                   matlab.ui.control.Button
        FrameDropDownLabel           matlab.ui.control.Label
        FrameDropDown                matlab.ui.control.DropDown
        ExportallButton              matlab.ui.control.Button
        IDLabel                      matlab.ui.control.Label
        IDDropDown                   matlab.ui.control.DropDown
        TrackletTextArea             matlab.ui.control.TextArea
        ShowframeButton              matlab.ui.control.Button
        AssignButton                 matlab.ui.control.Button
        ChooseframesButton           matlab.ui.control.Button
        ImageAxes                    matlab.ui.control.UIAxes
        XYAxes                       matlab.ui.control.UIAxes
        XAxes                        matlab.ui.control.UIAxes
        YAxes                        matlab.ui.control.UIAxes
        SingleantCheckBox            matlab.ui.control.CheckBox
        EliminateButton              matlab.ui.control.Button
        ManualfixLabel               matlab.ui.control.Label
        AddexamplesLabel             matlab.ui.control.Label
    end

    
    properties %(Access = private)
        Trck trhandles % Description
        G trgraph
        m
        c
        XY
        GraphPlot
        focus_id = ''
        focus_trj tracklet
        focus_node
        sg
        sg_assigned_ids
        sg_possible_ids
        sg_single_nodes
        sg_src_nodes
        sg_ant_nodes  
        sg_ant_subgraph 
        sg_trjs
        appdata
        dcm_obj
        numbering = 'experiment'
        ti
        tf
        lines
        
    end
    
    methods
        
        
        function goto(app, id, f, c)
        
            t = trtime(app.Trck, f);
            m = t.m;
            mf = t.mf;
            idix = find(strcmp(app.Trck.usedIDs,id));
            
            % load graph if needed
            reload = isempty(app.m~=app.m) || (app.Trck.get_param('geometry_multi_colony') && ~strcmp(c,app.c));
            if reload
                app.m = m;
                if app.Trck.get_param('geometry_multi_colony')
                    app.c = c;
                end
                load_G(app);
            end
            
            % find trj
             
            % get all trjs in f
            [~, ix] = app.G.trjs.inTime(f);
            ix = find(ix);
            
            assigned_ix = ix(app.G.assigned_ids(ix,idix));
            possible_ix = ix(app.G.possible_ids(ix,idix));
            
            % see if any of them is assigned 
            if ~isempty(assigned_ix)
                node = assigned_ix;
            elseif length(possible_ix)==1
                node = possible_ix;
            elseif length(possible_ix)>1
                report('W', 'More than one possible tracklets for id in frame - choosing the first')
                node = possible_ix(1);
            else
                report('W', 'No possible tracklets for id in frame')
                return
            end
            
            app.focus_node = node;
            app.focus_trj = app.G.trjs(node);
            
            % adjust plot range
            mfi = max([1,mf - 1000]);
            mff = max([mf + 1000, 2000]);
            mff = min([mff,app.G.tf.mf]);
            
            app.ti = trtime(app.Trck, app.m, mfi);
            app.tf = trtime(app.Trck, app.m, mff);
            update_frame_numbers(app);
            
            % plot 
            app.plot_G();
            
            % set focus trj
            app.set_focus_trj;
            
            % highlight id subgraph
            app.focus_id = id;
            for i = 1:length(app.HighlightIDMenu.Children)
                app.HighlightIDMenu.Children(i).Checked = false;
            end
            highlight_id(app);
        
        end
        
        function save_graph_image(app, filename)
        
        
        end
        
        
    end
    
    methods (Access = private)


        function set_experiment(app, expdir, session)
                    
            if  isa(expdir, 'trgraph')
                
                app.G = expdir;
                app.Trck = app.G.Trck;
                expdir = app.Trck.expdir;
                session = app.Trck.trackingdirname;
                
            elseif isa(expdir, 'trhandles')
                
                app.G = trgraph.empty;
                app.Trck = expdir;
                expdir = app.Trck.expdir;
                session = app.Trck.trackingdirname;
            
            elseif nargin<3 || isempty(session)
                
                app.G = trgraph.empty;
                app.Trck = trhandles.load(expdir);
                session = app.Trck.trackingdirname;
                
            else
                
                app.G = trgraph.empty;
                app.Trck = trhandles.load(expdir, session);
                
            end
            
            % clear axes

            % session menu
            app.SessionMenu.Enable = true;
            sessions = find_sessions(expdir);
            delete(app.SessionMenu.Children);
            
            for i=1:length(sessions)
                
                sm = uimenu(app.SessionMenu);
                sm.Text = sessions{i};
                sm.MenuSelectedFcn = createCallbackFcn(app, @SessionMenuSelected, true);
                
                if strcmp(session, sessions{i})
                    sm.Checked = true;
                end
            end
            
            % movie menu
            app.MovieMenu.Enable = true;
            delete(app.MovieMenu.Children);
            for i=1:length(app.Trck.graphlist)
                m = app.Trck.graphlist(i);
                sm = uimenu(app.MovieMenu);
                sm.Text = num2str(m);
                sm.MenuSelectedFcn = createCallbackFcn(app, @MovieChanged, true);
                if ~isempty(app.G) && app.G.m==m
                    sm.Checked = true;
                end
            end
            
            
            % add list of IDs to menu      
            
            app.IDDropDown.Items = unique(app.Trck.allLabels, 'Stable');
            app.IDDropDown.Value = app.Trck.usedIDs{1};
            
            ids = app.Trck.usedIDs;
            
            delete(app.HighlightIDMenu.Children);
            
            sm = uimenu(app.HighlightIDMenu);
            sm.Text = 'None';
            sm.MenuSelectedFcn = createCallbackFcn(app, @IDhiglightChanged, true);
            sm.Checked = true;
            
            for i=1:length(ids)
                sm = uimenu(app.HighlightIDMenu);
                sm.Text = ids{i};
                sm.MenuSelectedFcn = createCallbackFcn(app, @HighlightIDChanged, true);
                sm.Checked = false;
            end
            
            % colony list
            delete(app.ColonyMenu.Children);
            if app.Trck.prmtrs.geometry_multi_colony
                app.ColonyMenu.Enable = true;
                for c=1:length(app.Trck.colony_labels)
                    sm = uimenu(app.ColonyMenu);
                    sm.Text = app.Trck.colony_labels{c};
                    sm.MenuSelectedFcn = createCallbackFcn(app, @ColonyChanged, true);
                    if ~isempty(app.G) && strcmp(app.G.colony, sm.Text)
                        sm.Checked = true;
                    end
                end
            else
                app.ColonyMenu.Enable = false;
            end
            
            setG(app);
                    
        end
                
        function load_G(app)
           
            if isempty(app.m) || (app.Trck.get_param('geometry_multi_colony') && isempty(app.c))
                return
            end
        
            d = uiprogressdlg(app.anTraXGraphExplorerUIFigure,'Title','Loading data','Indeterminate','on');            
            app.G = app.Trck.loaddata(app.m,app.c);
                       
            setG(app);
            clear_axes(app);
            close(d);
            
            report('D', 'graph loaded')
            
        end
        
        function update_frame_numbers(app)
            
                        
            switch app.numbering
                
                case 'experiment'
                    
                    lims = [app.G.ti.f,app.G.tf.f];
                    from = app.ti.f;
                    to = app.tf.f;
                    
                case 'subdir'
                    
                    sd = app.m>=[app.Trck.er.subdirs.mi] & app.m<=[app.Trck.er.subdirs.mf];
                    sdfi = app.Trck.er.movies_info(app.Trck.er.subdirs(sd).mi).fi;
                    lims = [app.G.ti.f,app.G.tf.f] - sdfi + 1;
                    from = app.ti.f - sdfi + 1;
                    to = app.tf.f - sdfi + 1;
                    
                case 'movie'
                    
                    lims = [app.G.ti.mf,app.G.tf.mf];
                    from = app.ti.mf;
                    to = app.tf.mf;
                    
                otherwise
                    
                    report('E','Wrong numbering')
                
            end
            
            
            app.FromEditField.Enable = true;
            app.FromEditField.Limits = lims;
            app.FromEditField.Value = from;
            app.ToEditField.Enable = true;
            app.ToEditField.Limits = lims;
            app.ToEditField.Value = to;
            
        end
        
        function setG(app)
        
            if isempty(app.G)
                return
            end
            
            d = uiprogressdlg(app.anTraXGraphExplorerUIFigure,'Title','Loading data','Indeterminate','on');   
                        
            app.ti = app.G.ti;
            app.tf = min([app.G.tf,app.ti+1999]);
            update_frame_numbers(app);
            
            app.PlotButton.Enable = true;
            app.FromEditField.Enable = true;
            app.ToEditField.Enable = true;
                                    
            if ischar(app.G.xyfile) && exist(app.G.xyfile,'file')
                app.XY = load(app.G.xyfile);
            else
                app.XY = [];
                report('W','No XY file found')
            end
            
            app.G.node_single = app.G.get_singles;

            
            for i=1:length(app.MovieMenu.Children)
                app.MovieMenu.Children(i).Checked = strcmp(app.MovieMenu.Children(i).Text,num2str(app.m));
            end
            
            for i=1:length(app.ColonyMenu.Children)
                app.ColonyMenu.Children(i).Checked = strcmp(app.ColonyMenu.Children(i).Text,app.c);
            end
            
            close(d);
            
        end
        
        function clear_axes(app)
        
            cla(app.GAxes)
            cla(app.XYAxes)
            cla(app.XAxes)
            cla(app.YAxes)
            cla(app.ImageAxes)
            set(app.GAxes,'XTick',[],'YTick',[],'Box','on')
            set(app.XYAxes,'XTick',[],'YTick',[],'Box','on')
            set(app.YAxes,'XTick',[],'YTick',[],'Box','on')
            set(app.XAxes,'XTick',[],'YTick',[],'Box','on')
            set(app.ImageAxes,'XTick',[],'YTick',[],'Box','on')
            app.GAxes.XLabel.String='';
            app.GAxes.YLabel.String='';

        end
        
        function plot_G(app)


            d = uiprogressdlg(app.anTraXGraphExplorerUIFigure,'Title','Plotting data','Indeterminate','on');
        
            fi = app.ti.f;
            ff = app.tf.f;
            
            
            % extract subgraph to plot
            if ~isfield(app.G.aux,'fi')
                app.G.aux.fi = [app.G.trjs.fi];
                app.G.aux.ff = [app.G.trjs.ff];
            end
            node_fi = app.G.aux.fi;
            node_ff = app.G.aux.ff;
            
            include = find((node_ff >= fi) & (node_fi <=ff));
            exclude = indegree(app.G.G,include)==0 & outdegree(app.G.G,include)==0 & torow(~any(app.G.possible_ids(include,:),2));
            include = include(~exclude);
            
            if isempty(include)
                report('E','slice contained no nodes')
                return
            end
            
            app.sg = subgraph(app.G.G,include);
            try
                app.sg_assigned_ids = app.G.assigned_ids(include,:);
                app.sg_possible_ids = app.G.possible_ids(include,:);
            catch
                app.sg_assigned_ids = false(length(include),app.Trck.NIDs);
                app.sg_possible_ids = false(length(include),app.Trck.NIDs);
            end
            
            % find first and last layer tracklet
            app.sg_trjs = [app.sg.Nodes.trj];
            first = find([app.sg_trjs.fi]<=fi);
            last = find([app.sg_trjs.ff]>=ff);
            both = intersect(first,last);
            last = setdiff(last,both);
            
            z = app.sg.numnodes;
            
            nodey = tocol([app.sg_trjs.fi]);
            
            
            % create dummy nodes for singular nodes
            for i=1:length(both)
                app.sg = addnode(app.sg,['dummy',num2str(i)]);
                app.sg = addedge(app.sg,app.sg.Nodes(both(i),:).Name,['dummy',num2str(i)],1);
                nodey = [nodey;max(nodey)];
            end


            dummies = tocol(z+1:app.sg.numnodes);
            
            app.sg_src_nodes    = find(ismember({app.sg_trjs.propID},app.G.usedIDs));
            app.sg_single_nodes = find(tocol(app.sg_trjs.isSingle) & tocol(~ismember({app.sg_trjs.propID},app.Trck.labels.noant_labels)));
            
            
            from = app.sg.Edges.EndNodes(:,1);
            to = app.sg.Edges.EndNodes(:,2);
            
            app.sg_ant_nodes  = find(~ismember({app.sg_trjs.propID},app.Trck.labels.noant_labels));
            
            to_keep = ismember(from,app.sg.Nodes.Name(app.sg_ant_nodes)) & ismember(to,app.sg.Nodes.Name(app.sg_ant_nodes));
            
            app.sg_ant_subgraph = rmedge(app.sg,find(~to_keep));
                        
            % create the graph plot
            app.GraphPlot = plot(app.GAxes,app.sg,'Layout','layered','Sources',first,'Sinks',last,'NodeLabel',[]);
            if strcmp(app.YpositionbytimeMenu.Checked,'on')
                nodex = get(app.GraphPlot,'XData');
                set(app.GraphPlot,'XData',nodex,'YData',-nodey)
            end
            set(app.GAxes,'XTick',[],'YTick',[],'Box','on')
            
            
            highlight_none(app);
            
            
            app.HighlightIDMenu.Enable = true;
            app.ZoomMenu.Enable = true;
            
            %labels = arrayfun(@(x) [num2str(x.index)]  ,[sg.Nodes.trj],'UniformOutput',false);
            %labelnode(h,1:size(sg.Nodes,1),labels);
            
            set(app.GraphPlot,'ButtonDownFcn',@app.pick_node)
            
            
            app.GAxes.XLimMode = 'auto';
            app.GAxes.YLimMode = 'auto';
            
            plot_xy(app);
                        
            if ismember(app.focus_id,app.G.usedIDs)
                highlight_id(app);
            end
            
            close(d)
            
        end
        
        function highlight_none(app)
            
            % all including noant
            highlight(app.GraphPlot,app.sg,'Marker','o','EdgeColor',[0.9,0.9,0.9],'NodeColor',[0.9,0.9,0.9],'LineWidth',0.5,'MarkerSize',2)
            
            % now without noant
            highlight(app.GraphPlot,app.sg_ant_subgraph,'Marker','s','EdgeColor',[0.7,0.7,0.7],'NodeColor',[0.7,0.7,0.7],'LineWidth',1,'MarkerSize',4)
            
            % now single ant nodes
            highlight(app.GraphPlot,app.sg_single_nodes,'Marker','o','EdgeColor',[0.7,0.7,0.7],'NodeColor',[0.7,0.7,0.7],'LineWidth',1,'MarkerSize',4)
            
            % now src nodes
            highlight(app.GraphPlot,app.sg_src_nodes,'Marker','o','EdgeColor',[0.3,0.3,0.3],'NodeColor',[0.3,0.3,0.3],'LineWidth',1,'MarkerSize',4)
            
           
        end
        
        function highlight_id(app)
            
            
            id = find(strcmp(app.focus_id,app.G.usedIDs));
            
            highlight_none(app);
            
            if isempty(id)
                return
            end
            
            idstr = app.G.Trck.usedIDs{id};
            
            id_nodes = find(app.sg_possible_ids(:,id)|app.sg_assigned_ids(:,id));
            
            from = app.sg.Edges.EndNodes(:,1);
            to = app.sg.Edges.EndNodes(:,2);
            
            to_keep = ismember(from,app.sg.Nodes.Name(id_nodes)) & ismember(to,app.sg.Nodes.Name(id_nodes));
            
            idsg = rmedge(app.sg,find(~to_keep));
            
            highlight(app.GraphPlot,idsg,'EdgeColor','r','NodeColor','r','LineWidth',1,'MarkerSize',4);
            assigned_nodes =  find(app.sg_assigned_ids(:,id));
            highlight(app.GraphPlot,assigned_nodes,'NodeColor','m','MarkerSize',4);
            idsrcnodes = app.sg_src_nodes(strcmp(idstr,{app.sg_trjs(app.sg_src_nodes).autoID}));
            idsrcnodes = intersect(idsrcnodes,id_nodes);
            
            highlight(app.GraphPlot,idsrcnodes,'NodeColor','g','MarkerSize',4);
            
            plot_xy_id(app);
            
        end
        
        function pick_node(app,GP,event)
            
            % get node
            pos = event.IntersectionPoint;
            d = sqrt((GP.XData - pos(1)).^2 + (GP.YData - pos(2)).^2);
            
            ind = argmin(d);
            trj = app.sg_trjs(ind);
%             Gind = findnode(app.G.G,trj.name);
            
%             assigned_ids = app.G.usedIDs(app.sg_assigned_ids(ind,:));
%             assigned_str = strjoin(assigned_ids,' ');
%             possible_ids = app.G.usedIDs(app.sg_possible_ids(ind,:));
%             possible_ids = setdiff(possible_ids,assigned_ids);
%             possible_str = strjoin(possible_ids,' ');
%             
%             % highligh selected node
%             highlight(app.GraphPlot,app.sg,'MarkerSize',4)
%             highlight(app.GraphPlot,ind,'MarkerSize',8);
%             
%             % print tracklet info
%             text{1} =     ['Name: ',trj.name];
%             text{end+1} = ['Index in G: ',num2str(Gind)];
%             text{end+1} = ['Length: ',num2str(trj.len)];
%             text{end+1} = ['From: ',num2str(trj.fi),' (',num2str(trj.ti.mf),')'];
%             text{end+1} = ['To:   ',num2str(trj.ff),' (',num2str(trj.tf.mf),')'];
%             text{end+1} = ['manualID: ',trj.manualID];
%             text{end+1} = ['autoID: ',trj.autoID];
%             text{end+1} = ['Assigned: ',assigned_str];
%             text{end+1} = ['Possible: ',possible_str];
%             
%             app.TrackletTextArea.Value = text;
            set_focus_trj(app,trj)


        end
        
        function set_focus_trj(app,trj)
            
            if nargin>1
                app.focus_trj = trj;
            else
                trj = app.focus_trj;
            end
            
            
            Gind = findnode(app.G.G,trj.name);
            ind = findnode(app.sg,trj.name);
            
            assigned_ids = app.G.usedIDs(app.sg_assigned_ids(ind,:));
            assigned_str = strjoin(assigned_ids,' ');
            possible_ids = app.G.usedIDs(app.sg_possible_ids(ind,:));
            possible_ids = setdiff(possible_ids,assigned_ids);
            possible_str = strjoin(possible_ids,' ');
            
            % highligh selected node
            highlight(app.GraphPlot,app.sg,'MarkerSize',4)
            highlight(app.GraphPlot,ind,'MarkerSize',8);
            
            % print tracklet info
            text{1} =     ['Name: ',trj.name];
            text{end+1} = ['Index in G: ',num2str(Gind)];
            text{end+1} = ['Length: ',num2str(trj.len)];
            text{end+1} = ['From: ',num2str(trj.fi),' (',num2str(trj.ti.mf),')'];
            text{end+1} = ['To:   ',num2str(trj.ff),' (',num2str(trj.tf.mf),')'];
            text{end+1} = ['manualID: ',trj.manualID];
            text{end+1} = ['autoID: ',trj.autoID];
            text{end+1} = ['Assigned: ',assigned_str];
            text{end+1} = ['Possible: ',possible_str];
            
            app.TrackletTextArea.Value = text;
            %%

            app.focus_node = findnode(app.G.G,trj.name);
            app.FrameDropDown.Items = arrayfun(@(x) [num2str(trj.time2indx(x)), '(',num2str(x.f),')'],trj.tt,'UniformOutput',false);
            app.FrameDropDown.Value = app.FrameDropDown.Items{1};
        
            
            app.ImageButton.Enable = true;
            app.ShowframeButton.Enable = true;
            app.FrameDropDown.Enable = true;
            
            app.AssignButton.Enable = false;
            
            app.IDDropDown.Enable = true;
            
            
            app.AssignButton.Enable = false;
            
            
            % set default id
            if ismember(app.focus_trj.manualID,app.Trck.allLabels)
                id = app.focus_trj.manualID;
            elseif nnz(app.G.assigned_ids(app.focus_node,:))==1
                id = app.G.usedIDs{app.G.assigned_ids(app.focus_node,:)};
            elseif ismember(app.focus_trj.autoID,app.Trck.allLabels)
                id = app.focus_trj.autoID;
            else
                id = app.IDDropDown.Value;
            end
            app.IDDropDown.Value = id;
            
            app.SingleantCheckBox.Enable = true;
            app.SingleantCheckBox.Value = app.G.node_single(app.focus_node);
            
            app.AssignButton.Enable = true;
            app.EliminateButton.Enable = true;
                
            if trj.isSingle('criteria',{'maxarea'})
                app.ExportallButton.Enable = true;
                app.ChooseframesButton.Enable = true;
                app.ImageallframesButton.Enable = true;
                ImageButtonPushed(app)
            else
                app.ExportallButton.Enable = false;
                app.ChooseframesButton.Enable = false;
                app.ImageallframesButton.Enable = false;
                imshow(zeros(100,100,3),'Parent',app.ImageAxes)
                set(app.ImageAxes,'XTick',[],'YTick',[],'Box','on')
                
            end
            
            plot_xy_trj(app);
            
        end
    
    
        function plot_xy(app)
                        
            app.XYAxes.cla;
            app.XAxes.cla;
            app.YAxes.cla;
            
            hold(app.XYAxes,'on')
            hold(app.XAxes,'on')
            hold(app.YAxes,'on')
            
            m = app.ti.m;
            app.lines.mfrom = app.ti.mf;
            app.lines.mto = app.tf.mf;
            
            switch app.numbering
                case 'experiment'
                    app.lines.xdata = app.ti.f:app.tf.f;
                case 'subdir'
                    sd = m>=[app.Trck.er.subdirs.mi] & m<=[app.Trck.er.subdirs.mf];
                    sdfi = app.Trck.er.movies_info(app.Trck.er.subdirs(sd).mi).fi;
                    app.lines.xdata = [app.ti.f:app.tf.f] - sdfi + 1;
                case 'movie'
                    app.lines.xdata = app.ti.mf:app.tf.mf;
                otherwise  
                    report('E','Wrong numbering')
            end
            
            % plot all ids
            fn = fieldnames(app.XY);
            for i=1:length(fn)
                id = fn{i};
                app.lines.xy_all = plot(app.XYAxes,app.XY.(id)(app.lines.mfrom:app.lines.mto,1),app.XY.(id)(app.lines.mfrom:app.lines.mto,2),'Color',[0.7,0.7,0.7,0.5],'LineWidth',1);
                app.lines.x_all = plot(app.XAxes,app.lines.xdata,app.XY.(id)(app.lines.mfrom:app.lines.mto,1),'Color',[0.7,0.7,0.7,0.5],'LineWidth',1);
                app.lines.y_all = plot(app.YAxes,app.lines.xdata,app.XY.(id)(app.lines.mfrom:app.lines.mto,2),'Color',[0.7,0.7,0.7,0.5],'LineWidth',1);
            end

            set(app.XYAxes,'XTick',[],'YTick',[],'Box','on')
            set(app.XAxes,'XTick',[],'YTick',[],'Box','on')
            set(app.YAxes,'XTick',[],'YTick',[],'Box','on')
            
            plot_xy_id(app)
            
            
        end
    
        function plot_xy_id(app)
            
            
            id = app.focus_id;
            fn = fieldnames(app.XY);
            
            % delete previous
            
            if isfield(app.lines,'xy_id')
                delete(app.lines.xy_id);
            end
            
            if isfield(app.lines,'x_id')
                delete(app.lines.x_id);
            end
            
            if isfield(app.lines,'y_id')
                delete(app.lines.y_id);
            end
            
            % plot current
            
            if ismember(app.focus_id,fn)
                app.lines.xy_id = plot(app.XYAxes,app.XY.(id)(app.lines.mfrom:app.lines.mto,1),app.XY.(id)(app.lines.mfrom:app.lines.mto,2),'Color',[0.9,0.7,0.7],'LineWidth',1);
                app.lines.x_id = plot(app.XAxes,app.lines.xdata,app.XY.(id)(app.lines.mfrom:app.lines.mto,1),'Color',[0.9,0.7,0.7],'LineWidth',1);
                app.lines.y_id = plot(app.YAxes,app.lines.xdata,app.XY.(id)(app.lines.mfrom:app.lines.mto,2),'Color',[0.9,0.7,0.7],'LineWidth',1);
            end
        
        
        end
    
        function plot_xy_trj(app)
                                   
            is_in_focus = app.G.assigned_ids(app.focus_node,strcmp(app.focus_id,app.G.usedIDs));
                        
            switch app.numbering
                case 'experiment'
                    xdata1 = app.focus_trj.fi:app.focus_trj.ff;
                case 'subdir'
                    sd = app.m>=[app.Trck.er.subdirs.mi] & app.m<=[app.Trck.er.subdirs.mf];
                    sdfi = app.Trck.er.movies_info(app.Trck.er.subdirs(sd).mi).fi;
                    xdata1 = [app.focus_trj.fi:app.focus_trj.ff] - sdfi + 1;
                case 'movie'
                    xdata1 = app.focus_trj.ti.mf:app.focus_trj.tf.mf;
                otherwise  
                    report('E','Wrong numbering')
            end
            
            % delete previous
            
            if isfield(app.lines,'xy_trj')
                delete(app.lines.xy_trj);
            end
            
            if isfield(app.lines,'x_trj')
                delete(app.lines.x_trj);
            end
            
            if isfield(app.lines,'y_trj')
                delete(app.lines.y_trj);
            end
            
            % plot current
            if is_in_focus
                color = [0.9,0.4,0.4];
            else
                color = [0.2,0.2,0.2];
            end
            
            app.lines.xy_trj = plot(app.XYAxes,app.focus_trj.x,app.focus_trj.y,'Color',color,'LineWidth',3);
            app.lines.x_trj = plot(app.XAxes,xdata1,app.focus_trj.x,'Color',color,'LineWidth',3);
            app.lines.y_trj = plot(app.YAxes,xdata1,app.focus_trj.y,'Color',color,'LineWidth',3);
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Trck, varargin)
            
            p = inputParser;
            addRequired(p,'Trck',@(x) isa(x,'trhandles') || isa(x,'trgraph') || isfolder(x));
            addParameter(p, 'session', [], @ischar);
            addParameter(p, 'm', []);
            parse(p,Trck,varargin{:});

            clear_axes(app);
            set_experiment(app, Trck, p.Results.session);
            
            report('D', 'experiment loaded')
            if ~isempty(p.Results.m) 
                m = p.Results.m;
                if ischar(m)
                    m = str2num(m);
                end
                if m>0
                    app.m = m;
                    if ischar(app.m)
                        app.m = str2num(app.m);
                    end
                    load_G(app);
                end
            end
            report('I', 'Ready!')

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            plot_G(app)
        end

        % Callback function
        function HighlightIDChanged(app, event)
            app.focus_id = event.Source.Text;
            for i = 1:length(app.HighlightIDMenu.Children)
                app.HighlightIDMenu.Children(i).Checked = false;
            end
            event.Source.Checked = true;
            highlight_id(app);
        end

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
            %fh = tagged_figure('trj_image');
            %figure(fh);
            framestr = app.FrameDropDown.Value;
            s = strsplit(framestr,'(');
            ix = str2num(s{1});
            t = app.focus_trj.tt(ix);
            im=get_image(app.focus_trj,t);
            imshow(im,'Parent',app.ImageAxes)
            set(app.ImageAxes,'XTick',[],'YTick',[],'Box','on')
        end

        % Button pushed function: ImageallframesButton
        function ImageallframesButtonPushed(app, event)
            fh = tagged_figure('tracklet image');
            figure(fh);
            image(app.focus_trj,'all',[],[],false);
        end

        % Callback function
        function PlotXYButtonPushed(app, event)
            plot_xy_trj(app)
            
        end

        % Button pushed function: ExportallButton
        function ExportallButtonPushed(app, event)
            
            id = app.IDDropDown.Value;
            
            if app.focus_trj.isSingle('criteria',{'maxarea'})
                app.focus_trj.add_examples(id);
            else
                report('W','Trying to export examples from non single tracklet')
            end
            
        end

        % Button pushed function: AssignButton
        function AssignButtonPushed2(app, event)
            
            
            id = app.IDDropDown.Value;
            
            %if app.focus_trj.isSingle('criteria',{'maxarea'})
            app.focus_trj.add_manual_id(id);
            add_to_manual_cfg(app.Trck,app.focus_trj.name,'assign',id);
            app.set_focus_trj(app.focus_trj);


        end

        % Callback function
        function AssignButtonPushed(app, event)
            
            report('W','not implemented')
            return

            
        end

        % Menu selected function: ZoominMenu
        function ZoomButtonValueChanged(app, event)
            
            if  strcmp(event.Source.Checked, 'off')
                event.Source.Checked = 'on';
            else
                event.Source.Checked = 'off';
            end
                    
            if event.Source.Checked
                pan(app.GAxes,'on');
                zoom(app.GAxes,'on');
            else
                pan(app.GAxes,'off');
                zoom(app.GAxes,'off');
            end
        end

        % Menu selected function: ZoomresetMenu
        function ViewResetButtonPushed(app, event)
            app.GAxes.XLimMode = 'auto';
            app.GAxes.YLimMode = 'auto';
        end

        % Button pushed function: ChooseframesButton
        function ChooseframesButtonPushed(app, event)
            export_frame_selector_app(app.focus_trj,app.IDDropDown.Value);
        end

        % Value changed function: FromEditField, ToEditField
        function update_ti_tf(app, event)
            fi = app.FromEditField.Value;
            ff = app.ToEditField.Value;
            m = app.G.movlist(1);
            
            switch app.numbering
                case 'experiment'
                    app.ti = trtime(app.Trck,fi);
                    app.tf = trtime(app.Trck,ff);
                case 'subdir'
                    sd = m>=[app.Trck.er.subdirs.mi] & m<=[app.Trck.er.subdirs.mf];
                    sdfi = app.Trck.er.movies_info(app.Trck.er.subdirs(sd).mi).fi;
                    app.ti = trtime(app.Trck,fi+sdfi-1);
                    app.tf = trtime(app.Trck,ff+sdfi-1);
                case 'movie'
                    app.ti = trtime(app.Trck,m,fi);
                    app.tf = trtime(app.Trck,m,ff);
                otherwise
                    report('E','Wring numbering')
            end
            
        end

        % Callback function
        function MovieChanged(app, event)
            app.m = str2num(event.Source.Text);
            load_G(app);
        end

        % Callback function
        function ColonyChanged(app, event)
            app.c = event.Source.Text;
            load_G(app);
        end

        % Menu selected function: YpositionbytimeMenu
        function YpositionbytimeMenuSelected(app, event)
            if strcmp(event.Source.Checked,'off')
                event.Source.Checked = 'on';
            else
                event.Source.Checked = 'off';
            end
            plot_G(app)
        end

        % Menu selected function: experimentNumberingMenu, 
        % movieNumberingMenu, subdirNumberingMenu
        function FrameNumberingChanged(app, event)
            app.numbering = event.Source.Text;
            app.experimentNumberingMenu.Checked = strcmp(app.numbering, 'experiment');
            app.subdirNumberingMenu.Checked = strcmp(app.numbering, 'subdir');
            app.movieNumberingMenu.Checked = strcmp(app.numbering, 'movie');
            update_frame_numbers(app);
        end

        % Callback function
        function SessionMenuSelected(app, event)
            set_experiment(app, app.Trck.expdir, event.Source.Text);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            expdir = uigetdir([app.Trck.expdir,'../']);
            set_experiment(app, expdir);
        end

        % Callback function: QuitMenu, anTraXGraphExplorerUIFigure
        function anTraXGraphExplorerUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: SingleantCheckBox
        function SingleantCheckBoxValueChanged(app, event)
            value = app.SingleantCheckBox.Value;
            app.G.node_single(app.focus_node) = value;
            add_to_manual_cfg(app.Trck,app.focus_trj.name,'single',value);
        end

        % Button pushed function: ShowframeButton
        function ShowframeButtonPushed(app, event)
            framestr = app.FrameDropDown.Value;
            s = strsplit(framestr,'(');
            ix = str2num(s{1});
            t = app.focus_trj.tt(ix);
            app.Trck.read_frame(t);
            detect_blobs(app.Trck);
            
            mask = app.Trck.Masks.roi(:,:,1);
            frame = app.Trck.currfrm.CData;
            
            L = app.Trck.currfrm.antblob.LABEL;
            
            
            app.focus_trj.loaddata;
            
            xy = app.focus_trj.CENTROID(ix,:);
            
            bix = argmin(pdist2(single(xy),single(app.Trck.currfrm.antblob.DATA.CENTROID)));
            
            L = L==bix;
            
            frame = applyMasktoIm(frame,mask);
            S = regionprops(L,'ConvexHull');

            bch = S.ConvexHull;            
              
            fh = tagged_figure('frame image');
            figure(fh);
            cla
            hold on
            image(frame);
            plot(gca,bch(:,1),bch(:,2),'Color',rgb('Magenta'),'LineWidth',2);
            set(gca,'XTick',[],'YTick',[],'Box','on')
            
        end

        % Button pushed function: EliminateButton
        function EliminateButtonPushed(app, event)
            
            
            id = app.IDDropDown.Value;
            
            %if app.focus_trj.isSingle('criteria',{'maxarea'})
            app.focus_trj.add_manual_id(id);
            add_to_manual_cfg(app.Trck,app.focus_trj.name,'eliminate',id);
            app.set_focus_trj(app.focus_trj);
            
        end

        % Menu selected function: ZoomtofocalnodeMenu
        function ZoomtofocalnodeMenuSelected(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.anTraXGraphExplorerUIFigure = uifigure('Visible', 'off');
            app.anTraXGraphExplorerUIFigure.Name = 'anTraX: Graph Explorer';
            app.anTraXGraphExplorerUIFigure.CloseRequestFcn = createCallbackFcn(app, @anTraXGraphExplorerUIFigureCloseRequest, true);
            app.ExperimentMenu = uimenu(app.anTraXGraphExplorerUIFigure);
            app.ExperimentMenu.Text = 'Experiment';
            app.OpenMenu = uimenu(app.ExperimentMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.RecentMenu = uimenu(app.ExperimentMenu);
            app.RecentMenu.Enable = 'off';
            app.RecentMenu.Text = 'Recent';
            app.SessionMenu = uimenu(app.ExperimentMenu);
            app.SessionMenu.Text = 'Session';
            app.QuitMenu = uimenu(app.ExperimentMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @anTraXGraphExplorerUIFigureCloseRequest, true);
            app.QuitMenu.Separator = 'on';
            app.QuitMenu.Text = 'Quit';
            app.GraphMenu = uimenu(app.anTraXGraphExplorerUIFigure);
            app.GraphMenu.Text = 'Graph';
            app.ColonyMenu = uimenu(app.GraphMenu);
            app.ColonyMenu.Text = 'Colony';
            app.MovieMenu = uimenu(app.GraphMenu);
            app.MovieMenu.Text = 'Movie';
            app.ViewMenu = uimenu(app.anTraXGraphExplorerUIFigure);
            app.ViewMenu.Text = 'View';
            app.ZoomMenu = uimenu(app.ViewMenu);
            app.ZoomMenu.Visible = 'off';
            app.ZoomMenu.Enable = 'off';
            app.ZoomMenu.Text = 'Zoom';
            app.ZoominMenu = uimenu(app.ZoomMenu);
            app.ZoominMenu.MenuSelectedFcn = createCallbackFcn(app, @ZoomButtonValueChanged, true);
            app.ZoominMenu.Text = 'Zoom in';
            app.ZoomresetMenu = uimenu(app.ZoomMenu);
            app.ZoomresetMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewResetButtonPushed, true);
            app.ZoomresetMenu.Text = 'Zoom reset';
            app.HighlightIDMenu = uimenu(app.ViewMenu);
            app.HighlightIDMenu.Enable = 'off';
            app.HighlightIDMenu.Text = 'Highlight ID';
            app.FramenumberingMenu = uimenu(app.ViewMenu);
            app.FramenumberingMenu.Text = 'Frame numbering';
            app.experimentNumberingMenu = uimenu(app.FramenumberingMenu);
            app.experimentNumberingMenu.MenuSelectedFcn = createCallbackFcn(app, @FrameNumberingChanged, true);
            app.experimentNumberingMenu.Checked = 'on';
            app.experimentNumberingMenu.Text = 'experiment';
            app.subdirNumberingMenu = uimenu(app.FramenumberingMenu);
            app.subdirNumberingMenu.MenuSelectedFcn = createCallbackFcn(app, @FrameNumberingChanged, true);
            app.subdirNumberingMenu.Text = 'subdir';
            app.movieNumberingMenu = uimenu(app.FramenumberingMenu);
            app.movieNumberingMenu.MenuSelectedFcn = createCallbackFcn(app, @FrameNumberingChanged, true);
            app.movieNumberingMenu.Text = 'movie';
            app.YpositionbytimeMenu = uimenu(app.ViewMenu);
            app.YpositionbytimeMenu.MenuSelectedFcn = createCallbackFcn(app, @YpositionbytimeMenuSelected, true);
            app.YpositionbytimeMenu.Checked = 'on';
            app.YpositionbytimeMenu.Text = 'Y position by time';
            app.ZoomtofocalnodeMenu = uimenu(app.ViewMenu);
            app.ZoomtofocalnodeMenu.MenuSelectedFcn = createCallbackFcn(app, @ZoomtofocalnodeMenuSelected, true);
            app.ZoomtofocalnodeMenu.Text = 'Zoom to focal node';
            app.GAxes = uiaxes(app.anTraXGraphExplorerUIFigure);
            title(app.GAxes, '')
            xlabel(app.GAxes, 'X')
            ylabel(app.GAxes, 'Y')
            app.ImageButton = uibutton(app.anTraXGraphExplorerUIFigure, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Enable = 'off';
            app.ImageButton.Text = 'Image';
            app.ImageallframesButton = uibutton(app.anTraXGraphExplorerUIFigure, 'push');
            app.ImageallframesButton.ButtonPushedFcn = createCallbackFcn(app, @ImageallframesButtonPushed, true);
            app.ImageallframesButton.Enable = 'off';
            app.ImageallframesButton.Text = 'Image all frames';
            app.FromLabel = uilabel(app.anTraXGraphExplorerUIFigure);
            app.FromLabel.Text = 'From:';
            app.FromEditField = uieditfield(app.anTraXGraphExplorerUIFigure, 'numeric');
            app.FromEditField.ValueChangedFcn = createCallbackFcn(app, @update_ti_tf, true);
            app.FromEditField.Enable = 'off';
            app.FromEditField.Value = 1;
            app.ToEditFieldLabel = uilabel(app.anTraXGraphExplorerUIFigure);
            app.ToEditFieldLabel.Text = 'To:';
            app.ToEditField = uieditfield(app.anTraXGraphExplorerUIFigure, 'numeric');
            app.ToEditField.ValueChangedFcn = createCallbackFcn(app, @update_ti_tf, true);
            app.ToEditField.Enable = 'off';
            app.ToEditField.Value = 1000;
            app.PlotButton = uibutton(app.anTraXGraphExplorerUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.FrameDropDownLabel = uilabel(app.anTraXGraphExplorerUIFigure);
            app.FrameDropDownLabel.Text = 'Frame:';
            app.FrameDropDown = uidropdown(app.anTraXGraphExplorerUIFigure);
            app.FrameDropDown.Enable = 'off';
            app.ExportallButton = uibutton(app.anTraXGraphExplorerUIFigure, 'push');
            app.ExportallButton.ButtonPushedFcn = createCallbackFcn(app, @ExportallButtonPushed, true);
            app.ExportallButton.Enable = 'off';
            app.ExportallButton.Text = 'Export all';
            app.IDLabel = uilabel(app.anTraXGraphExplorerUIFigure);
            app.IDLabel.Text = 'ID:';
            app.IDDropDown = uidropdown(app.anTraXGraphExplorerUIFigure);
            app.IDDropDown.Enable = 'off';
            app.TrackletTextArea = uitextarea(app.anTraXGraphExplorerUIFigure);
            app.ShowframeButton = uibutton(app.anTraXGraphExplorerUIFigure, 'push');
            app.ShowframeButton.ButtonPushedFcn = createCallbackFcn(app, @ShowframeButtonPushed, true);
            app.ShowframeButton.Enable = 'off';
            app.ShowframeButton.Text = 'Show frame';
            app.AssignButton = uibutton(app.anTraXGraphExplorerUIFigure, 'push');
            app.AssignButton.ButtonPushedFcn = createCallbackFcn(app, @AssignButtonPushed2, true);
            app.AssignButton.Enable = 'off';
            app.AssignButton.Text = 'Assign';
            app.ChooseframesButton = uibutton(app.anTraXGraphExplorerUIFigure, 'push');
            app.ChooseframesButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseframesButtonPushed, true);
            app.ChooseframesButton.Enable = 'off';
            app.ChooseframesButton.Text = 'Choose frames';
            app.ImageAxes = uiaxes(app.anTraXGraphExplorerUIFigure);
            app.XYAxes = uiaxes(app.anTraXGraphExplorerUIFigure);
            app.XAxes = uiaxes(app.anTraXGraphExplorerUIFigure);
            app.YAxes = uiaxes(app.anTraXGraphExplorerUIFigure);
            app.SingleantCheckBox = uicheckbox(app.anTraXGraphExplorerUIFigure);
            app.SingleantCheckBox.ValueChangedFcn = createCallbackFcn(app, @SingleantCheckBoxValueChanged, true);
            app.SingleantCheckBox.Enable = 'off';
            app.SingleantCheckBox.Text = 'Single ant';
            app.EliminateButton = uibutton(app.anTraXGraphExplorerUIFigure, 'push');
            app.EliminateButton.ButtonPushedFcn = createCallbackFcn(app, @EliminateButtonPushed, true);
            app.EliminateButton.Enable = 'off';
            app.EliminateButton.Text = 'Eliminate';
            app.ManualfixLabel = uilabel(app.anTraXGraphExplorerUIFigure);
            app.ManualfixLabel.Text = 'Manual fix:';
            app.AddexamplesLabel = uilabel(app.anTraXGraphExplorerUIFigure);
            app.AddexamplesLabel.Text = 'Add examples:';
            % Show the figure after all components are created
            app.anTraXGraphExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graph_explorer_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.anTraXGraphExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.anTraXGraphExplorerUIFigure)
        end
    end
end