classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        anTraxUIFigure                 matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        OpenRecentMenu                 matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
        PreferencesMenu                matlab.ui.container.Menu
        QuitMenu                       matlab.ui.container.Menu
        ViewMenu                       matlab.ui.container.Menu
        FullScreenMenu                 matlab.ui.container.Menu
        ToolsMenu                      matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        DocsMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        SessionTab                     matlab.ui.container.Tab
        SessionDropDownLabel           matlab.ui.control.Label
        SessionDropDown                matlab.ui.control.DropDown
        ImportfromanexperimentButton   matlab.ui.control.Button
        ImportfromasessionButton       matlab.ui.control.Button
        BackgroundTab                  matlab.ui.container.Tab
        FrameSelectPanel               matlab.ui.container.Panel
        MovieframeSpinnerLabel         matlab.ui.control.Label
        MovieframeSpinner              matlab.ui.control.Spinner
        ExperimentframeSpinnerLabel    matlab.ui.control.Label
        ExperimentframeSpinner         matlab.ui.control.Spinner
        BGfileDropDownLabel            matlab.ui.control.Label
        BGfileDropDown                 matlab.ui.control.DropDown
        MovienumberSpinnerLabel        matlab.ui.control.Label
        ShowBGButton                   matlab.ui.control.Button
        ShowframeButton                matlab.ui.control.Button
        MovienumberSpinner             matlab.ui.control.Spinner
        Panel                          matlab.ui.container.Panel
        MethodDropDownLabel            matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
        FramesperBGSpinnerLabel        matlab.ui.control.Label
        FramesperBGSpinner             matlab.ui.control.Spinner
        CreateBGButton                 matlab.ui.control.Button
        OneBGCheckBox                  matlab.ui.control.CheckBox
        FramerangestartEditFieldLabel  matlab.ui.control.Label
        FramerangestartEditField       matlab.ui.control.NumericEditField
        FramerangeendEditFieldLabel    matlab.ui.control.Label
        FramerangeendEditField         matlab.ui.control.NumericEditField
        BGpersubdirCheckBox            matlab.ui.control.CheckBox
        ScaleTab                       matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        CircletoolButton               matlab.ui.control.Button
        LinetoolButton                 matlab.ui.control.Button
        MeasureEditFieldLabel          matlab.ui.control.Label
        MeasureEditField               matlab.ui.control.EditField
        mmLabel                        matlab.ui.control.Label
        CleartoolButton                matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        ROITab                         matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        UIAxes3                        matlab.ui.control.UIAxes
        SegmentationTab                matlab.ui.container.Tab
        LinkingTab                     matlab.ui.container.Tab
        IDsTab                         matlab.ui.container.Tab
        OtheroptionsTab                matlab.ui.container.Tab
        RunTab                         matlab.ui.container.Tab
        ValidateTab                    matlab.ui.container.Tab
    end

    
    properties (Access = public)
        Trck trhandles
        bgdir
        t
    end
    
    methods (Access = public)
        
        function cs = get_components(app, s)
        
            fn = fieldnames(app);
            fn = fn(contains(fn, s));
            fn = fn(arrayfun(@(x) isa(app.(x),'matlab.ui.control.internal.model.ComponentModel'), fn));
            cs = arrayfun(@(x) app.(x), fn);
            
        end
        
        
        function setTrck(app,Trck)
            
            % set properties
            app.Trck = Trck;
            
            app.t = trtime(Trck,1);
            
            
            % bg tab
            app.bgdir = [Trck.paramsdir,'backgrounds',filesep];
            mkdirp(app.bgdir);
            
            update_frame_selectors(app);
            update_bg_list(app);
            
            app.BGpersubdirCheckBox.Value = Trck.get_param('background_per_subdir');
            app.OneBGCheckBox.Value = ~Trck.get_param('background_per_subdir');
            app.FramesperBGSpinner.Value = Trck.get_param('background_nframes');
            app.MethodDropDown.Value = Trck.get_param('background_method');
            
            frame_range = Trck.get_param('background_frame_range');
            frame_range(2) = min(Trck.er.movies_info(end).ff,frame_range(2));
            
            app.FramerangestartEditField.Value = frame_range(1);
            app.FramerangeendEditField.Value = frame_range(2);
            
            if isempty(app.BGfileDropDown.Value)
                show_frame(app);
            else
                show_bg(app);
            end
            
            
        end
        
        function update_frame_selectors(app)
            
            movlist = [app.Trck.er.movies_info.index];
            if length(movlist)==1
                app.MovienumberSpinner.Limits = [movlist movlist+1];
                app.MovienumberSpinner.Value = movlist;
                app.MovienumberSpinner.Enable = false;
                
            else
                app.MovienumberSpinner.Limits = [min(movlist),max(movlist)];
                app.MovienumberSpinner.Value = app.t.m;
            end
            app.ExperimentframeSpinner.Limits = [app.Trck.er.movies_info(1).fi,app.Trck.er.movies_info(end).ff];
            app.ExperimentframeSpinner.Value = app.t.f;
            app.MovieframeSpinner.Limits = [1,app.Trck.er.movies_info(app.t.m).nframes];
            app.MovieframeSpinner.Value = app.t.mf;
            
        end
        
        function update_bg_list(app)
            
            % list files in bgdir
            files=dir([app.bgdir,'background*.png']);
            
            if ~isempty(files)
                app.ShowBGButton.Enable = true;
                app.BGfileDropDown.Enable = true;
                app.BGfileDropDown.Items = {files.name};
                app.BGfileDropDown.Value = app.BGfileDropDown.Items{1};
            else
                app.ShowBGButton.Enable = false;
                app.BGfileDropDown.Enable = false;
                app.BGfileDropDown.Items = {''};
                app.BGfileDropDown.Value = '';
            end
            
        end
        
        function show_frame(app)
            
            frame = app.Trck.er.read(app.t);
            imshow(frame,'Parent',app.UIAxes)
            app.UIAxes.YLabel.String = '';
            app.UIAxes.XLabel.String = '';
        end
        
        function show_bg(app)
            
            
            if isempty(app.BGfileDropDown.Value)
                return
            end
            file = [app.bgdir,app.BGfileDropDown.Value];
            imshow(file,'Parent',app.UIAxes)
            app.UIAxes.YLabel.String = '';
            app.UIAxes.XLabel.String = '';
            
            imshow(file,'Parent',app.UIAxes2)
            app.UIAxes2.YLabel.String = '';
            app.UIAxes2.XLabel.String = '';
            
        end
        
        
        function create_bg(app)
            
            
            app.Trck.set_param('background_method',app.MethodDropDown.Value);
            app.Trck.set_param('background_nframes',app.FramesperBGSpinner.Value);
            app.Trck.set_param('background_per_subdir',app.BGpersubdirCheckBox.Value);
            
            fi = app.FramerangestartEditField.Value;
            ff = app.FramerangeendEditField.Value;
            app.Trck.set_param('background_frame_range',[fi,ff]);
            
            create_backgrounds(app.Trck);
            
            update_bg_list(app);
            show_bg(app);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Trck)
            
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            
            if nargin>1
                setTrck(app,Trck);
            end
            
            
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            % show the 'about' dialog
            logoFcn = @()imread('mathworks_consulting_banner.png','BackgroundColor', [0.94 0.94 0.94]);
            logoBanner = uiw.utility.loadIcon(logoFcn);
            d = uiw.dialog.About(...
                'ShowOk',true,...
                'Name', 'anTrax',...
                'Version','0.1.7',...
                'Date', 'Mar 17, 2019',...
                'CustomText', 'This is my favorite app.',...
                'ContactInfo', 'agal@rockefeller.edu',...
                'LogoCData', logoBanner);
            
        end

        % Value changed function: MovienumberSpinner
        function MovienumberSpinnerValueChanged(app, event)
            m = app.MovienumberSpinner.Value;
            app.t = trtime(app.Trck,m,1);
            update_frame_selectors(app);
            show_frame(app);
        end

        % Value changed function: MovieframeSpinner
        function MovieframeSpinnerValueChanged(app, event)
            mf = app.MovieframeSpinner.Value;
            app.t = trtime(app.Trck,app.t.m,mf);
            update_frame_selectors(app);
            show_frame(app);
        end

        % Value changed function: ExperimentframeSpinner
        function ExperimentframeSpinnerValueChanged(app, event)
            f = app.ExperimentframeSpinner.Value;
            app.t = trtime(app.Trck,f);
            update_frame_selectors(app);
            show_frame(app);
        end

        % Button pushed function: ShowframeButton
        function ShowframeButtonPushed(app, event)
            show_frame(app)
        end

        % Callback function: BGfileDropDown, ShowBGButton
        function ShowBGButtonPushed(app, event)
            show_bg(app);
        end

        % Button pushed function: CreateBGButton
        function CreateBGButtonPushed(app, event)
            create_bg(app);
        end

        % Value changed function: OneBGCheckBox
        function OneBGCheckBoxValueChanged(app, event)
            value = app.OneBGCheckBox.Value;
            
            if value
                app.Trck.set_param('background_per_subdir',false);
                app.Trck.set_param('background_kind','experiment');
                app.BGpersubdirCheckBox.Value = false;
                app.FramerangeendEditField.Enable = true;
                app.FramerangestartEditField.Enable = true;
            else
                app.Trck.set_param('background_per_subdir',true);
                app.Trck.set_param('background_kind','subdir')
                app.BGpersubdirCheckBox.Value = true;
                app.FramerangeendEditField.Enable = false;
                app.FramerangestartEditField.Enable = false;
            end
            
        end

        % Value changed function: BGpersubdirCheckBox
        function BGpersubdirCheckBoxValueChanged(app, event)
            value = app.BGpersubdirCheckBox.Value;
            
            if value
                app.Trck.set_param('background_per_subdir',true);
                app.Trck.set_param('background_kind','subdir')
                app.OneBGCheckBox.Value = false;
                app.FramerangeendEditField.Enable = false;
                app.FramerangestartEditField.Enable = false;
            else
                app.Trck.set_param('background_per_subdir',false);
                app.Trck.set_param('background_kind','experiment')
                app.OneBGCheckBox.Value = true;
                app.FramerangeendEditField.Enable = true;
                app.FramerangestartEditField.Enable = true;
            end
            
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % open dialog to select expdir


        end

        % Button pushed function: CircletoolButton
        function CircletoolButtonPushed(app, event)
            drawellipse(app.UIAxes2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.anTraxUIFigure = uifigure('Visible', 'off');
            app.anTraxUIFigure.Name = 'anTrax';
            app.FileMenu = uimenu(app.anTraxUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Tooltip = {'Load an experiment to track'};
            app.OpenMenu.Text = 'Open';
            app.OpenRecentMenu = uimenu(app.FileMenu);
            app.OpenRecentMenu.Text = 'Open Recent';
            app.Menu = uimenu(app.OpenRecentMenu);
            app.Menu.Text = 'Menu';
            app.PreferencesMenu = uimenu(app.FileMenu);
            app.PreferencesMenu.Text = 'Preferences';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.Separator = 'on';
            app.QuitMenu.Text = 'Quit';
            app.ViewMenu = uimenu(app.anTraxUIFigure);
            app.ViewMenu.Enable = 'off';
            app.ViewMenu.Text = 'View';
            app.FullScreenMenu = uimenu(app.ViewMenu);
            app.FullScreenMenu.Text = 'Full Screen';
            app.ToolsMenu = uimenu(app.anTraxUIFigure);
            app.ToolsMenu.Enable = 'off';
            app.ToolsMenu.Text = 'Tools';
            app.HelpMenu = uimenu(app.anTraxUIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocsMenu = uimenu(app.HelpMenu);
            app.DocsMenu.Text = 'Docs';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.TabGroup = uitabgroup(app.anTraxUIFigure);
            app.SessionTab = uitab(app.TabGroup);
            app.SessionTab.Title = 'Session';
            app.SessionDropDownLabel = uilabel(app.SessionTab);
            app.SessionDropDownLabel.Text = 'Session:';
            app.SessionDropDown = uidropdown(app.SessionTab);
            app.SessionDropDown.Editable = 'on';
            app.SessionDropDown.Tooltip = {'Load an existing tracking session in the experiment'; ' or create a new one'};
            app.ImportfromanexperimentButton = uibutton(app.SessionTab, 'push');
            app.ImportfromanexperimentButton.Tooltip = {'Import parameters from another experiment'};
            app.ImportfromanexperimentButton.Text = 'Import from an experiment';
            app.ImportfromasessionButton = uibutton(app.SessionTab, 'push');
            app.ImportfromasessionButton.Tooltip = {'Import parameters from another session in this experiment'};
            app.ImportfromasessionButton.Text = 'Import from a session';
            app.BackgroundTab = uitab(app.TabGroup);
            app.BackgroundTab.Title = 'Background';
            app.FrameSelectPanel = uipanel(app.BackgroundTab);
            app.MovieframeSpinnerLabel = uilabel(app.FrameSelectPanel);
            app.MovieframeSpinnerLabel.Text = 'Movie frame';
            app.MovieframeSpinner = uispinner(app.FrameSelectPanel);
            app.MovieframeSpinner.ValueChangedFcn = createCallbackFcn(app, @MovieframeSpinnerValueChanged, true);
            app.ExperimentframeSpinnerLabel = uilabel(app.FrameSelectPanel);
            app.ExperimentframeSpinnerLabel.Text = 'Experiment frame';
            app.ExperimentframeSpinner = uispinner(app.FrameSelectPanel);
            app.ExperimentframeSpinner.ValueChangedFcn = createCallbackFcn(app, @ExperimentframeSpinnerValueChanged, true);
            app.BGfileDropDownLabel = uilabel(app.FrameSelectPanel);
            app.BGfileDropDownLabel.Text = 'BG file';
            app.BGfileDropDown = uidropdown(app.FrameSelectPanel);
            app.BGfileDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowBGButtonPushed, true);
            app.MovienumberSpinnerLabel = uilabel(app.FrameSelectPanel);
            app.MovienumberSpinnerLabel.Text = 'Movie number';
            app.ShowBGButton = uibutton(app.FrameSelectPanel, 'push');
            app.ShowBGButton.ButtonPushedFcn = createCallbackFcn(app, @ShowBGButtonPushed, true);
            app.ShowBGButton.Text = 'Show BG';
            app.ShowframeButton = uibutton(app.FrameSelectPanel, 'push');
            app.ShowframeButton.ButtonPushedFcn = createCallbackFcn(app, @ShowframeButtonPushed, true);
            app.ShowframeButton.Text = 'Show frame';
            app.MovienumberSpinner = uispinner(app.FrameSelectPanel);
            app.MovienumberSpinner.ValueChangedFcn = createCallbackFcn(app, @MovienumberSpinnerValueChanged, true);
            app.Panel = uipanel(app.BackgroundTab);
            app.Panel.Tooltip = {'Select the last frame in the frame range for background creation'};
            app.MethodDropDownLabel = uilabel(app.Panel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.Panel);
            app.MethodDropDown.Items = {'median', 'max'};
            app.MethodDropDown.Tooltip = {'Select method for generating background'};
            app.MethodDropDown.Value = 'median';
            app.FramesperBGSpinnerLabel = uilabel(app.Panel);
            app.FramesperBGSpinnerLabel.Text = 'Frames per BG';
            app.FramesperBGSpinner = uispinner(app.Panel);
            app.FramesperBGSpinner.Limits = [1 Inf];
            app.FramesperBGSpinner.Tooltip = {'Select how many frames should be used for background creation'};
            app.FramesperBGSpinner.Value = 20;
            app.CreateBGButton = uibutton(app.Panel, 'push');
            app.CreateBGButton.ButtonPushedFcn = createCallbackFcn(app, @CreateBGButtonPushed, true);
            app.CreateBGButton.Tooltip = {'Click to create background'};
            app.CreateBGButton.Text = 'Create BG';
            app.OneBGCheckBox = uicheckbox(app.Panel);
            app.OneBGCheckBox.ValueChangedFcn = createCallbackFcn(app, @OneBGCheckBoxValueChanged, true);
            app.OneBGCheckBox.Tooltip = {'Check to create one global background '};
            app.OneBGCheckBox.Text = 'One BG';
            app.OneBGCheckBox.Value = true;
            app.FramerangestartEditFieldLabel = uilabel(app.Panel);
            app.FramerangestartEditFieldLabel.Text = 'Frame range start';
            app.FramerangestartEditField = uieditfield(app.Panel, 'numeric');
            app.FramerangestartEditField.ValueDisplayFormat = '%.0f';
            app.FramerangestartEditField.Tooltip = {'Select the first frame in the range of frames for background creation'};
            app.FramerangeendEditFieldLabel = uilabel(app.Panel);
            app.FramerangeendEditFieldLabel.Text = 'Frame range end';
            app.FramerangeendEditField = uieditfield(app.Panel, 'numeric');
            app.FramerangeendEditField.ValueDisplayFormat = '%.0f';
            app.BGpersubdirCheckBox = uicheckbox(app.Panel);
            app.BGpersubdirCheckBox.ValueChangedFcn = createCallbackFcn(app, @BGpersubdirCheckBoxValueChanged, true);
            app.BGpersubdirCheckBox.Tooltip = {'Check to create a background for each video subdirectory'};
            app.BGpersubdirCheckBox.Text = 'BG per subdir';
            app.ScaleTab = uitab(app.TabGroup);
            app.ScaleTab.Title = 'Scale';
            app.UIAxes2 = uiaxes(app.ScaleTab);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.CircletoolButton = uibutton(app.ScaleTab, 'push');
            app.CircletoolButton.ButtonPushedFcn = createCallbackFcn(app, @CircletoolButtonPushed, true);
            app.CircletoolButton.Text = 'Circle tool';
            app.LinetoolButton = uibutton(app.ScaleTab, 'push');
            app.LinetoolButton.Text = 'Line tool';
            app.MeasureEditFieldLabel = uilabel(app.ScaleTab);
            app.MeasureEditFieldLabel.Text = 'Measure:';
            app.MeasureEditField = uieditfield(app.ScaleTab, 'text');
            app.mmLabel = uilabel(app.ScaleTab);
            app.mmLabel.Text = 'mm';
            app.CleartoolButton = uibutton(app.ScaleTab, 'push');
            app.CleartoolButton.Text = 'Clear tool';
            app.Label = uilabel(app.ScaleTab);
            app.ROITab = uitab(app.TabGroup);
            app.ROITab.Title = 'ROI';
            app.GridLayout = uigridlayout(app.ROITab);
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.LinkingTab = uitab(app.TabGroup);
            app.LinkingTab.Title = 'Linking';
            app.IDsTab = uitab(app.TabGroup);
            app.IDsTab.Title = 'IDs';
            app.OtheroptionsTab = uitab(app.TabGroup);
            app.OtheroptionsTab.Title = 'Other options';
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.ValidateTab = uitab(app.TabGroup);
            app.ValidateTab.Title = 'Validate';
            % Show the figure after all components are created
            app.anTraxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.anTraxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.anTraxUIFigure)
        end
    end
end