classdef segmentation_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CATTTuneSegmentationUIFigure    matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        MovienumberSpinnerLabel         matlab.ui.control.Label
        MovienumberSpinner              matlab.ui.control.Spinner
        MovieframeSpinnerLabel          matlab.ui.control.Label
        MovieframeSpinner               matlab.ui.control.Spinner
        ExperimentframeSpinnerLabel     matlab.ui.control.Label
        ExperimentframeSpinner          matlab.ui.control.Spinner
        SingleantfiltersPanel           matlab.ui.container.Panel
        MinsizeSpinnerLabel             matlab.ui.control.Label
        SingleMinSpinner                matlab.ui.control.Spinner
        MaxsizeSpinnerLabel             matlab.ui.control.Label
        SingleMaxSpinner                matlab.ui.control.Spinner
        UnitsLabel                      matlab.ui.control.Label
        UnitsLabel2                     matlab.ui.control.Label
        PixelsCheckBox                  matlab.ui.control.CheckBox
        SICheckBox                      matlab.ui.control.CheckBox
        SegmentationparametersPanel     matlab.ui.container.Panel
        IlluminationcorrectionCheckBox  matlab.ui.control.CheckBox
        ThresholdCheckBox               matlab.ui.control.CheckBox
        ThresholdSpinner                matlab.ui.control.Spinner
        MinblobareaCheckBox             matlab.ui.control.CheckBox
        MinAreaSpinner                  matlab.ui.control.Spinner
        ClosingCheckBox                 matlab.ui.control.CheckBox
        ClosingSpinner                  matlab.ui.control.Spinner
        OpeningCheckBox                 matlab.ui.control.CheckBox
        OpenningSpinner                 matlab.ui.control.Spinner
        MinintensityCheckBox            matlab.ui.control.CheckBox
        IntensitySpinner                matlab.ui.control.Spinner
        UseconvexhullCheckBox           matlab.ui.control.CheckBox
        FillholesCheckBox               matlab.ui.control.CheckBox
        UsemaxrgbforsegmentationCheckBox  matlab.ui.control.CheckBox
        LocalzscalingCheckBox           matlab.ui.control.CheckBox
        ViewoptionsPanel                matlab.ui.container.Panel
        ShowROICheckBox                 matlab.ui.control.CheckBox
        ShowsegmentedimageCheckBox      matlab.ui.control.CheckBox
        ShowblobmeasuresCheckBox        matlab.ui.control.CheckBox
        ShowgrayimageCheckBox           matlab.ui.control.CheckBox
        ZoomresetButton                 matlab.ui.control.Button
        ZoomButton                      matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        Trck trhandles 
        bgdir 
        t
        chplot
        BlobText
    end
    
    methods (Access = private)
        
        function update_frame_selectors(app)
            
            movlist = [app.Trck.er.movies_info.index];
            if length(movlist)==1
                app.MovienumberSpinner.Limits = [movlist movlist+1];
                app.MovienumberSpinner.Value = movlist; 
                app.MovienumberSpinner.Enable = false;
                
            else
                app.MovienumberSpinner.Limits = [min(movlist),max(movlist)];
                app.MovienumberSpinner.Value = app.t.m;
            end
            app.ExperimentframeSpinner.Limits = [app.Trck.er.movies_info(1).fi,app.Trck.er.movies_info(end).ff];
            app.ExperimentframeSpinner.Value = app.t.f;
            app.MovieframeSpinner.Limits = [1,app.Trck.er.movies_info(app.t.m).nframes];
            app.MovieframeSpinner.Value = app.t.mf;
            
        end
    
        function get_params(app)
            
            app.UsemaxrgbforsegmentationCheckBox.Value = app.Trck.get_param('segmentation_use_max_rgb');
            app.LocalzscalingCheckBox.Value = app.Trck.get_param('segmentation_local_z_scaling');
            app.ThresholdCheckBox.Value     = true;
            app.IlluminationcorrectionCheckBox.Value = false; %app.Trck.get_param('segmentation_illum_correct');
            app.ThresholdSpinner.Value      = app.Trck.get_param('segmentation_threshold');
            app.MinblobareaCheckBox.Value   = app.Trck.get_param('segmentation_MinimumBlobArea')>0;
            app.MinAreaSpinner.Value        = app.Trck.get_param('segmentation_MinimumBlobArea');
            app.ClosingCheckBox.Value       = app.Trck.get_param('segmentation_ImClosing');
            app.ClosingSpinner.Value        = app.Trck.get_param('segmentation_ImClosingSize');
            app.OpeningCheckBox.Value       = app.Trck.get_param('segmentation_ImOpenning');
            app.OpenningSpinner.Value       = app.Trck.get_param('segmentation_ImOpenningSize');
            app.MinintensityCheckBox.Value  = app.Trck.get_param('segmentation_IntensityFilter');
            app.IntensitySpinner.Value      = app.Trck.get_param('segmentation_MaxIntensityThreshold');
            app.UseconvexhullCheckBox.Value = app.Trck.get_param('segmentation_useConvexHull');
            app.FillholesCheckBox.Value     = app.Trck.get_param('segmentation_fillHoles');
            app.IlluminationcorrectionCheckBox.Value = app.Trck.get_param('segmentation_illum_correct');        
            
            update_single_ant_filters(app)
            
            app.ThresholdSpinner.Enable = app.ThresholdCheckBox.Value;
            app.MinAreaSpinner.Enable = app.MinblobareaCheckBox.Value;
            app.ClosingSpinner.Enable = app.ClosingCheckBox.Value;
            app.OpenningSpinner.Enable = app.OpeningCheckBox.Value;
            app.IntensitySpinner.Enable = app.MinintensityCheckBox.Value;
            
        end
    
        function set_params(app)
            
            app.Trck.set_param('segmentation_use_max_rgb',app.UsemaxrgbforsegmentationCheckBox.Value);
            app.Trck.set_param('segmentation_illum_correct',app.IlluminationcorrectionCheckBox.Value);
            app.Trck.set_param('segmentation_local_z_scaling',app.LocalzscalingCheckBox.Value);
            app.Trck.set_param('segmentation_threshold',app.ThresholdSpinner.Value);
            app.Trck.set_param('segmentation_MinimumBlobArea',app.MinAreaSpinner.Value);
            app.Trck.set_param('segmentation_ImClosing',app.ClosingCheckBox.Value);
            app.Trck.set_param('segmentation_ImClosingSize',app.ClosingSpinner.Value);
            app.Trck.set_param('segmentation_ImOpenning',app.OpeningCheckBox.Value);
            app.Trck.set_param('segmentation_ImOpenningSize',app.OpenningSpinner.Value);
            app.Trck.set_param('segmentation_IntensityFilter',app.MinintensityCheckBox.Value);
            app.Trck.set_param('segmentation_MaxIntensityThreshold',app.IntensitySpinner.Value);
            app.Trck.set_param('segmentation_useConvexHull',app.UseconvexhullCheckBox.Value);
            app.Trck.set_param('segmentation_fillHoles',app.FillholesCheckBox.Value);
            
            if app.SICheckBox.Value
                app.Trck.set_param('thrsh_meanareamin',app.SingleMinSpinner.Value * 1e-6);
                app.Trck.set_param('thrsh_meanareamax',app.SingleMaxSpinner.Value * 1e-6);
            else
                scale2 = app.Trck.get_param('geometry_rscale')^2;
                app.Trck.set_param('thrsh_meanareamin',app.SingleMinSpinner.Value * scale2);
                app.Trck.set_param('thrsh_meanareamax',app.SingleMaxSpinner.Value * scale2);
            end
             
            app.Trck.set_param('segmentation_SplitDoubleAntBlobs',false);
            
            app.Trck.set_param('segmentation_ImClosingStrel',strel('disk',app.ClosingSpinner.Value));
            app.Trck.set_param('segmentation_ImOpenningStrel',strel('disk',app.OpenningSpinner.Value));
            
            app.ThresholdSpinner.Enable = app.ThresholdCheckBox.Value;
            app.MinAreaSpinner.Enable = app.MinblobareaCheckBox.Value;
            app.ClosingSpinner.Enable = app.ClosingCheckBox.Value;
            app.OpenningSpinner.Enable = app.OpeningCheckBox.Value;
            app.IntensitySpinner.Enable = app.MinintensityCheckBox.Value;
            
            
        end
    
        function update_single_ant_filters(app)
            
            meanareamin = app.Trck.get_param('thrsh_meanareamin');
            meanareamax = app.Trck.get_param('thrsh_meanareamax');
            
            scale = app.Trck.get_param('geometry_rscale');
            scale2 = scale^2;
            
            if app.SICheckBox.Value
                
                app.SingleMinSpinner.Step = 0.1;
                app.SingleMaxSpinner.Step = 0.1;
                app.SingleMinSpinner.Value = round(10*meanareamin*1e6)/10;
                app.SingleMaxSpinner.Value = round(10*meanareamax*1e6)/10;
                
                app.UnitsLabel.Text = 'sq mm';
                app.UnitsLabel2.Text = 'sq mm';
                
            else
                
                app.SingleMinSpinner.Step = 1;
                app.SingleMaxSpinner.Step = 1;
                app.SingleMinSpinner.Value = round(meanareamin/scale2);
                app.SingleMaxSpinner.Value = round(meanareamax/scale2);
                
                app.UnitsLabel.Text = 'pixels';
                app.UnitsLabel2.Text = 'pixels';
                
            end
            
            
        end

        function show_frame(app)
                        
            
            app.Trck.read_frame(app.t);
            frame = app.Trck.currfrm.CData;
            detect_blobs(app.Trck);
            ab = app.Trck.currfrm.antblob;
            S = regionprops(ab.LABEL,app.Trck.currfrm.grayIm,'ConvexHull');


            big = ab.rarea > app.Trck.get_param('thrsh_meanareamax');
            sml = ab.rarea < app.Trck.get_param('thrsh_meanareamin');
            
            if app.ShowgrayimageCheckBox.Value
                frame = repmat(app.Trck.currfrm.grayIm,[1,1,3]);
            end
           
            if app.IlluminationcorrectionCheckBox.Value
                
                [~,~,BGW] = app.Trck.get_bg(app.t.m);
                L = get_param(app.Trck,'segmentation_illum_correct_level');
                frame = im2uint8(L*im2single(frame)./BGW);
                
            end
            
            for i=1:length(S)
                if big(i)
                    clr(i,:) = rgb('Red');
                elseif sml(i)
                    clr(i,:) = rgb('Magenta');
                else
                    clr(i,:) = rgb('LimeGreen');
                end
            end
            
            delete(app.chplot);
            delete(app.BlobText);
            app.chplot = [];
            app.BlobText = [];
            
            if app.ShowsegmentedimageCheckBox.Value
                frame = imadd(frame,label2rgb(ab.LABEL,'jet','k'));
            end
            
            if app.ShowROICheckBox.Value
                frame = imdivide(frame,3*(1-app.Trck.TrackingMask)+app.Trck.TrackingMask);
            end

            imshow(frame,'Parent',app.UIAxes)
            hold(app.UIAxes,'on')
            
            if ~app.ShowsegmentedimageCheckBox.Value
                for i=1:length(S)
                    app.chplot(i) = plot(app.UIAxes,S(i).ConvexHull(:,1),S(i).ConvexHull(:,2),'Color',clr(i,:),'LineWidth',2);
                end
            end
            
            if app.ShowblobmeasuresCheckBox.Value
                for i=1:length(S)
                    s = [num2str(ab.DATA.AREA(i)),' (',num2str(ab.DATA.MAXZ(i)),')'];
                    app.BlobText(i) = text(app.UIAxes,ab.DATA.CENTROID(i,1),ab.DATA.CENTROID(i,2),s,'Color',clr(i,:));
                end
            end
            
            
            app.UIAxes.YLabel.String = '';
            app.UIAxes.XLabel.String = '';
            
        end
    
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Trck)
            
            if nargin<2
                return
            end
            
            app.Trck = Trck;

            app.t = trtime(Trck,1);
            update_frame_selectors(app);
            get_params(app);
            show_frame(app);
            pan(app.UIAxes,'off');
            zoom(app.UIAxes,'off');            
        end

        % Value changed function: MovienumberSpinner
        function MovienumberSpinnerValueChanged(app, event)
            m = app.MovienumberSpinner.Value;
            app.t = trtime(app.Trck,m,1);
            update_frame_selectors(app);
            show_frame(app);
        end

        % Value changed function: MovieframeSpinner
        function MovieframeSpinnerValueChanged(app, event)
            mf = app.MovieframeSpinner.Value;
            app.t = trtime(app.Trck,app.t.m,mf);
            update_frame_selectors(app);
            show_frame(app);
        end

        % Value changed function: ExperimentframeSpinner
        function ExperimentframeSpinnerValueChanged(app, event)
            f = app.ExperimentframeSpinner.Value;
            app.t = trtime(app.Trck,f);
            update_frame_selectors(app);
            show_frame(app);
        end

        % Value changed function: ClosingCheckBox, ClosingSpinner, 
        % FillholesCheckBox, IlluminationcorrectionCheckBox, 
        % IntensitySpinner, LocalzscalingCheckBox, MinAreaSpinner, 
        % MinblobareaCheckBox, MinintensityCheckBox, OpeningCheckBox, 
        % OpenningSpinner, SingleMaxSpinner, SingleMinSpinner, 
        % ThresholdCheckBox, ThresholdSpinner, UseconvexhullCheckBox, 
        % UsemaxrgbforsegmentationCheckBox
        function ParameterChanged(app, event)
            
            set_params(app);
            show_frame(app);
            
        end

        % Value changed function: ShowROICheckBox, 
        % ShowblobmeasuresCheckBox, ShowgrayimageCheckBox, 
        % ShowsegmentedimageCheckBox
        function ViewOptionsChanged(app, event)
            show_frame(app);            
        end

        % Value changed function: ZoomButton
        function ZoomButtonValueChanged(app, event)
            value = app.ZoomButton.Value;
            if value
                pan(app.UIAxes,'on');
                zoom(app.UIAxes,'on');
            else
                pan(app.UIAxes,'off');
                zoom(app.UIAxes,'off');
            end
        end

        % Button pushed function: ZoomresetButton
        function ZoomresetButtonPushed(app, event)
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
        end

        % Value changed function: PixelsCheckBox
        function PixelsCheckBoxValueChanged(app, event)
            value = app.PixelsCheckBox.Value;
            
            if value
                app.SICheckBox.Value = false;
            else
                app.SICheckBox.Value = true;
            end
                
            update_single_ant_filters(app);
            
        end

        % Value changed function: SICheckBox
        function SICheckBoxValueChanged(app, event)
            value = app.SICheckBox.Value;
            
            if ~value
                app.PixelsCheckBox.Value = true;
            else
                app.PixelsCheckBox.Value = false;
            end
            
            update_single_ant_filters(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CATTTuneSegmentationUIFigure = uifigure;
            app.CATTTuneSegmentationUIFigure.Name = 'CATT: Tune Segmentation';
            app.UIAxes = uiaxes(app.CATTTuneSegmentationUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MovienumberSpinnerLabel = uilabel(app.CATTTuneSegmentationUIFigure);
            app.MovienumberSpinnerLabel.Text = 'Movie number';
            app.MovienumberSpinner = uispinner(app.CATTTuneSegmentationUIFigure);
            app.MovienumberSpinner.ValueChangedFcn = createCallbackFcn(app, @MovienumberSpinnerValueChanged, true);
            app.MovieframeSpinnerLabel = uilabel(app.CATTTuneSegmentationUIFigure);
            app.MovieframeSpinnerLabel.Text = 'Movie frame';
            app.MovieframeSpinner = uispinner(app.CATTTuneSegmentationUIFigure);
            app.MovieframeSpinner.ValueChangedFcn = createCallbackFcn(app, @MovieframeSpinnerValueChanged, true);
            app.ExperimentframeSpinnerLabel = uilabel(app.CATTTuneSegmentationUIFigure);
            app.ExperimentframeSpinnerLabel.Text = 'Experiment frame';
            app.ExperimentframeSpinner = uispinner(app.CATTTuneSegmentationUIFigure);
            app.ExperimentframeSpinner.ValueChangedFcn = createCallbackFcn(app, @ExperimentframeSpinnerValueChanged, true);
            app.SingleantfiltersPanel = uipanel(app.CATTTuneSegmentationUIFigure);
            app.SingleantfiltersPanel.Title = 'Single ant filters';
            app.MinsizeSpinnerLabel = uilabel(app.SingleantfiltersPanel);
            app.MinsizeSpinnerLabel.Text = 'Min size';
            app.SingleMinSpinner = uispinner(app.SingleantfiltersPanel);
            app.SingleMinSpinner.Step = 0.1;
            app.SingleMinSpinner.Limits = [0 Inf];
            app.SingleMinSpinner.ValueChangedFcn = createCallbackFcn(app, @ParameterChanged, true);
            app.MaxsizeSpinnerLabel = uilabel(app.SingleantfiltersPanel);
            app.MaxsizeSpinnerLabel.Text = 'Max size';
            app.SingleMaxSpinner = uispinner(app.SingleantfiltersPanel);
            app.SingleMaxSpinner.Step = 0.1;
            app.SingleMaxSpinner.Limits = [0 Inf];
            app.SingleMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @ParameterChanged, true);
            app.UnitsLabel = uilabel(app.SingleantfiltersPanel);
            app.UnitsLabel.Text = 'sq mm';
            app.UnitsLabel2 = uilabel(app.SingleantfiltersPanel);
            app.UnitsLabel2.Text = 'sq mm';
            app.PixelsCheckBox = uicheckbox(app.SingleantfiltersPanel);
            app.PixelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PixelsCheckBoxValueChanged, true);
            app.PixelsCheckBox.Text = 'Pixels';
            app.SICheckBox = uicheckbox(app.SingleantfiltersPanel);
            app.SICheckBox.ValueChangedFcn = createCallbackFcn(app, @SICheckBoxValueChanged, true);
            app.SICheckBox.Text = 'SI';
            app.SICheckBox.Value = true;
            app.SegmentationparametersPanel = uipanel(app.CATTTuneSegmentationUIFigure);
            app.SegmentationparametersPanel.Title = 'Segmentation parameters';
            app.IlluminationcorrectionCheckBox = uicheckbox(app.SegmentationparametersPanel);
            app.IlluminationcorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParameterChanged, true);
            app.IlluminationcorrectionCheckBox.Enable = 'off';
            app.IlluminationcorrectionCheckBox.Text = 'Illumination correction';
            app.ThresholdCheckBox = uicheckbox(app.SegmentationparametersPanel);
            app.ThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParameterChanged, true);
            app.ThresholdCheckBox.Enable = 'off';
            app.ThresholdCheckBox.Text = 'Threshold';
            app.ThresholdCheckBox.Value = true;
            app.ThresholdSpinner = uispinner(app.SegmentationparametersPanel);
            app.ThresholdSpinner.Step = 0.01;
            app.ThresholdSpinner.Limits = [0 1];
            app.ThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @ParameterChanged, true);
            app.ThresholdSpinner.Value = 0.14;
            app.MinblobareaCheckBox = uicheckbox(app.SegmentationparametersPanel);
            app.MinblobareaCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParameterChanged, true);
            app.MinblobareaCheckBox.Text = 'Min blob area';
            app.MinAreaSpinner = uispinner(app.SegmentationparametersPanel);
            app.MinAreaSpinner.Limits = [0 Inf];
            app.MinAreaSpinner.ValueChangedFcn = createCallbackFcn(app, @ParameterChanged, true);
            app.MinAreaSpinner.Value = 100;
            app.ClosingCheckBox = uicheckbox(app.SegmentationparametersPanel);
            app.ClosingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParameterChanged, true);
            app.ClosingCheckBox.Text = 'Closing';
            app.ClosingSpinner = uispinner(app.SegmentationparametersPanel);
            app.ClosingSpinner.Limits = [1 Inf];
            app.ClosingSpinner.ValueChangedFcn = createCallbackFcn(app, @ParameterChanged, true);
            app.ClosingSpinner.Value = 2;
            app.OpeningCheckBox = uicheckbox(app.SegmentationparametersPanel);
            app.OpeningCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParameterChanged, true);
            app.OpeningCheckBox.Text = 'Opening';
            app.OpenningSpinner = uispinner(app.SegmentationparametersPanel);
            app.OpenningSpinner.Limits = [1 Inf];
            app.OpenningSpinner.ValueChangedFcn = createCallbackFcn(app, @ParameterChanged, true);
            app.OpenningSpinner.Value = 1;
            app.MinintensityCheckBox = uicheckbox(app.SegmentationparametersPanel);
            app.MinintensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParameterChanged, true);
            app.MinintensityCheckBox.Text = 'Min intensity';
            app.IntensitySpinner = uispinner(app.SegmentationparametersPanel);
            app.IntensitySpinner.Step = 0.01;
            app.IntensitySpinner.Limits = [0 1];
            app.IntensitySpinner.ValueChangedFcn = createCallbackFcn(app, @ParameterChanged, true);
            app.UseconvexhullCheckBox = uicheckbox(app.SegmentationparametersPanel);
            app.UseconvexhullCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParameterChanged, true);
            app.UseconvexhullCheckBox.Text = 'Use convex hull';
            app.FillholesCheckBox = uicheckbox(app.SegmentationparametersPanel);
            app.FillholesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParameterChanged, true);
            app.FillholesCheckBox.Text = 'Fill holes';
            app.UsemaxrgbforsegmentationCheckBox = uicheckbox(app.SegmentationparametersPanel);
            app.UsemaxrgbforsegmentationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParameterChanged, true);
            app.UsemaxrgbforsegmentationCheckBox.Enable = 'off';
            app.UsemaxrgbforsegmentationCheckBox.Text = 'Use max(rgb) for segmentation';
            app.LocalzscalingCheckBox = uicheckbox(app.SegmentationparametersPanel);
            app.LocalzscalingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParameterChanged, true);
            app.LocalzscalingCheckBox.Text = 'Local z scaling';
            app.ViewoptionsPanel = uipanel(app.CATTTuneSegmentationUIFigure);
            app.ViewoptionsPanel.Title = 'View options';
            app.ShowROICheckBox = uicheckbox(app.ViewoptionsPanel);
            app.ShowROICheckBox.ValueChangedFcn = createCallbackFcn(app, @ViewOptionsChanged, true);
            app.ShowROICheckBox.Text = 'Show ROI';
            app.ShowsegmentedimageCheckBox = uicheckbox(app.ViewoptionsPanel);
            app.ShowsegmentedimageCheckBox.ValueChangedFcn = createCallbackFcn(app, @ViewOptionsChanged, true);
            app.ShowsegmentedimageCheckBox.Text = 'Show segmented image';
            app.ShowblobmeasuresCheckBox = uicheckbox(app.ViewoptionsPanel);
            app.ShowblobmeasuresCheckBox.ValueChangedFcn = createCallbackFcn(app, @ViewOptionsChanged, true);
            app.ShowblobmeasuresCheckBox.Text = 'Show blob measures';
            app.ShowgrayimageCheckBox = uicheckbox(app.ViewoptionsPanel);
            app.ShowgrayimageCheckBox.ValueChangedFcn = createCallbackFcn(app, @ViewOptionsChanged, true);
            app.ShowgrayimageCheckBox.Text = 'Show gray image';
            app.ZoomresetButton = uibutton(app.CATTTuneSegmentationUIFigure, 'push');
            app.ZoomresetButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomresetButtonPushed, true);
            app.ZoomresetButton.Text = 'Zoom reset';
            app.ZoomButton = uibutton(app.CATTTuneSegmentationUIFigure, 'state');
            app.ZoomButton.ValueChangedFcn = createCallbackFcn(app, @ZoomButtonValueChanged, true);
            app.ZoomButton.Text = 'Zoom';
        end
    end

    methods (Access = public)

        % Construct app
        function app = segmentation_app(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CATTTuneSegmentationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CATTTuneSegmentationUIFigure)
        end
    end
end