classdef set_colors_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CATTColorsandLabelsUIFigure  matlab.ui.Figure
        BCheckBox                    matlab.ui.control.CheckBox
        GCheckBox                    matlab.ui.control.CheckBox
        OCheckBox                    matlab.ui.control.CheckBox
        LCheckBox                    matlab.ui.control.CheckBox
        PCheckBox                    matlab.ui.control.CheckBox
        RCheckBox                    matlab.ui.control.CheckBox
        YCheckBox                    matlab.ui.control.CheckBox
        DoneButton                   matlab.ui.control.Button
        AntLabelsText                matlab.ui.control.TextArea
        AntlabelsLabel               matlab.ui.control.Label
        NonAntLabelsText             matlab.ui.control.TextArea
        NonAntlabelsLabel            matlab.ui.control.Label
        OtherLabelsText              matlab.ui.control.TextArea
        OtherlabelsLabel             matlab.ui.control.Label
        WCheckBox                    matlab.ui.control.CheckBox
        KCheckBox                    matlab.ui.control.CheckBox
    end


    properties (Access = public)
        PossColors = {'B','G','K','O','L','P','R','W','Y'};
        PossIDs
        Trck
        colors
        antlabels
        nonantlabels
        otherlabels
    end

    methods (Access = public)
    
        function setTrck(app,Trck)
                                    
            app.Trck = Trck;
            app.colors = Trck.labels.tagcolors;
            app.antlabels = Trck.labels.ant_labels;
            app.nonantlabels = Trck.labels.nonant_labels;
            app.otherlabels = Trck.labels.other_labels;
            
            if ~ismember('Unknown',torow(app.otherlabels))
                app.otherlabels{end+1} = 'Unknown';
            end
            
            if ~ismember('NoAnt',torow(app.nonantlabels))
                app.nonantlabels{end+1} = 'NoAnt';
            end
            
            app.BCheckBox.Value = ismember('B',app.colors);
            app.GCheckBox.Value = ismember('G',app.colors);
            app.OCheckBox.Value = ismember('O',app.colors);
            app.LCheckBox.Value = ismember('L',app.colors);
            app.PCheckBox.Value = ismember('P',app.colors);
            app.RCheckBox.Value = ismember('R',app.colors);
            app.YCheckBox.Value = ismember('Y',app.colors);
            app.WCheckBox.Value = ismember('W',app.colors);
            app.KCheckBox.Value = ismember('K',app.colors);
            
            app.PossIDs = all_possible_ids(app.colors);
          
            update_text(app);


        end
    
    
        function update_text(app)
            
            if isempty(app.antlabels)
                app.AntLabelsText.Value = '';
            else
                app.AntLabelsText.Value = tocol(app.antlabels);
            end
            
            if isempty(app.nonantlabels)
                app.NonAntLabelsText.Value = '';
            else
                app.NonAntLabelsText.Value = tocol(app.nonantlabels);
            end
            
            if isempty(app.otherlabels)
                app.OtherLabelsText.Value = '';
            else
                app.OtherLabelsText.Value = tocol(app.otherlabels);
            end
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Trck)
            if nargin>1
                setTrck(app,Trck);
            end
               
        end

        % Value changed function: BCheckBox, GCheckBox, LCheckBox, 
        % OCheckBox, PCheckBox, RCheckBox, YCheckBox
        function ColorsChanged(app, event)
            prev_colors = app.colors;
            new_colors = {};
            for i=1:length(app.PossColors)
                c = app.PossColors{i};
                v = eval(['app.',c,'CheckBox.Value;']);
                if v
                    new_colors{end+1} = c;
                end
            end
            
            removed = setdiff(prev_colors,new_colors);
            added = setdiff(new_colors,prev_colors);
            
            for i=1:length(removed)
                c = removed{i};
                app.antlabels = app.antlabels(~contains(app.antlabels,c));
            end
            
            for i=1:length(added)
                c = added{i};
                for j=1:length(new_colors)
                    app.antlabels{end+1} = [new_colors{j},c];
                    app.antlabels{end+1} = [c,new_colors{j}];
                end
            end

            app.PossIDs = all_possible_ids(new_colors);
            
            app.antlabels = unique(app.antlabels);
            app.antlabels = sort(app.antlabels);
            app.colors = new_colors;
            
            update_text(app)
            

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
            if ~ismember('Unknown',torow(app.otherlabels))
                app.otherlabels{end+1} = 'Unknown';
            end
            
            app.Trck.labels.tagcolors = app.colors;
            app.Trck.labels.ant_labels = app.antlabels;
            app.Trck.labels.nonant_labels = app.nonantlabels;
            app.Trck.labels.other_labels = app.otherlabels;
            app.Trck.save_params;
            delete(app)
            
        end

        % Value changed function: AntLabelsText, NonAntLabelsText, 
        % OtherLabelsText
        function AntLabelsTextValueChanged(app, event)
            
            labels = setdiff(app.AntLabelsText.Value,{''});
            app.antlabels = intersect(labels,app.PossIDs);
            
            labels = setdiff(app.NonAntLabelsText.Value,{''});
            app.nonantlabels = setdiff(labels,app.PossIDs);
            
            labels = setdiff(app.OtherLabelsText.Value,{''});
            app.otherlabels = setdiff(labels,app.PossIDs);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CATTColorsandLabelsUIFigure = uifigure('Visible', 'off');
            app.CATTColorsandLabelsUIFigure.Name = 'CATT: Colors and Labels';
            app.BCheckBox = uicheckbox(app.CATTColorsandLabelsUIFigure);
            app.BCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorsChanged, true);
            app.BCheckBox.Text = 'Blue (B)';
            app.BCheckBox.Value = true;
            app.GCheckBox = uicheckbox(app.CATTColorsandLabelsUIFigure);
            app.GCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorsChanged, true);
            app.GCheckBox.Text = 'Green (G)';
            app.GCheckBox.Value = true;
            app.OCheckBox = uicheckbox(app.CATTColorsandLabelsUIFigure);
            app.OCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorsChanged, true);
            app.OCheckBox.Text = 'Orange (O)';
            app.OCheckBox.Value = true;
            app.LCheckBox = uicheckbox(app.CATTColorsandLabelsUIFigure);
            app.LCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorsChanged, true);
            app.LCheckBox.Text = 'Purple (L)';
            app.PCheckBox = uicheckbox(app.CATTColorsandLabelsUIFigure);
            app.PCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorsChanged, true);
            app.PCheckBox.Text = 'Pink (P)';
            app.PCheckBox.Value = true;
            app.RCheckBox = uicheckbox(app.CATTColorsandLabelsUIFigure);
            app.RCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorsChanged, true);
            app.RCheckBox.Text = 'Red (R)';
            app.YCheckBox = uicheckbox(app.CATTColorsandLabelsUIFigure);
            app.YCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorsChanged, true);
            app.YCheckBox.Text = 'Yellow (Y)';
            app.DoneButton = uibutton(app.CATTColorsandLabelsUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.AntLabelsText = uitextarea(app.CATTColorsandLabelsUIFigure);
            app.AntLabelsText.ValueChangedFcn = createCallbackFcn(app, @AntLabelsTextValueChanged, true);
            app.AntlabelsLabel = uilabel(app.CATTColorsandLabelsUIFigure);
            app.AntlabelsLabel.Text = 'Ant labels:';
            app.NonAntLabelsText = uitextarea(app.CATTColorsandLabelsUIFigure);
            app.NonAntLabelsText.ValueChangedFcn = createCallbackFcn(app, @AntLabelsTextValueChanged, true);
            app.NonAntlabelsLabel = uilabel(app.CATTColorsandLabelsUIFigure);
            app.NonAntlabelsLabel.Text = 'NonAnt labels:';
            app.OtherLabelsText = uitextarea(app.CATTColorsandLabelsUIFigure);
            app.OtherLabelsText.ValueChangedFcn = createCallbackFcn(app, @AntLabelsTextValueChanged, true);
            app.OtherlabelsLabel = uilabel(app.CATTColorsandLabelsUIFigure);
            app.OtherlabelsLabel.Text = 'Other labels:';
            app.WCheckBox = uicheckbox(app.CATTColorsandLabelsUIFigure);
            app.WCheckBox.Text = 'White (W)';
            app.KCheckBox = uicheckbox(app.CATTColorsandLabelsUIFigure);
            app.KCheckBox.Text = 'Black (K)';
            % Show the figure after all components are created
            app.CATTColorsandLabelsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = set_colors_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CATTColorsandLabelsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CATTColorsandLabelsUIFigure)
        end
    end
end