classdef validate_classifications < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        anTraXExtractTrainsetUIFigure  matlab.ui.Figure
        ExperimentMenu                 matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        RecentMenu                     matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
        SessionMenu                    matlab.ui.container.Menu
        QuitMenu                       matlab.ui.container.Menu
        TrackletsMenu                  matlab.ui.container.Menu
        FilterbyautoIDMenu             matlab.ui.container.Menu
        FilterbymovieMenu              matlab.ui.container.Menu
        CorrectIDButton                matlab.ui.control.Button
        ImageAxes                      matlab.ui.control.UIAxes
        WrongIDButton                  matlab.ui.control.Button
        ShouldnothaveIDButton          matlab.ui.control.Button
        IDDropDown                     matlab.ui.control.DropDown
        ExportallButton                matlab.ui.control.Button
        SelectframesButton             matlab.ui.control.Button
        StatsTextArea                  matlab.ui.control.TextArea
        ShowallframesCheckBox          matlab.ui.control.CheckBox
        NextButton                     matlab.ui.control.Button
        BackButton                     matlab.ui.control.Button
        IsanantButton                  matlab.ui.control.Button
        NotanantButton                 matlab.ui.control.Button
        IDLabel                        matlab.ui.control.Label
        TrackletTextArea               matlab.ui.control.TextArea
        WhitebackgroundCheckBox        matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        Trck trhandles % Description
        trjs 
        movlist
        classdir
        focus_trj 
        focal_id
        correct 
        wrong 
        ambig 
        trueneg 
        falseneg  
        assigned_rate_frames
        assigned_rate_trjs
        cnt=0;
        N
        progress_on_export
        turnedoff = {}
        classified
    end
    
    methods (Access = public)
        
        
        function next_trj(app)
           
            if length(app.trjs) <= app.cnt
                report('W', 'No more tracklets to display!')
                return
            end
        
            app.cnt = app.cnt + 1;
            set_focus_trj(app)
            
        end
    
        function prev_trj(app)
        
            if app.cnt == 1
                report('W', 'Reached first tracklet!')
                return
            end
           
            app.cnt = app.cnt - 1;
            set_focus_trj(app)
            
        end
    
        function correct_empty_tracklet_arrays(app)
            
           if isempty(app.ambig), app.ambig = tracklet.empty; end
           if isempty(app.correct), app.correct = tracklet.empty; end
           if isempty(app.wrong), app.wrong = tracklet.empty; end
           if isempty(app.falseneg), app.falseneg = tracklet.empty; end
           if isempty(app.trueneg), app.trueneg = tracklet.empty; end
            
        end
    
        function set_focus_trj(app)

        
            app.focus_trj = app.trjs(app.cnt);
            
            aid = app.focus_trj.autoID;
            
            if ismember(aid,app.Trck.usedIDs)
                
                app.CorrectIDButton.Enable = true;
                app.WrongIDButton.Enable = true;
                app.ShouldnothaveIDButton.Enable = true;
                app.IsanantButton.Enable = false;
                app.NotanantButton.Enable = false;
                
            elseif ismember(aid,app.Trck.labels.noant_labels)
               
                app.CorrectIDButton.Enable = false;
                app.WrongIDButton.Enable = false;
                app.ShouldnothaveIDButton.Enable = false;
                app.IsanantButton.Enable = true;
                app.NotanantButton.Enable = true;
                
            else
                
                app.CorrectIDButton.Enable = false;
                app.WrongIDButton.Enable = false;
                app.ShouldnothaveIDButton.Enable = false;
                app.IsanantButton.Enable = false;
                app.NotanantButton.Enable = false;
                
            end
            
            txt{1}      = ['Name: ',app.focus_trj.name];
            txt{end+1}  = ['Index: ',num2str(app.cnt),'/',num2str(length(app.trjs))];
            txt{end+1}  = ['Movie: ',num2str(app.focus_trj.m)];
            txt{end+1}  = ['Length: ',num2str(app.focus_trj.len)];
%             if app.Trck.get_param('geometry_multi_colony')
%                 txt{end+1}  = ['Colony: ',app.Trck.colony_labels{app.focus_trj.colony}];
%             end
            
            app.TrackletTextArea.Value = txt;
            
            
            show_image(app);
            
        end
        
        function update_counters(app)
            
            correct_trjs = length(app.correct)/(length(app.correct)+length(app.wrong));
            wrong_trjs = length(app.wrong)/(length(app.correct)+length(app.wrong));
            fp_trjs = length(app.ambig)/(length(app.ambig)+length(app.correct)+length(app.wrong));
            fn_trjs = length(app.falseneg)/(length(app.falseneg)+length(app.trueneg));
            
            
            nframes_correct = sum([app.trjs(app.correct).len]);
            nframes_wrong = sum([app.trjs(app.wrong).len]);
            nframes_ambig = sum([app.trjs(app.ambig).len]);
            nframes_fn = sum([app.trjs(app.falseneg).len]);
            nframes_tn = sum([app.trjs(app.trueneg).len]);
            
            correct_frames = nframes_correct/(nframes_correct+nframes_wrong);
            wrong_frames = nframes_wrong/(nframes_correct+nframes_wrong);
            fp_frames = nframes_ambig/(nframes_ambig+nframes_correct+nframes_wrong);
            fn_frames = nframes_fn/(nframes_fn+nframes_tn);
            
            % print stats
            text{1}     = ['total tracklets checked: ',num2str(app.cnt-1)];
            if app.cnt>1
                totframes = sum([app.trjs(1:app.cnt-1).len]);
            else
                totframes=0;
            end
            text{end+1} = ['total frames checked: ',num2str(totframes)]; 
            text{end+1} = '';
            
            text{end+1} = ['tracklet assigned: ',num2str(100*app.assigned_rate_trjs,'%.2f'),'%'];
            text{end+1} = ['frames assigned: ',num2str(100*app.assigned_rate_frames,'%.2f'),'%'];
            text{end+1} = '';
            
            text{end+1} = ['tracklet wrong id: ',num2str(100*wrong_trjs,'%.2f'),'%'];
            text{end+1} = ['tracklet fp: ',num2str(100*fp_trjs,'%.2f'),'%'];
            text{end+1} = ['tracklet fn: ',num2str(100*fn_trjs,'%.2f'),'%'];
            text{end+1} = '';
            
            text{end+1} = ['frames wrong id: ',num2str(100*wrong_frames,'%.2f'),'%'];
            text{end+1} = ['frames fp: ',num2str(100*fp_frames,'%.2f'),'%'];
            text{end+1} = ['frames fn: ',num2str(100*fn_frames,'%.2f'),'%'];
            
            app.StatsTextArea.Value = text;
            
        end
        
        function show_image(app)
            
            if isempty(app.focus_trj.images)
                app.focus_trj.images = app.Trck.imagesfile(app.focus_trj.m).(app.focus_trj.name);
            end
            
            if app.ShowallframesCheckBox.Value && app.focus_trj.len>1
                ims=app.focus_trj.images;
                im = imtile(ims);
                %montage(ims,'Parent',app.ImageAxes);
            else
                im=app.focus_trj.images(:,:,:,1);
            end
            
            if app.WhitebackgroundCheckBox.Value
                gry = min(im,[],3);
                msk = repmat(gry==0, [1,1,3]);
                im(msk) = 255;
            end
            
            imshow(im,'Parent',app.ImageAxes);
            
            %text(app.ImageAxes,10,10,app.focus_trj.autoID,'Color',[1,0.6,0.1],'FontSize',40,'FontWeight','Bold');
            
            app.IDLabel.Text = app.focus_trj.autoID;
            
            set(app.ImageAxes,'XTick',[],'YTick',[],'Box','on')
            app.ImageAxes.XLimMode = 'auto';
            app.ImageAxes.YLimMode = 'auto';
        end
    
    
        function add_examples(app,id)


            classdir = app.classdir;
            
            if ~isfolder(classdir)
                report('E','classdir does not exist')
                return
            end

            target_dir = [classdir,filesep,'examples',filesep,id,filesep];
            
            mkdirp(target_dir);
            
            if ~isfolder(target_dir)
                report('E','could not create target dir')
                return
            end
              
            ims = app.focus_trj.images;
            msk = repmat(max(ims,[],3)==0,[1,1,3,1]);
            
            if app.Trck.get_param('segmentation_invert')
                ims(msk)=0;
            else
                ims(msk)=255;
            end
            
            for i=1:size(ims,4)
                f = app.focus_trj.fi + i - 1;
                fname = [target_dir,app.Trck.expname,'.',app.focus_trj.name,'.',num2str(f),'.png'];
                imwrite(ims(:,:,:,i),fname);
                
            end

        end
        
        
        function generate_trjs_list(app)


            d = uiprogressdlg(app.anTraXExtractTrainsetUIFigure,'Title','Preparing validation dataset','Indeterminate','off');
            set(d,'Indeterminate','off','Message','Loading tracklet data...','Value',0)
            
            
            S=[];
            for i=1:length(app.movlist)
                m=app.movlist(i);
                set(d,'Indeterminate','off','Message',['Loading tracklet data for movie ',num2str(m)],'Value',i/length(app.movlist))
                Si = whos(app.Trck.imagesfile(m));                
                Si_names = {Si.name};
                
                x = arrayfun(@(x) numel(x.size)==4,Si);
                Li = ones(size(Si));
                Li(x) = arrayfun(@(x) x.size(4),Si(x));
                for j=1:length(Si)
                    Si(j).m=m;
                    Si(j).images=[];
                    Si(j).len=Li(j); 
                    Si(j).autoID='';
                    Si(j).Trck=app.Trck;
                    [ti,tf] = parse_tracklet_name(app.Trck,Si(j).name);
                    Si(j).fi = ti.f;
                    Si(j).ff = tf.f;
                end
                                
                f = [app.Trck.labelsdir,'autoids_',num2str(m),'.csv'];
                if ~exist(f,'file')
                    S = cat(1,S,Si);
                    continue
                end
                Ti = readtable(f);
                ix = ismember(Ti.tracklet,Si_names);
                Ti = Ti(ix,:);
                if isempty(Ti)
                    S = cat(1,S,Si);
                    continue
                end
                
                ix=cellfun(@(x) find(strcmp(x,Si_names)),Ti.tracklet');
                
                for j=1:length(ix)
                    Si(j).autoID = Ti.label{j};
                end
                
                S = cat(1,S,Si);
                
            end
            
            if ~isempty(app.focal_id)
                
                S = S(strcmp(app.focal_id,{S.autoID}));
                
                app.IDDropDown.Value = app.focal_id;
                
            end
            
            
            app.assigned_rate_trjs = 0;
            app.assigned_rate_frames = 0;
            aids = {S.autoID};
            if ~isempty(aids)
                assigned = ismember(aids,app.Trck.usedIDs) | ismember(aids,app.Trck.labels.noant_labels);
                app.assigned_rate_trjs = mean(assigned);
                app.assigned_rate_frames = sum([S(assigned).len])/sum([S.len]);
            end
            
            % randomize order
            S = S(randperm(length(S)));
                        
            if length(S)<app.N
                report('W','found fewer tracklets than requested')
            end
            
            
            ntrjs = min([app.N,length(S)]);
            for i=1:ntrjs
                set(d,'Indeterminate','off','Message',['Loading images...'],'Value',i/ntrjs)
                S(i).images = app.Trck.imagesfile(S(i).m).(S(i).name);
            end
            app.trjs = S;
            
            % present the first one
            app.cnt = 0;
            app.correct = [];
            app.wrong = [];
            app.ambig = [];
            app.trueneg = [];
            app.falseneg = [];
            
            close(d)
            
            if isempty(app.trjs)
                message = sprintf('No tracklet matching criteria found');
                uialert(app.anTraXExtractTrainsetUIFigure,message,'Alert','Icon','info');
                delete(app);
            else
                next_trj(app);
            end

        end
        
        
        function set_experiment(app, expdir, session)
        
            if isa(expdir, 'trhandles')
                
                app.Trck = expdir;
                expdir = app.Trck.expdir;
                session = app.Trck.trackingdirname;
            
            elseif nargin<3 || isempty(session)
                
                app.Trck = trhandles.load(expdir);
                session = app.Trck.trackingdirname;
            else
                
                app.Trck = trhandles.load(expdir, session);
                
            end
            
            
            app.classdir = [app.Trck.trackingdir, 'classifier'];
            mkdirp(app.classdir);
            mkdirp([app.classdir,filesep,'examples']);
            
            app.classified = isfile([app.Trck.labelsdir,'autoids_', num2str(app.Trck.graphlist(1)) ,'.csv']);
        
            if ~isempty(app.focal_id) && ~ismember(app.focal_id, app.Trck.allLabels)
                app.focal_id = [];
            end
            
            % session menu
            app.SessionMenu.Enable = true;
            sessions = find_sessions(expdir);
            delete(app.SessionMenu.Children);
            
            for i=1:length(sessions)
                
                sm = uimenu(app.SessionMenu);
                sm.Text = sessions{i};
                sm.MenuSelectedFcn = createCallbackFcn(app, @SessionMenuSelected, true);
                
                if strcmp(session, sessions{i})
                    sm.Checked = true;
                end
            end
            
            % movie menu
            if isempty(app.movlist) || ~all(ismember(app.movlist, app.Trck.graphlist))
                app.movlist = app.Trck.graphlist(1);
            end
            
            app.FilterbymovieMenu.Enable = true;
            
            delete(app.FilterbymovieMenu.Children);
            for i=1:length(app.Trck.graphlist)
                sm = uimenu(app.FilterbymovieMenu);
                sm.Text = num2str(app.Trck.graphlist(i));
                sm.MenuSelectedFcn = createCallbackFcn(app, @FilterByMovie, true);
                if ismember(app.Trck.graphlist(i),app.movlist)
                    sm.Checked = true;
                end
            end
            
            
            % add list of IDs to menu            
            labels = unique(app.Trck.allLabels,'stable');
            
            app.IDDropDown.Items = labels;
            app.IDDropDown.Value = app.IDDropDown.Items{1};
            
            labels = [{'All'};labels];
            
            delete(app.FilterbyautoIDMenu.Children);
            for i=1:length(labels)
                
                sm = uimenu(app.FilterbyautoIDMenu);
                sm.Text = labels{i};
                sm.MenuSelectedFcn = createCallbackFcn(app, @FilterByID, true);
                
            end
            
            app.CorrectIDButton.Visible = app.classified;
            app.WrongIDButton.Visible = app.classified;
            app.NotanantButton.Visible = app.classified;
            app.IsanantButton.Visible = app.classified;
            app.ShouldnothaveIDButton.Visible = app.classified;
            app.StatsTextArea.Visible = app.classified;
            app.IDLabel.Visible = app.classified;
            app.FilterbyautoIDMenu.Enable = app.classified;
            app.progress_on_export = ~app.classified;            
            
            generate_trjs_list(app)
            update_counters(app);
            
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Trck, varargin)
 
                                
            p = inputParser;
            addRequired(p,'Trck',@(x) isa(x,'trhandles') || isfolder(x));
            addParameter(p, 'session', [], @ischar)
            addParameter(p,'movlist',[],@isnumeric);
            addParameter(p,'N',50,@isnumeric);
            addParameter(p,'id',[],@ischar);
            parse(p,Trck,varargin{:});
            
           
            app.N = p.Results.N;
            app.movlist = p.Results.movlist;
            app.focal_id = p.Results.id;
            
            app.IDLabel.Visible = false;
            
            set_experiment(app, Trck, p.Results.session)
          
            
        end

        % Value changed function: ShowallframesCheckBox
        function ShowallframesCheckBoxValueChanged(app, event)
            show_image(app)
        end

        % Button pushed function: CorrectIDButton
        function CorrectIDButtonPushed(app, event)
            
            app.correct = cat(1,app.correct,app.cnt);
            app.correct = unique(app.correct);
            app.wrong = setdiff(app.wrong,app.correct);
            app.ambig = setdiff(app.ambig,app.correct);
%             correct_empty_tracklet_arrays(app)
            update_counters(app);
            
            next_trj(app);
            
            
        end

        % Button pushed function: WrongIDButton
        function WrongIDButtonPushed(app, event)
            
            app.wrong = cat(1,app.wrong,app.cnt);
            app.wrong = unique(app.wrong);
            app.correct = setdiff(app.correct,app.wrong);
            app.ambig = setdiff(app.ambig,app.wrong);
%             correct_empty_tracklet_arrays(app)
            update_counters(app);
            
            next_trj(app);
            
        end

        % Button pushed function: ShouldnothaveIDButton
        function ShouldnothaveIDButtonPushed(app, event)
            
            app.ambig = cat(1,app.ambig,app.cnt);
            app.ambig = unique(app.ambig);
            app.correct = setdiff(app.correct,app.ambig);
            app.wrong = setdiff(app.wrong,app.ambig);
%             correct_empty_tracklet_arrays(app)
            update_counters(app);
            
            next_trj(app);
            
        end

        % Button pushed function: ExportallButton
        function ExportallButtonPushed(app, event)
            
            id = app.IDDropDown.Value;
            add_examples(app,id);
            if app.progress_on_export
                update_counters(app);
                next_trj(app); 
            end

        end

        % Button pushed function: SelectframesButton
        function SelectframesButtonPushed(app, event)
            
            export_frame_selector_app(app.focus_trj,app.IDDropDown.Value,app.classdir);            
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
           update_counters(app);
           next_trj(app); 
            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            
            prev_trj(app);
            
        end

        % Button pushed function: IsanantButton
        function IsanantButtonPushed(app, event)
            
            app.falseneg = cat(1,app.falseneg,app.cnt);
            app.falseneg = unique(app.falseneg);
            app.trueneg = setdiff(app.trueneg,app.falseneg);
%             correct_empty_tracklet_arrays(app)
            update_counters(app);
            
            next_trj(app);
                        
        end

        % Button pushed function: NotanantButton
        function NotanantButtonPushed(app, event)
            
            app.trueneg = cat(1,app.trueneg,app.cnt);
            app.trueneg = unique(app.trueneg);
            app.falseneg = setdiff(app.falseneg,app.trueneg);
%             correct_empty_tracklet_arrays(app)
            update_counters(app);
            
            next_trj(app);
            
        end

        % Callback function: QuitMenu, anTraXExtractTrainsetUIFigure
        function anTraXExtractTrainsetUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Callback function
        function FilterByID(app, event)
            id = event.Source.Text;
            for i=1:length(app.FilterbyautoIDMenu.Children)
                app.FilterbyautoIDMenu.Children(i).Checked = false;
            end
            event.Source.Checked = true;
            if ~strcmp(id,'All')
                app.focal_id = id;
            else
                app.focal_id = [];
            end
            generate_trjs_list(app);
            update_counters(app);
        end

        % Callback function
        function FilterByMovie(app, event)
            m = str2double(event.Source.Text);
            for i=1:length(app.FilterbymovieMenu.Children)
                app.FilterbymovieMenu.Children(i).Checked = false;
            end
            event.Source.Checked = true;
            app.movlist = m;
            generate_trjs_list(app);
            update_counters(app);
        end

        % Callback function
        function SessionMenuSelected(app, event)
            set_experiment(app, app.Trck.expdir, event.Source.Text);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            expdir = uigetdir([app.Trck.expdir,'../']);
            set_experiment(app, expdir);
        end

        % Value changed function: WhitebackgroundCheckBox
        function WhitebackgroundCheckBoxValueChanged(app, event)
            value = app.WhitebackgroundCheckBox.Value;
            app.show_image();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.anTraXExtractTrainsetUIFigure = uifigure('Visible', 'off');
            app.anTraXExtractTrainsetUIFigure.Name = 'anTraX: Extract Trainset';
            app.anTraXExtractTrainsetUIFigure.CloseRequestFcn = createCallbackFcn(app, @anTraXExtractTrainsetUIFigureCloseRequest, true);
            app.ExperimentMenu = uimenu(app.anTraXExtractTrainsetUIFigure);
            app.ExperimentMenu.Text = 'Experiment';
            app.OpenMenu = uimenu(app.ExperimentMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.RecentMenu = uimenu(app.ExperimentMenu);
            app.RecentMenu.Enable = 'off';
            app.RecentMenu.Text = 'Recent';
            app.Menu = uimenu(app.RecentMenu);
            app.Menu.Text = 'Menu';
            app.SessionMenu = uimenu(app.ExperimentMenu);
            app.SessionMenu.Enable = 'off';
            app.SessionMenu.Text = 'Session';
            app.QuitMenu = uimenu(app.ExperimentMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @anTraXExtractTrainsetUIFigureCloseRequest, true);
            app.QuitMenu.Separator = 'on';
            app.QuitMenu.Text = 'Quit';
            app.TrackletsMenu = uimenu(app.anTraXExtractTrainsetUIFigure);
            app.TrackletsMenu.Text = 'Tracklets';
            app.FilterbyautoIDMenu = uimenu(app.TrackletsMenu);
            app.FilterbyautoIDMenu.Enable = 'off';
            app.FilterbyautoIDMenu.Text = 'Filter by autoID';
            app.FilterbymovieMenu = uimenu(app.TrackletsMenu);
            app.FilterbymovieMenu.Enable = 'off';
            app.FilterbymovieMenu.Text = 'Filter by movie';
            app.CorrectIDButton = uibutton(app.anTraXExtractTrainsetUIFigure, 'push');
            app.CorrectIDButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectIDButtonPushed, true);
            app.CorrectIDButton.Text = 'Correct ID';
            app.ImageAxes = uiaxes(app.anTraXExtractTrainsetUIFigure);
            app.WrongIDButton = uibutton(app.anTraXExtractTrainsetUIFigure, 'push');
            app.WrongIDButton.ButtonPushedFcn = createCallbackFcn(app, @WrongIDButtonPushed, true);
            app.WrongIDButton.Text = 'Wrong ID';
            app.ShouldnothaveIDButton = uibutton(app.anTraXExtractTrainsetUIFigure, 'push');
            app.ShouldnothaveIDButton.ButtonPushedFcn = createCallbackFcn(app, @ShouldnothaveIDButtonPushed, true);
            app.ShouldnothaveIDButton.Text = 'Should not have ID';
            app.IDDropDown = uidropdown(app.anTraXExtractTrainsetUIFigure);
            app.ExportallButton = uibutton(app.anTraXExtractTrainsetUIFigure, 'push');
            app.ExportallButton.ButtonPushedFcn = createCallbackFcn(app, @ExportallButtonPushed, true);
            app.ExportallButton.Text = 'Export all';
            app.SelectframesButton = uibutton(app.anTraXExtractTrainsetUIFigure, 'push');
            app.SelectframesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectframesButtonPushed, true);
            app.SelectframesButton.Text = 'Select frames';
            app.StatsTextArea = uitextarea(app.anTraXExtractTrainsetUIFigure);
            app.ShowallframesCheckBox = uicheckbox(app.anTraXExtractTrainsetUIFigure);
            app.ShowallframesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowallframesCheckBoxValueChanged, true);
            app.ShowallframesCheckBox.Text = 'Show all frames';
            app.ShowallframesCheckBox.Value = true;
            app.NextButton = uibutton(app.anTraXExtractTrainsetUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.BackButton = uibutton(app.anTraXExtractTrainsetUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.IsanantButton = uibutton(app.anTraXExtractTrainsetUIFigure, 'push');
            app.IsanantButton.ButtonPushedFcn = createCallbackFcn(app, @IsanantButtonPushed, true);
            app.IsanantButton.Enable = 'off';
            app.IsanantButton.Text = 'Is an ant';
            app.NotanantButton = uibutton(app.anTraXExtractTrainsetUIFigure, 'push');
            app.NotanantButton.ButtonPushedFcn = createCallbackFcn(app, @NotanantButtonPushed, true);
            app.NotanantButton.Enable = 'off';
            app.NotanantButton.Text = 'Not an ant';
            app.IDLabel = uilabel(app.anTraXExtractTrainsetUIFigure);
            app.IDLabel.Text = 'XX';
            app.TrackletTextArea = uitextarea(app.anTraXExtractTrainsetUIFigure);
            app.WhitebackgroundCheckBox = uicheckbox(app.anTraXExtractTrainsetUIFigure);
            app.WhitebackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @WhitebackgroundCheckBoxValueChanged, true);
            app.WhitebackgroundCheckBox.Text = 'White background';
            % Show the figure after all components are created
            app.anTraXExtractTrainsetUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = validate_classifications(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.anTraXExtractTrainsetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.anTraXExtractTrainsetUIFigure)
        end
    end
end