classdef validate_tracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        anTraXValidationUIFigure  matlab.ui.Figure
        ExperimentMenu            matlab.ui.container.Menu
        OpenMenu                  matlab.ui.container.Menu
        RecentMenu                matlab.ui.container.Menu
        SessionMenu               matlab.ui.container.Menu
        QuitMenu                  matlab.ui.container.Menu
        AssigmentsMenu            matlab.ui.container.Menu
        TypeMenu                  matlab.ui.container.Menu
        finalMenu                 matlab.ui.container.Menu
        nopropMenu                matlab.ui.container.Menu
        FilterbyIDMenu            matlab.ui.container.Menu
        FilterbycolonyMenu        matlab.ui.container.Menu
        SettimerangeMenu          matlab.ui.container.Menu
        ImageAxes                 matlab.ui.control.UIAxes
        CorrectButton             matlab.ui.control.Button
        WrongButton               matlab.ui.control.Button
        CantsayButton             matlab.ui.control.Button
        ZoomtoblobCheckBox        matlab.ui.control.CheckBox
        IsolateblobCheckBox       matlab.ui.control.CheckBox
        TextArea                  matlab.ui.control.TextArea
        IDLabel                   matlab.ui.control.Label
        MarkblobCheckBox          matlab.ui.control.CheckBox
        ValidationPointInfo       matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        Trck trhandles % Description
        XY
        T
        cnt
        bbb
        frame
        masked
        antxy
        blobConvexHull
        c
        id
        type
        roi
        ti
        tf
        movlist
        
        correct = 0
        wrong = 0
        ambig = 0
        
        assigment_rate
        
        
    end
    
    methods (Access = private)
        
        function update_counters(app)
           
            
            % print tracklet info
            text{1}     = ['assigment rate: ',num2str(100*app.assigment_rate,'%.2f'),'%'];
            text{end+1} = ' ';
            text{end+1} = ['total points checked: ',num2str(app.cnt)]; 
            text{end+1} = ['points ambig: ', num2str(app.cnt - app.correct - app.wrong)];
            text{end+1} = ['points correct: ',num2str(app.correct)];
            text{end+1} = ['points wrong: ',num2str(app.wrong)];
            [phat,pci] = binofit(app.wrong,app.wrong+app.correct);
            estr = [num2str(phat,'%.2f'),' [',num2str(pci(1),'%.3f'),',',num2str(pci(2),'%.3f'),']'];
            text{end+1} = ' ';
            text{end+1} = 'error [95% CI]: ';
            text{end+1} = estr;
            
            
            app.TextArea.Value = text;
            
        end
        
        function show_next(app)
            
            app.ImageAxes.cla;
            hold(app.ImageAxes,'on')
            
            app.cnt = app.cnt + 1;
            
            app.IDLabel.Text = app.T.id{app.cnt};
            app.IDLabel.Visible = true;
            
            app.Trck.read_frame(app.T.f(app.cnt));
            detect_blobs(app.Trck);            
            
            % prepare frame for display
            if app.Trck.get_param('geometry_multi_colony')
                c = strcmp(app.T.colony{app.cnt},app.Trck.colony_labels);
                mask  =  app.Trck.Masks.colony(:,:,c);
            else
                mask = app.Trck.Masks.roi(:,:,1);
            end
            
            frame = app.Trck.currfrm.CData;
            
            L = app.Trck.currfrm.antblob.LABEL;
            
            xy = app.T.xy(app.cnt,:);
            xy = xy/app.Trck.get_param('geometry_rscale');
            
            bix = argmin(pdist2(xy,app.Trck.currfrm.antblob.DATA.CENTROID));
            app.bbb = app.Trck.currfrm.antblob.DATA.BBOX(bix,:);
            
            L = L==bix;
            
            frame = applyMasktoIm(frame,mask);
            x = any(mask,1);
            y = any(mask,2);
            
            x0 = find(x,1,'first');
            y0 = find(y,1,'first');
            x1 = find(x,1,'last');
            y1 = find(y,1,'last');
            
            cbb = [x0,y0,x1-x0+1,y1-y0+1];
            app.bbb(1) = app.bbb(1) - x0 + 1;
            app.bbb(2) = app.bbb(2) - y0 + 1;
            
            
            app.frame = imcrop(frame,cbb);
            L = imcrop(L,cbb);
            app.masked = applyMasktoIm(app.frame,L);
            S = regionprops(L,'ConvexHull');

            bch = S.ConvexHull;
            
            app.antxy = [xy(1)-x0+1,xy(2)-y0+1];
            app.blobConvexHull = bch;
            
            reimage(app)
            
            f = num2str(app.T.f(app.cnt));
            id = app.T.id{app.cnt};
            info_str = [id, ' / ', f];
            if app.Trck.get_param('geometry_multi_colony')
                c = app.T.colony{app.cnt};
                info_str = [c, ' / ', info_str];
            end
         
            
            app.ValidationPointInfo.Text = info_str;
            
        end
    
        function reimage(app)
            
            if app.IsolateblobCheckBox.Value
                imshow(app.masked,'Parent',app.ImageAxes);
            else
                imshow(app.frame,'Parent',app.ImageAxes);
            end
            
            if app.MarkblobCheckBox.Value
                plot(app.ImageAxes,app.blobConvexHull(:,1),app.blobConvexHull(:,2),'Color',rgb('Magenta'),'LineWidth',2);
            else
                scatter(app.ImageAxes,app.antxy(1),app.antxy(2),200,'m','+');
            end
            
            
            set(app.ImageAxes,'XTick',[],'YTick',[],'Box','on')
            set_view(app)
        end
    
    
        function set_view(app)
            
            if app.ZoomtoblobCheckBox.Value
                app.ImageAxes.XLimMode = 'manual';
                app.ImageAxes.YLimMode = 'manual';
                app.ImageAxes.XLim = [app.bbb(1),app.bbb(1)+app.bbb(3)];
                app.ImageAxes.YLim = [app.bbb(2),app.bbb(2)+app.bbb(4)];
            else
                app.ImageAxes.XLimMode = 'auto';
                app.ImageAxes.YLimMode = 'auto';
            end
            
        end
        
        function set_experiment(app, expdir, session)
        
        
            d = uiprogressdlg(app.anTraXValidationUIFigure,'Title','Preparing data','Indeterminate','on');
        
        
            if isa(expdir, 'trhandles')
                
                app.Trck = expdir;
                expdir = app.Trck.expdir;
                session = app.Trck.trackingdirname;
            
            elseif nargin<3 || isempty(session)
                
                app.Trck = trhandles.load(expdir);
                session = app.Trck.trackingdirname;
            else
                
                app.Trck = trhandles.load(expdir, session);
                
            end
            
            
            % session menu
            app.SessionMenu.Enable = true;
            sessions = find_sessions(expdir);
            delete(app.SessionMenu.Children);
            
            for i=1:length(sessions)
                
                sm = uimenu(app.SessionMenu);
                sm.Text = sessions{i};
                sm.MenuSelectedFcn = createCallbackFcn(app, @SessionMenuSelected, true);
                
                if strcmp(session, sessions{i})
                    sm.Checked = true;
                end
            end
            
            % id menu
            ids = app.Trck.usedIDs;
            delete(app.FilterbyIDMenu.Children);
            
            sm = uimenu(app.FilterbyIDMenu);
            sm.Text = 'All';
            sm.MenuSelectedFcn = createCallbackFcn(app, @IDChanged, true);
            sm.Checked = strcmp(sm.Text, app.id);
            
            for i=1:length(ids)
                sm = uimenu(app.FilterbyIDMenu);
                sm.Text = ids{i};
                sm.MenuSelectedFcn = createCallbackFcn(app, @IDChanged, true);
                sm.Checked = strcmp(sm.Text, app.id);
            end
            
            % colony menu
            delete(app.FilterbycolonyMenu.Children);
            
            sm = uimenu(app.FilterbycolonyMenu);
            sm.Text = 'All';
            sm.MenuSelectedFcn = createCallbackFcn(app, @ColonyChanged, true);
            sm.Checked = strcmp(sm.Text, app.c);
            
            if app.Trck.prmtrs.geometry_multi_colony
                app.FilterbycolonyMenu.Enable = true;
                for c=1:length(app.Trck.colony_labels)
                    sm = uimenu(app.FilterbycolonyMenu);
                    sm.Text = app.Trck.colony_labels{c};
                    sm.MenuSelectedFcn = createCallbackFcn(app, @ColonyChanged, true);
                    sm.Checked = strcmp(sm.Text, app.c);
                end
            else
                app.FilterbycolonyMenu.Enable = false;
            end
            
            
            % load XY
            
            [app.XY,frames] = app.Trck.loadxy('movlist',app.ti.m:app.tf.m,'type',app.type);
            
            ix = frames >= app.ti.f & frames <= app.tf.f;
            
            
            if app.Trck.get_param('geometry_multi_colony')
                
                for cc=1:length(app.Trck.colony_labels)
                    
                    for i=1:length(ids)
                        
                        app.XY.(app.Trck.colony_labels{cc}).(ids{i}) = app.XY.(app.Trck.colony_labels{cc}).(ids{i})(ix,:);
                        
                    end
                    
                end
                
            else
                
                for i=1:length(ids)
                    
                    app.XY.(ids{i}) = app.XY.(ids{i})(ix,:);
                    
                end
                
            end
                
            
            frames = frames(ix);
            
            % create a random list of Colony/ID/frame to verify
            N  = min(numel(frames), 5000);
            
            if app.Trck.get_param('geometry_multi_colony') && strcmp(app.c, 'All')
                A.colony = tocol(app.Trck.colony_labels(randi(app.Trck.Ncolonies,N,1)));
            elseif app.Trck.get_param('geometry_multi_colony')
                A.colony = repmat({app.c},N,1);
            end
            
            if strcmp(app.id, 'All')
                A.id = tocol(app.Trck.usedIDs(randi(app.Trck.NIDs,N,1)));
            else
                A.id = repmat({app.id},N,1);
            end
            
            A.fix = tocol(randperm(numel(frames),N));
            A.f  = tocol(frames(A.fix));
            
            app.T = struct2table(A);
            
            % calculate assigment rate
%             if app.Trck.get_param('geometry_multi_colony')
%                 for i=1:app.Trck.Ncolonies
%                     c = app.Trck.colony_labels{i};
%                     for j=1:app.Trck.NIDs
%                         id = app.Trck.usedIDs{j};
%                         a(i,j) = mean(~isnan(app.XY.(c).(id)(:,1)));
%                     end
%                 end
%             else
%                 for j=1:app.Trck.NIDs
%                     id = app.Trck.usedIDs{j};
%                     a(j) = mean(~isnan(app.XY.(id)(:,1)));
%                 end
%             end
            
            app.assigment_rate = generate_assignment_rate_report(app.Trck, app.XY);
            
            if app.Trck.get_param('geometry_multi_colony')
                for i=1:size(app.T,1)
                    xy(i,:) = app.XY.(app.T.colony{i}).(app.T.id{i})(app.T.fix(i),1:2);
                end
            else
                for i=1:size(app.T,1)
                    xy(i,:) = app.XY.(app.T.id{i})(app.T.fix(i),1:2);
                end
            end
            app.T.xy = xy;
            
            % filter unassigned
            app.T=app.T(~isnan(xy(:,1)),:);
            
            % filter not in roi
            cx = round(app.T.xy(:,1)/app.Trck.get_param('geometry_rscale'));
            cy = round(app.T.xy(:,2)/app.Trck.get_param('geometry_rscale'));
            ix = sub2ind(size(app.roi),cy,cx);
            inroi(:,1) =  app.roi(ix)>0;
            
            app.T = app.T(inroi,:);
            
            % reset counters
            app.cnt = 0;
            app.correct = 0;
            app.wrong = 0;
            app.ambig = 0;
            
            update_counters(app);
            show_next(app);
            
            close(d);
        
        end
    
    
        function mark_blob(app)


        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Trck, varargin)
            
            p = inputParser;
            addRequired(p,'Trck',@(x) isa(x,'trhandles') || isfolder(x));
            addParameter(p, 'session', [], @ischar)
            addParameter(p, 'roi', [])
            addParameter(p, 'ti', [])
            addParameter(p, 'tf', [])
            addParameter(p, 'type', 'final', @(x) ismember(x, {'final', 'noprop'}))
            
            parse(p,Trck,varargin{:});
            
            
            if ~isa(Trck, 'trhandles')
                                
                Trck = trhandles.load(Trck, p.Results.session);
                
            end
           
            
            if ~isempty(p.Results.roi)
                if ischar(p.Results.roi) 
                    %roi = Trck.TrackingMask(:,:,1)>0;
                    %app.roi = bwareaopen(roi,round(bwarea(roi)/2));
                    app.roi = Trck.Masks.(p.Results.roi)(:,:,1) > 0;
                else
                    app.roi = p.Results.roi(:,:,1)>0;
                end
            else
                app.roi = Trck.TrackingMask(:,:,1);
            end
            
            if ~isempty(p.Results.ti)
                if isa(p.Results.ti, 'trtime')
                    app.ti = p.Results.ti;
                else
                    app.ti = trtime(Trck, p.Results.ti);
                end
            else
                app.ti = trtime(Trck, 1);
            end
            
            if ~isempty(p.Results.tf)
                if isa(p.Results.tf, 'trtime')
                    app.tf = p.Results.tf;
                else
                    app.tf = trtime(Trck, p.Results.tf);
                end
            else
                app.tf = trtime(Trck, Trck.er.totalframenum);
            end
                       
            app.id = 'All';
            app.c = 'All';
            app.type = p.Results.type;
            
            app.IDLabel.Visible = false;
            
            set_experiment(app, Trck, p.Results.session)

        end

        % Button pushed function: CorrectButton
        function CorrectButtonPushed(app, event)
            app.correct = app.correct + 1;
            update_counters(app)
            show_next(app)
        end

        % Button pushed function: WrongButton
        function WrongButtonPushed(app, event)
            app.wrong = app.wrong + 1;
            update_counters(app)
            show_next(app)
        end

        % Button pushed function: CantsayButton
        function CantsayButtonPushed(app, event)
            app.ambig = app.ambig + 1;
            update_counters(app)
            show_next(app)
        end

        % Value changed function: ZoomtoblobCheckBox
        function ZoomtoblobCheckBoxValueChanged(app, event)
            set_view(app)
        end

        % Value changed function: IsolateblobCheckBox, 
        % MarkblobCheckBox
        function IsolateblobCheckBoxValueChanged(app, event)
            reimage(app)
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            expdir = uigetdir([app.Trck.expdir,'../']);
            set_experiment(app, expdir);
        end

        % Callback function: QuitMenu, anTraXValidationUIFigure
        function anTraXValidationUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Callback function
        function SessionMenuSelected(app, event)
            set_experiment(app, app.Trck.expdir, event.Source.Text);
        end

        % Callback function
        function IDChanged(app, event)
            app.id = event.Source.Text;
            set_experiment(app, app.Trck.expdir, app.Trck.trackingdirname);
        end

        % Callback function
        function ColonyChanged(app, event)
            app.c = event.Source.Text;
            set_experiment(app, app.Trck.expdir, app.Trck.trackingdirname);
        end

        % Menu selected function: SettimerangeMenu
        function SettimerangeMenuSelected(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.anTraXValidationUIFigure = uifigure('Visible', 'off');
            app.anTraXValidationUIFigure.Name = 'anTraX: Validation';
            app.anTraXValidationUIFigure.CloseRequestFcn = createCallbackFcn(app, @anTraXValidationUIFigureCloseRequest, true);
            app.ExperimentMenu = uimenu(app.anTraXValidationUIFigure);
            app.ExperimentMenu.Text = 'Experiment';
            app.OpenMenu = uimenu(app.ExperimentMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.RecentMenu = uimenu(app.ExperimentMenu);
            app.RecentMenu.Enable = 'off';
            app.RecentMenu.Text = 'Recent';
            app.SessionMenu = uimenu(app.ExperimentMenu);
            app.SessionMenu.Text = 'Session';
            app.QuitMenu = uimenu(app.ExperimentMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @anTraXValidationUIFigureCloseRequest, true);
            app.QuitMenu.Separator = 'on';
            app.QuitMenu.Text = 'Quit';
            app.AssigmentsMenu = uimenu(app.anTraXValidationUIFigure);
            app.AssigmentsMenu.Text = 'Assigments';
            app.TypeMenu = uimenu(app.AssigmentsMenu);
            app.TypeMenu.Enable = 'off';
            app.TypeMenu.Text = 'Type';
            app.finalMenu = uimenu(app.TypeMenu);
            app.finalMenu.Text = 'final';
            app.nopropMenu = uimenu(app.TypeMenu);
            app.nopropMenu.Text = 'noprop';
            app.FilterbyIDMenu = uimenu(app.AssigmentsMenu);
            app.FilterbyIDMenu.Text = 'Filter by ID';
            app.FilterbycolonyMenu = uimenu(app.AssigmentsMenu);
            app.FilterbycolonyMenu.Text = 'Filter by colony';
            app.SettimerangeMenu = uimenu(app.AssigmentsMenu);
            app.SettimerangeMenu.MenuSelectedFcn = createCallbackFcn(app, @SettimerangeMenuSelected, true);
            app.SettimerangeMenu.Enable = 'off';
            app.SettimerangeMenu.Text = 'Set time range';
            app.ImageAxes = uiaxes(app.anTraXValidationUIFigure);
            title(app.ImageAxes, '')
            xlabel(app.ImageAxes, '')
            ylabel(app.ImageAxes, '')
            app.CorrectButton = uibutton(app.anTraXValidationUIFigure, 'push');
            app.CorrectButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectButtonPushed, true);
            app.CorrectButton.Text = 'Correct';
            app.WrongButton = uibutton(app.anTraXValidationUIFigure, 'push');
            app.WrongButton.ButtonPushedFcn = createCallbackFcn(app, @WrongButtonPushed, true);
            app.WrongButton.Text = 'Wrong';
            app.CantsayButton = uibutton(app.anTraXValidationUIFigure, 'push');
            app.CantsayButton.ButtonPushedFcn = createCallbackFcn(app, @CantsayButtonPushed, true);
            app.CantsayButton.Text = 'Can''t say';
            app.ZoomtoblobCheckBox = uicheckbox(app.anTraXValidationUIFigure);
            app.ZoomtoblobCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZoomtoblobCheckBoxValueChanged, true);
            app.ZoomtoblobCheckBox.Text = 'Zoom to blob';
            app.IsolateblobCheckBox = uicheckbox(app.anTraXValidationUIFigure);
            app.IsolateblobCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsolateblobCheckBoxValueChanged, true);
            app.IsolateblobCheckBox.Text = 'Isolate blob';
            app.TextArea = uitextarea(app.anTraXValidationUIFigure);
            app.IDLabel = uilabel(app.anTraXValidationUIFigure);
            app.MarkblobCheckBox = uicheckbox(app.anTraXValidationUIFigure);
            app.MarkblobCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsolateblobCheckBoxValueChanged, true);
            app.MarkblobCheckBox.Text = 'Mark blob';
            app.ValidationPointInfo = uilabel(app.anTraXValidationUIFigure);
            app.ValidationPointInfo.Text = '';
            % Show the figure after all components are created
            app.anTraXValidationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = validate_tracking(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.anTraXValidationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.anTraXValidationUIFigure)
        end
    end
end