classdef verify_autoids_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        anTraXVerifyAutoIDsUIFigure  matlab.ui.Figure
        ExperimentMenu               matlab.ui.container.Menu
        Menu                         matlab.ui.container.Menu
        Menu_2                       matlab.ui.container.Menu
        OpenMenu                     matlab.ui.container.Menu
        QuitMenu                     matlab.ui.container.Menu
        TargetdirMenu_2              matlab.ui.container.Menu
        OpenMenu_2                   matlab.ui.container.Menu
        OpenrecentMenu               matlab.ui.container.Menu
        SessionMenu                  matlab.ui.container.Menu
        CorrectIDButton              matlab.ui.control.Button
        ImageAxes                    matlab.ui.control.UIAxes
        WrongIDButton                matlab.ui.control.Button
        ShouldnothaveIDButton        matlab.ui.control.Button
        IDDropDown                   matlab.ui.control.DropDown
        ExportallButton              matlab.ui.control.Button
        SelectframesButton           matlab.ui.control.Button
        StatsTextArea                matlab.ui.control.TextArea
        ShowallframesCheckBox        matlab.ui.control.CheckBox
        NextButton                   matlab.ui.control.Button
        BackButton                   matlab.ui.control.Button
        IsanantButton                matlab.ui.control.Button
        NotanantButton               matlab.ui.control.Button
        IDLabel                      matlab.ui.control.Label
        TrackletTextArea             matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        Trck trhandles % Description
        trjs 
        movlist
        classdir
        focus_trj 
        focal_id
        correct 
        wrong 
        ambig 
        trueneg 
        falseneg  
        assigned_rate_frames
        assigned_rate_trjs
        cnt=0;
        N
        progress_on_export
        turnedoff = {}
    end
    
    methods (Access = public)
        
        
        function next_trj(app)
           
            app.cnt = app.cnt + 1;
            set_focus_trj(app)
            
        end
    
        function prev_trj(app)
           
            app.cnt = app.cnt - 1;
            set_focus_trj(app)
            
        end
    
        function correct_empty_tracklet_arrays(app)
            
           if isempty(app.ambig), app.ambig = tracklet.empty; end
           if isempty(app.correct), app.correct = tracklet.empty; end
           if isempty(app.wrong), app.wrong = tracklet.empty; end
           if isempty(app.falseneg), app.falseneg = tracklet.empty; end
           if isempty(app.trueneg), app.trueneg = tracklet.empty; end
            
        end
    
        function set_focus_trj(app)


            app.focus_trj = app.trjs(app.cnt);
            
            aid = app.focus_trj.autoID;
            
            if ismember(aid,app.Trck.usedIDs)
                
                app.CorrectIDButton.Enable = true;
                app.WrongIDButton.Enable = true;
                app.ShouldnothaveIDButton.Enable = true;
                app.IsanantButton.Enable = false;
                app.NotanantButton.Enable = false;
                
            elseif ismember(aid,app.Trck.labels.nonant_labels)
               
                app.CorrectIDButton.Enable = false;
                app.WrongIDButton.Enable = false;
                app.ShouldnothaveIDButton.Enable = false;
                app.IsanantButton.Enable = true;
                app.NotanantButton.Enable = true;
                
            else
                
                app.CorrectIDButton.Enable = false;
                app.WrongIDButton.Enable = false;
                app.ShouldnothaveIDButton.Enable = false;
                app.IsanantButton.Enable = false;
                app.NotanantButton.Enable = false;
                
            end
            
            txt{1}      = ['Name: ',app.focus_trj.name];
            txt{end+1}  = ['Index: ',num2str(app.cnt),'/',num2str(length(app.trjs))];
            txt{end+1}  = ['Movie: ',num2str(app.focus_trj.m)];
            txt{end+1}  = ['Length: ',num2str(app.focus_trj.len)];
%             if app.Trck.get_param('geometry_multi_colony')
%                 txt{end+1}  = ['Colony: ',app.Trck.colony_labels{app.focus_trj.colony}];
%             end
            
            app.TrackletTextArea.Value = txt;
            
            
            show_image(app);
            
        end
    
        function update_counters(app)
            
            correct_trjs = length(app.correct)/(length(app.correct)+length(app.wrong));
            wrong_trjs = length(app.wrong)/(length(app.correct)+length(app.wrong));
            fp_trjs = length(app.ambig)/(length(app.ambig)+length(app.correct)+length(app.wrong));
            fn_trjs = length(app.falseneg)/(length(app.falseneg)+length(app.trueneg));
            
            
            nframes_correct = sum([app.trjs(app.correct).len]);
            nframes_wrong = sum([app.trjs(app.wrong).len]);
            nframes_ambig = sum([app.trjs(app.ambig).len]);
            nframes_fn = sum([app.trjs(app.falseneg).len]);
            nframes_tn = sum([app.trjs(app.trueneg).len]);
            
            correct_frames = nframes_correct/(nframes_correct+nframes_wrong);
            wrong_frames = nframes_wrong/(nframes_correct+nframes_wrong);
            fp_frames = nframes_ambig/(nframes_ambig+nframes_correct+nframes_wrong);
            fn_frames = nframes_fn/(nframes_fn+nframes_tn);
            
            % print stats
            text{1}     = ['total tracklets checked: ',num2str(app.cnt-1)];
            if app.cnt>1
                totframes = sum([app.trjs(1:app.cnt-1).len]);
            else
                totframes=0;
            end
            text{end+1} = ['total frames checked: ',num2str(totframes)]; 
            text{end+1} = '';
            
            text{end+1} = ['tracklet assigned: ',num2str(100*app.assigned_rate_trjs,'%.2f'),'%'];
            text{end+1} = ['frames assigned: ',num2str(100*app.assigned_rate_frames,'%.2f'),'%'];
            text{end+1} = '';
            
            text{end+1} = ['tracklet wrong id: ',num2str(100*wrong_trjs,'%.2f'),'%'];
            text{end+1} = ['tracklet fp: ',num2str(100*fp_trjs,'%.2f'),'%'];
            text{end+1} = ['tracklet fn: ',num2str(100*fn_trjs,'%.2f'),'%'];
            text{end+1} = '';
            
            text{end+1} = ['frames wrong id: ',num2str(100*wrong_frames,'%.2f'),'%'];
            text{end+1} = ['frames fp: ',num2str(100*fp_frames,'%.2f'),'%'];
            text{end+1} = ['frames fn: ',num2str(100*fn_frames,'%.2f'),'%'];
            
            app.StatsTextArea.Value = text;
            
        end
        
        function show_image(app)
            
            if isempty(app.focus_trj.images)
                app.focus_trj.images = app.Trck.imagesfile(app.focus_trj.m).(app.focus_trj.name);
            end
            
            if app.ShowallframesCheckBox.Value && app.focus_trj.len>1
                ims=app.focus_trj.images;
                montage(ims,'Parent',app.ImageAxes);
            else
                im=app.focus_trj.images(:,:,:,1);
                imshow(im,'Parent',app.ImageAxes);
            end
            
            %text(app.ImageAxes,10,10,app.focus_trj.autoID,'Color',[1,0.6,0.1],'FontSize',40,'FontWeight','Bold');
            
            app.IDLabel.Text = app.focus_trj.autoID;
            
            set(app.ImageAxes,'XTick',[],'YTick',[],'Box','on')
            app.ImageAxes.XLimMode = 'auto';
            app.ImageAxes.YLimMode = 'auto';
        end
    
    
        function add_examples(app,id)
            
            classdir = app.classdir;
            
            if ~isfolder(classdir)
                report('E','classdir does not exist')
                return
            end

            target_dir = [classdir,filesep,'examples',filesep,id,filesep];
            
            mkdirp(target_dir);
            
            if ~isfolder(target_dir)
                report('E','could not create target dir')
                return
            end
              
            ims = app.focus_trj.images;
            msk = repmat(max(ims,[],3)==0,[1,1,3,1]);
            ims(msk)=255;
            
            for i=1:size(ims,4)
                f = app.focus_trj.fi + i - 1;
                fname = [target_dir,app.Trck.expname,'.',app.focus_trj.name,'.',num2str(f),'.png'];
                imwrite(ims(:,:,:,i),fname);
                
            end

        end
        
        
        function generate_trjs_list(app)


            d = uiprogressdlg(app.anTraXVerifyAutoIDsUIFigure,'Title','Preparing validation dataset','Indeterminate','off');
            set(d,'Indeterminate','off','Message','Loading tracklet data...','Value',0)
            
            %report('I','Generating list of tracklets (might take a while if your experiment is long).')
            
            %report('I',['Analyzing mat files']);
            
            %set_all_imagesfiles(app.Trck)
            
            S=[];
            for i=1:length(app.movlist)
                m=app.movlist(i);
                %report('I',['Loading data for movie ',num2str(i)]);
                set(d,'Indeterminate','off','Message',['Loading tracklet data for movie ',num2str(m)],'Value',i/length(app.movlist))
                Si = whos(app.Trck.imagesfile(m));                
                Si_names = {Si.name};
                
                x = arrayfun(@(x) numel(x.size)==4,Si);
                Li = ones(size(Si));
                Li(x) = arrayfun(@(x) x.size(4),Si(x));
                for j=1:length(Si)
                    Si(j).m=m;
                    Si(j).images=[];
                    Si(j).len=Li(j); 
                    Si(j).autoID='';
                    Si(j).Trck=app.Trck;
                    [ti,tf] = parse_tracklet_name(app.Trck,Si(j).name);
                    Si(j).fi = ti.f;
                    Si(j).ff = tf.f;
                end
                                
                f = [app.Trck.labelsdir,'autoids_',num2str(m),'.csv'];
                if ~exist(f,'file')
                    S = cat(1,S,Si);
                    continue
                end
                Ti = readtable(f);
                ix = ismember(Ti.tracklet,Si_names);
                Ti = Ti(ix,:);
                if isempty(Ti)
                    S = cat(1,S,Si);
                    continue
                end
                
                ix=cellfun(@(x) find(strcmp(x,Si_names)),Ti.tracklet');
                
                for j=1:length(ix)
                    Si(j).autoID = Ti.label{j};
                end
                
                S = cat(1,S,Si);
                
            end
            
            if ~isempty(app.focal_id)
                
                S = S(strcmp(app.focal_id,{S.autoID}));
                
                app.IDDropDown.Value = app.focal_id;
                
            end
            
            
            app.assigned_rate_trjs = 0;
            app.assigned_rate_frames = 0;
            aids = {S.autoID};
            if ~isempty(aids)
                assigned = ismember(aids,app.Trck.usedIDs) | ismember(aids,app.Trck.labels.nonant_labels);
                app.assigned_rate_trjs = mean(assigned);
                app.assigned_rate_frames = sum([S(assigned).len])/sum([S.len]);
            end
            
            % randomize order
            S = S(randperm(length(S)));
                        
            if length(S)<app.N
                report('W','found fewer tracklets than requested')
            end
            
            
            ntrjs = min([app.N,length(S)]);
            for i=1:ntrjs
                set(d,'Indeterminate','off','Message',['Loading images...'],'Value',i/ntrjs)
                S(i).images = app.Trck.imagesfile(S(i).m).(S(i).name);
            end
            app.trjs = S;
            
            % present the first one
            app.cnt = 0;
            next_trj(app); 
            
            %report('G','Ready!');
            close(d)
            
            for iprop = 1:length(app.turnedoff)
                app.(app.turnedoff{iprop}).Visible = 'on';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Trck, varargin)
            
            Trck = trhandles.load(Trck);
            
            if ~isempty(Trck.classdir)
                classdir = Trck.classdir;
            else
                classdir = [Trck.trackingdir, 'classifier', filesep];
                
            end
            
            mkdirp(classdir);
            mkdirp([classdir,'examples']);
            
            p = inputParser;
            addRequired(p,'Trck',@(x) isa(x,'trhandles') || isfolder(x));
            addParameter(p,'movlist',Trck.graphlist,@isnumeric);
            addParameter(p,'N',200,@isnumeric);
            addParameter(p,'id',[],@ischar);
            addParameter(p,'classdir',classdir, @isfolder);
            addParameter(p,'progress_on_export',false, @islogical);
            parse(p,Trck,varargin{:});
           
            app.Trck = Trck;
            app.N = p.Results.N;
            app.movlist = p.Results.movlist;
            app.classdir = p.Results.classdir;
            app.focal_id = p.Results.id;
            
            if ~isempty(p.Results.id) && ~ismember(p.Results.id,Trck.usedIDs)
                
                report('E','Given id not in id list')
                return
                
            end
            
            props = properties(app);
            turnedoff = {};
            for iprop = 1:length(props)
                thisprop = props{iprop};
                if isprop(app.(thisprop), 'Visible')
                    app.(thisprop).Visible = 'off';
                    turnedoff{end+1} = thisprop;
                end
            
            end
            
            app.turnedoff = turnedoff;
                       
            app.anTraXVerifyAutoIDsUIFigure.Visible = 'on';
            
            app.progress_on_export = p.Results.progress_on_export;
            
            app.IDDropDown.Items = Trck.allLabels;
            app.IDDropDown.Value = app.IDDropDown.Items{1};


            if ~isfolder(app.classdir)
                app.IDDropDown.Enable = false;
                app.ExportallButton.Enable = false;
                app.SelectframesButton.Enable = false;
            end
            
            generate_trjs_list(app)
            update_counters(app);
            
        end

        % Value changed function: ShowallframesCheckBox
        function ShowallframesCheckBoxValueChanged(app, event)
            show_image(app)
        end

        % Button pushed function: CorrectIDButton
        function CorrectIDButtonPushed(app, event)
            
            app.correct = cat(1,app.correct,app.cnt);
            app.correct = unique(app.correct);
            app.wrong = setdiff(app.wrong,app.correct);
            app.ambig = setdiff(app.ambig,app.correct);
%             correct_empty_tracklet_arrays(app)
            update_counters(app);
            
            next_trj(app);
            
            
        end

        % Button pushed function: WrongIDButton
        function WrongIDButtonPushed(app, event)
            
            app.wrong = cat(1,app.wrong,app.cnt);
            app.wrong = unique(app.wrong);
            app.correct = setdiff(app.correct,app.wrong);
            app.ambig = setdiff(app.ambig,app.wrong);
%             correct_empty_tracklet_arrays(app)
            update_counters(app);
            
            next_trj(app);
            
        end

        % Button pushed function: ShouldnothaveIDButton
        function ShouldnothaveIDButtonPushed(app, event)
            
            app.ambig = cat(1,app.ambig,app.cnt);
            app.ambig = unique(app.ambig);
            app.correct = setdiff(app.correct,app.ambig);
            app.wrong = setdiff(app.wrong,app.ambig);
%             correct_empty_tracklet_arrays(app)
            update_counters(app);
            
            next_trj(app);
            
        end

        % Button pushed function: ExportallButton
        function ExportallButtonPushed(app, event)
            
            id = app.IDDropDown.Value;
            add_examples(app,id);
            if app.progress_on_export
                update_counters(app);
                next_trj(app); 
            end

        end

        % Button pushed function: SelectframesButton
        function SelectframesButtonPushed(app, event)
            
            export_frame_selector_app(app.focus_trj,app.IDDropDown.Value,app.classdir);            
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
           update_counters(app);
           next_trj(app); 
            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            
            prev_trj(app);
            
        end

        % Button pushed function: IsanantButton
        function IsanantButtonPushed(app, event)
            
            app.falseneg = cat(1,app.falseneg,app.cnt);
            app.falseneg = unique(app.falseneg);
            app.trueneg = setdiff(app.trueneg,app.falseneg);
%             correct_empty_tracklet_arrays(app)
            update_counters(app);
            
            next_trj(app);
                        
        end

        % Button pushed function: NotanantButton
        function NotanantButtonPushed(app, event)
            
            app.trueneg = cat(1,app.trueneg,app.cnt);
            app.trueneg = unique(app.trueneg);
            app.falseneg = setdiff(app.falseneg,app.trueneg);
%             correct_empty_tracklet_arrays(app)
            update_counters(app);
            
            next_trj(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.anTraXVerifyAutoIDsUIFigure = uifigure('Visible', 'off');
            app.anTraXVerifyAutoIDsUIFigure.Name = 'anTraX: Verify Auto IDs';
            app.ExperimentMenu = uimenu(app.anTraXVerifyAutoIDsUIFigure);
            app.ExperimentMenu.Text = 'Experiment';
            app.OpenMenu = uimenu(app.ExperimentMenu);
            app.OpenMenu.Text = 'Open';
            app.OpenrecentMenu = uimenu(app.ExperimentMenu);
            app.OpenrecentMenu.Text = 'Open recent';
            app.Menu = uimenu(app.OpenrecentMenu);
            app.Menu.Text = 'Menu';
            app.SessionMenu = uimenu(app.ExperimentMenu);
            app.SessionMenu.Text = 'Session';
            app.Menu_2 = uimenu(app.SessionMenu);
            app.Menu_2.Text = 'Menu';
            app.QuitMenu = uimenu(app.ExperimentMenu);
            app.QuitMenu.Text = 'Quit';
            app.TargetdirMenu_2 = uimenu(app.anTraXVerifyAutoIDsUIFigure);
            app.TargetdirMenu_2.Text = 'Target dir';
            app.OpenMenu_2 = uimenu(app.TargetdirMenu_2);
            app.OpenMenu_2.Text = 'Open';
            app.CorrectIDButton = uibutton(app.anTraXVerifyAutoIDsUIFigure, 'push');
            app.CorrectIDButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectIDButtonPushed, true);
            app.CorrectIDButton.Text = 'Correct ID';
            app.ImageAxes = uiaxes(app.anTraXVerifyAutoIDsUIFigure);
            app.WrongIDButton = uibutton(app.anTraXVerifyAutoIDsUIFigure, 'push');
            app.WrongIDButton.ButtonPushedFcn = createCallbackFcn(app, @WrongIDButtonPushed, true);
            app.WrongIDButton.Text = 'Wrong ID';
            app.ShouldnothaveIDButton = uibutton(app.anTraXVerifyAutoIDsUIFigure, 'push');
            app.ShouldnothaveIDButton.ButtonPushedFcn = createCallbackFcn(app, @ShouldnothaveIDButtonPushed, true);
            app.ShouldnothaveIDButton.Text = 'Should not have ID';
            app.IDDropDown = uidropdown(app.anTraXVerifyAutoIDsUIFigure);
            app.ExportallButton = uibutton(app.anTraXVerifyAutoIDsUIFigure, 'push');
            app.ExportallButton.ButtonPushedFcn = createCallbackFcn(app, @ExportallButtonPushed, true);
            app.ExportallButton.Text = 'Export all';
            app.SelectframesButton = uibutton(app.anTraXVerifyAutoIDsUIFigure, 'push');
            app.SelectframesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectframesButtonPushed, true);
            app.SelectframesButton.Text = 'Select frames';
            app.StatsTextArea = uitextarea(app.anTraXVerifyAutoIDsUIFigure);
            app.ShowallframesCheckBox = uicheckbox(app.anTraXVerifyAutoIDsUIFigure);
            app.ShowallframesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowallframesCheckBoxValueChanged, true);
            app.ShowallframesCheckBox.Text = 'Show all frames';
            app.ShowallframesCheckBox.Value = true;
            app.NextButton = uibutton(app.anTraXVerifyAutoIDsUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.BackButton = uibutton(app.anTraXVerifyAutoIDsUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.IsanantButton = uibutton(app.anTraXVerifyAutoIDsUIFigure, 'push');
            app.IsanantButton.ButtonPushedFcn = createCallbackFcn(app, @IsanantButtonPushed, true);
            app.IsanantButton.Enable = 'off';
            app.IsanantButton.Text = 'Is an ant';
            app.NotanantButton = uibutton(app.anTraXVerifyAutoIDsUIFigure, 'push');
            app.NotanantButton.ButtonPushedFcn = createCallbackFcn(app, @NotanantButtonPushed, true);
            app.NotanantButton.Enable = 'off';
            app.NotanantButton.Text = 'Not an ant';
            app.IDLabel = uilabel(app.anTraXVerifyAutoIDsUIFigure);
            app.IDLabel.Text = 'XX';
            app.TrackletTextArea = uitextarea(app.anTraXVerifyAutoIDsUIFigure);
            % Show the figure after all components are created
            app.anTraXVerifyAutoIDsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = verify_autoids_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.anTraXVerifyAutoIDsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.anTraXVerifyAutoIDsUIFigure)
        end
    end
end