classdef verify_tracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        anTraXVerifyTrackingUIFigure  matlab.ui.Figure
        ImageAxes                     matlab.ui.control.UIAxes
        CorrectButton                 matlab.ui.control.Button
        WrongButton                   matlab.ui.control.Button
        CantsayButton                 matlab.ui.control.Button
        ZoomtoblobCheckBox            matlab.ui.control.CheckBox
        IsolateblobCheckBox           matlab.ui.control.CheckBox
        TextArea                      matlab.ui.control.TextArea
        IDLabel                       matlab.ui.control.Label
        MarkblobCheckBox              matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        
        Trck trhandles % Description
        XY
        T
        cnt
        bbb
        frame
        masked
        antxy
        blobConvexHull
        
        correct = 0
        wrong = 0
        ambig = 0
        
        assigment_rate
        
        
    end
    
    methods (Access = private)
        
        function update_counters(app)
           
            
            % print tracklet info
            text{1}     = ['assigment rate: ',num2str(100*app.assigment_rate,'%.2f'),'%'];
            text{end+1} = ' ';
            text{end+1} = ['total points checked: ',num2str(app.cnt)]; 
            text{end+1} = ['points ambig: ', num2str(app.cnt - app.correct - app.wrong)];
            text{end+1} = ['points correct: ',num2str(app.correct)];
            text{end+1} = ['points wrong: ',num2str(app.wrong)];
            [phat,pci] = binofit(app.wrong,app.wrong+app.correct);
            estr = [num2str(phat,'%.2f'),' [',num2str(pci(1),'%.3f'),',',num2str(pci(2),'%.3f'),']'];
            text{end+1} = ' ';
            text{end+1} = 'error [95% CI]: ';
            text{end+1} = estr;
            
            
            app.TextArea.Value = text;
            
        end
        
        function show_next(app)
            
            app.ImageAxes.cla;
            hold(app.ImageAxes,'on')
            
            app.cnt = app.cnt + 1;
            
            app.IDLabel.Text = app.T.id{app.cnt};
            
            app.Trck.read_frame(app.T.f(app.cnt));
            detect_blobs(app.Trck);            
            
            % prepare frame for display
            if app.Trck.get_param('geometry_multi_colony')
                c = strcmp(app.T.colony{app.cnt},app.Trck.colony_labels);
                mask  =  app.Trck.Masks.colony(:,:,c);
            else
                mask = app.Trck.Masks.roi(:,:,1);
            end
            
            frame = app.Trck.currfrm.CData;
            
            L = app.Trck.currfrm.antblob.LABEL;
            
            xy = app.T.xy(app.cnt,:);
            xy = xy/app.Trck.get_param('geometry_rscale');
            
            bix = argmin(pdist2(xy,app.Trck.currfrm.antblob.DATA.CENTROID));
            app.bbb = app.Trck.currfrm.antblob.DATA.BBOX(bix,:);
            
            L = L==bix;
            
            frame = applyMasktoIm(frame,mask);
            x = any(mask,1);
            y = any(mask,2);
            
            x0 = find(x,1,'first');
            y0 = find(y,1,'first');
            x1 = find(x,1,'last');
            y1 = find(y,1,'last');
            
            cbb = [x0,y0,x1-x0+1,y1-y0+1];
            app.bbb(1) = app.bbb(1) - x0 + 1;
            app.bbb(2) = app.bbb(2) - y0 + 1;
            
            
            app.frame = imcrop(frame,cbb);
            L = imcrop(L,cbb);
            app.masked = applyMasktoIm(app.frame,L);
            S = regionprops(L,'ConvexHull');

            bch = S.ConvexHull;
            
            app.antxy = [xy(1)-x0+1,xy(2)-y0+1];
            app.blobConvexHull = bch;
            
            reimage(app)
            
        end
    
        function reimage(app)
            
            if app.IsolateblobCheckBox.Value
                imshow(app.masked,'Parent',app.ImageAxes);
            else
                imshow(app.frame,'Parent',app.ImageAxes);
            end
            
            if app.MarkblobCheckBox.Value
                plot(app.ImageAxes,app.blobConvexHull(:,1),app.blobConvexHull(:,2),'Color',rgb('Magenta'),'LineWidth',2);
            else
                scatter(app.ImageAxes,app.antxy(1),app.antxy(2),200,'m','+');
            end
            
            
            set(app.ImageAxes,'XTick',[],'YTick',[],'Box','on')
            set_view(app)
        end
    
    
        function set_view(app)
            
            if app.ZoomtoblobCheckBox.Value
                app.ImageAxes.XLimMode = 'manual';
                app.ImageAxes.YLimMode = 'manual';
                app.ImageAxes.XLim = [app.bbb(1),app.bbb(1)+app.bbb(3)];
                app.ImageAxes.YLim = [app.bbb(2),app.bbb(2)+app.bbb(4)];
            else
                app.ImageAxes.XLimMode = 'auto';
                app.ImageAxes.YLimMode = 'auto';
            end
            
        end
    
    
        function mark_blob(app)


        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Trck, type)


            if nargin<3
                type = 'final';
            end
        
        
            app.Trck = Trck;
                       
            % load XY
            app.XY = Trck.loadxy('movlist','all','type',type);
           
            
            % create a random list of Colony/ID/frame to verify
            frames = 1:Trck.er.totalframenum;
            N  = min(Trck.er.totalframenum, 10000);
            
            if app.Trck.get_param('geometry_multi_colony')
                A.colony  = tocol(Trck.colony_labels(randi(Trck.Ncolonies,N,1)));
            end
            A.id = tocol(Trck.usedIDs(randi(Trck.NIDs,N,1)));
            A.f  = tocol(frames(randperm(numel(frames),N)));
            
            app.T = struct2table(A);

            if app.Trck.get_param('geometry_multi_colony')
            for i=1:Trck.Ncolonies
                c = Trck.colony_labels{i};
                for j=1:Trck.NIDs
                    id = Trck.usedIDs{j};
                    a(i,j) = mean(~isnan(app.XY.(c).(id)(:,1)));
                    
                end
            end
            else
                for j=1:Trck.NIDs
                    id = Trck.usedIDs{j};
                    a(j) = mean(~isnan(app.XY.(id)(:,1)));
                end
            end
            
            app.assigment_rate = mean(a(:));
            
            if app.Trck.get_param('geometry_multi_colony')
            for i=1:N
                xy(i,:) = app.XY.(app.T.colony{i}).(app.T.id{i})(app.T.f(i),1:2);  
            end
            else
                for i=1:N
                xy(i,:) = app.XY.(app.T.id{i})(app.T.f(i),1:2);  
                end
            end
            app.T.xy = xy;
            
            % filter unassigned
            app.T=app.T(~isnan(xy(:,1)),:);
            
            app.cnt = 0;
            show_next(app);
            
        end

        % Button pushed function: CorrectButton
        function CorrectButtonPushed(app, event)
            app.correct = app.correct + 1;
            update_counters(app)
            show_next(app)
        end

        % Button pushed function: WrongButton
        function WrongButtonPushed(app, event)
            app.wrong = app.wrong + 1;
            update_counters(app)
            show_next(app)
        end

        % Button pushed function: CantsayButton
        function CantsayButtonPushed(app, event)
            app.ambig = app.ambig + 1;
            update_counters(app)
            show_next(app)
        end

        % Value changed function: ZoomtoblobCheckBox
        function ZoomtoblobCheckBoxValueChanged(app, event)
            set_view(app)
        end

        % Value changed function: IsolateblobCheckBox, 
        % MarkblobCheckBox
        function IsolateblobCheckBoxValueChanged(app, event)
            reimage(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.anTraXVerifyTrackingUIFigure = uifigure('Visible', 'off');
            app.anTraXVerifyTrackingUIFigure.Name = 'anTraX: Verify Tracking';
            app.ImageAxes = uiaxes(app.anTraXVerifyTrackingUIFigure);
            title(app.ImageAxes, '')
            xlabel(app.ImageAxes, '')
            ylabel(app.ImageAxes, '')
            app.CorrectButton = uibutton(app.anTraXVerifyTrackingUIFigure, 'push');
            app.CorrectButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectButtonPushed, true);
            app.CorrectButton.Text = 'Correct';
            app.WrongButton = uibutton(app.anTraXVerifyTrackingUIFigure, 'push');
            app.WrongButton.ButtonPushedFcn = createCallbackFcn(app, @WrongButtonPushed, true);
            app.WrongButton.Text = 'Wrong';
            app.CantsayButton = uibutton(app.anTraXVerifyTrackingUIFigure, 'push');
            app.CantsayButton.ButtonPushedFcn = createCallbackFcn(app, @CantsayButtonPushed, true);
            app.CantsayButton.Text = 'Can''t say';
            app.ZoomtoblobCheckBox = uicheckbox(app.anTraXVerifyTrackingUIFigure);
            app.ZoomtoblobCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZoomtoblobCheckBoxValueChanged, true);
            app.ZoomtoblobCheckBox.Text = 'Zoom to blob';
            app.IsolateblobCheckBox = uicheckbox(app.anTraXVerifyTrackingUIFigure);
            app.IsolateblobCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsolateblobCheckBoxValueChanged, true);
            app.IsolateblobCheckBox.Text = 'Isolate blob';
            app.TextArea = uitextarea(app.anTraXVerifyTrackingUIFigure);
            app.IDLabel = uilabel(app.anTraXVerifyTrackingUIFigure);
            app.MarkblobCheckBox = uicheckbox(app.anTraXVerifyTrackingUIFigure);
            app.MarkblobCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsolateblobCheckBoxValueChanged, true);
            app.MarkblobCheckBox.Text = 'Mark blob';
            % Show the figure after all components are created
            app.anTraXVerifyTrackingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = verify_tracking(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.anTraXVerifyTrackingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.anTraXVerifyTrackingUIFigure)
        end
    end
end